classdef ZebraExploreApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZebraMainFig                   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PlotTab                        matlab.ui.container.Tab
        plotSlate                      matlab.ui.container.Panel
        tminTxt                        matlab.ui.control.NumericEditField
        ymaxTxt_lfp                    matlab.ui.control.NumericEditField
        yminTxt_lfp                    matlab.ui.control.NumericEditField
        ResetButton_lfp                matlab.ui.control.Button
        scrollLeft                     matlab.ui.control.Button
        scrollRight                    matlab.ui.control.Button
        tResetBtn                      matlab.ui.control.Button
        tmaxTxt                        matlab.ui.control.NumericEditField
        sendToClipboard                matlab.ui.control.Button
        ymaxTxt_BP                     matlab.ui.control.NumericEditField
        yminTxt_BP                     matlab.ui.control.NumericEditField
        ResetButton_BP                 matlab.ui.control.Button
        ymaxTxt_spg                    matlab.ui.control.NumericEditField
        yminTxt_spg                    matlab.ui.control.NumericEditField
        ResetButton_spg                matlab.ui.control.Button
        wide_plot_BP                   matlab.ui.control.UIAxes
        spectraWide                    matlab.ui.control.UIAxes
        wide_plot_up                   matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        InputDataTab                   matlab.ui.container.Tab
        select_data                    matlab.ui.control.Button
        excludeChFlag                  matlab.ui.control.CheckBox
        internalSynchCk                matlab.ui.control.CheckBox
        uipanel15                      matlab.ui.container.ButtonGroup
        mVck                           matlab.ui.control.RadioButton
        microVck                       matlab.ui.control.RadioButton
        autoclear                      matlab.ui.control.CheckBox
        openDir                        matlab.ui.control.Button
        excludeCh                      matlab.ui.control.NumericEditField
        synchCh                        matlab.ui.control.NumericEditField
        TimeoffsetsLabel               matlab.ui.control.Label
        trTimeOffset_txt               matlab.ui.control.NumericEditField
        SamplingsEditFieldLabel        matlab.ui.control.Label
        spEdit                         matlab.ui.control.NumericEditField
        NChannelsLabel                 matlab.ui.control.Label
        chEdit                         matlab.ui.control.NumericEditField
        TrialsEditFieldLabel           matlab.ui.control.Label
        trialsEdit                     matlab.ui.control.NumericEditField
        TrialtimesLabel                matlab.ui.control.Label
        SweepTimeEdit                  matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        gain                           matlab.ui.control.NumericEditField
        DataFormatLabel                matlab.ui.control.Label
        dataFormat                     matlab.ui.control.DropDown
        autofocus                      matlab.ui.control.CheckBox
        Standardize_CheckBox           matlab.ui.control.CheckBox
        SamplingkHzLabel               matlab.ui.control.Label
        sf                             matlab.ui.control.NumericEditField
        SelectprobeDropDownLabel       matlab.ui.control.Label
        SelectprobeDropDown            matlab.ui.control.DropDown
        ShowprobeButton                matlab.ui.control.Button
        SubtractMean                   matlab.ui.control.CheckBox
        AI1EditFieldLabel              matlab.ui.control.Label
        AI1_gainEditField              matlab.ui.control.NumericEditField
        AI2EditFieldLabel              matlab.ui.control.Label
        AI2_gainEditField              matlab.ui.control.NumericEditField
        NNexusgainLabel                matlab.ui.control.Label
        DisplayControlTab              matlab.ui.container.Tab
        SPGmode                        matlab.ui.container.ButtonGroup
        SPG                            matlab.ui.control.RadioButton
        gammaPower                     matlab.ui.control.RadioButton
        SPGlogCk                       matlab.ui.control.CheckBox
        EIbutton                       matlab.ui.control.RadioButton
        BP                             matlab.ui.control.RadioButton
        displayLeakCk                  matlab.ui.control.CheckBox
        ColormapLimitsPanel            matlab.ui.container.Panel
        upperDB                        matlab.ui.control.NumericEditField
        lowerDB                        matlab.ui.control.NumericEditField
        MaxLabel                       matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        autoCLimSpg_ck                 matlab.ui.control.CheckBox
        computeEIrat                   matlab.ui.control.CheckBox
        computeEnvelope                matlab.ui.control.CheckBox
        BPManagerPanel                 matlab.ui.container.Panel
        BPplot_drop                    matlab.ui.control.DropDown
        BPplot_ck                      matlab.ui.control.CheckBox
        BPplotColor_btn                matlab.ui.control.Button
        BPplotColorShow_btn            matlab.ui.control.Button
        DefaultColorsButton            matlab.ui.control.Button
        computeBP                      matlab.ui.control.Button
        recomputeSPG                   matlab.ui.control.Button
        BPsynch_led1                   matlab.ui.control.Lamp
        Panel                          matlab.ui.container.Panel
        plotMeanFlag                   matlab.ui.control.CheckBox
        plotTrCk                       matlab.ui.control.CheckBox
        plotTemplateCk                 matlab.ui.control.CheckBox
        Panel_2                        matlab.ui.container.Panel
        plotMultiCh                    matlab.ui.control.CheckBox
        autoOffCk                      matlab.ui.control.CheckBox
        ChscalefactorsButton           matlab.ui.control.Button
        SaveTab                        matlab.ui.container.Tab
        uipanel5_3                     matlab.ui.container.Panel
        saveCurrentCh                  matlab.ui.control.Button
        saveSPG                        matlab.ui.control.Button
        saveBP                         matlab.ui.control.Button
        sepochsButton                  matlab.ui.control.Button
        WindowonimagingButton          matlab.ui.control.Button
        syncchEditFieldLabel           matlab.ui.control.Label
        ImgSynchChEditField            matlab.ui.control.NumericEditField
        savePower_button               matlab.ui.control.Button
        save_allchennelsCheckBox       matlab.ui.control.CheckBox
        BPEditFieldLabel               matlab.ui.control.Label
        saveBP_EditField               matlab.ui.control.NumericEditField
        save_downsampleCheckBox        matlab.ui.control.CheckBox
        factorEditFieldLabel           matlab.ui.control.Label
        downsample_factorEditField     matlab.ui.control.NumericEditField
        SelectchannelandtrialPanel     matlab.ui.container.Panel
        trialSlider_1                  matlab.ui.control.Spinner
        chSlider_1                     matlab.ui.control.Spinner
        chText_1                       matlab.ui.control.Label
        trialText_1                    matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        dataProcessing                 matlab.ui.container.Panel
        uipanel4                       matlab.ui.container.ButtonGroup
        TrialanalysisPanel             matlab.ui.container.Panel
        text16                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        baselineFrom                   matlab.ui.control.NumericEditField
        baselineTo                     matlab.ui.control.NumericEditField
        respFrom                       matlab.ui.control.NumericEditField
        respTo                         matlab.ui.control.NumericEditField
        templateCk                     matlab.ui.control.CheckBox
        smooth_ck                      matlab.ui.control.CheckBox
        templExp_bt                    matlab.ui.control.Button
        templRecalc_bt                 matlab.ui.control.Button
        text29                         matlab.ui.control.Label
        templateFrom                   matlab.ui.control.NumericEditField
        templateTo                     matlab.ui.control.NumericEditField
        text32                         matlab.ui.control.Label
        templImp_bt                    matlab.ui.control.Button
        winPWleft                      matlab.ui.control.NumericEditField
        winPWright                     matlab.ui.control.NumericEditField
        BPtext                         matlab.ui.control.Label
        sliderBPpower                  matlab.ui.control.Spinner
        TrialselectionPanel            matlab.ui.container.Panel
        SynchsignalmindurationmsLabel  matlab.ui.control.Label
        triggerMinLength               matlab.ui.control.NumericEditField
        SynchsignalmaxdurationmsLabel  matlab.ui.control.Label
        triggerMaxLength               matlab.ui.control.NumericEditField
        skip1trialCk                   matlab.ui.control.CheckBox
        doubleStimCk                   matlab.ui.control.CheckBox
        MinimumTTLinterdistancesLabel  matlab.ui.control.Label
        trialMinLength                 matlab.ui.control.NumericEditField
        DisplayPanel                   matlab.ui.container.ButtonGroup
        text4                          matlab.ui.control.Label
        notchFilter                    matlab.ui.control.CheckBox
        predefinedBW                   matlab.ui.container.ButtonGroup
        mouseCk                        matlab.ui.control.RadioButton
        zebraCk                        matlab.ui.control.RadioButton
        EEGck                          matlab.ui.control.RadioButton
        NewcastleBand                  matlab.ui.control.RadioButton
        envelopeChoice                 matlab.ui.container.ButtonGroup
        text12                         matlab.ui.control.Label
        radiobutton6                   matlab.ui.control.RadioButton
        plotEnvelopeCk                 matlab.ui.control.CheckBox
        filterBPpower                  matlab.ui.control.CheckBox
        envSPGfrom                     matlab.ui.control.NumericEditField
        envSPGto                       matlab.ui.control.NumericEditField
        FromLabel                      matlab.ui.control.Label
        ToLabel                        matlab.ui.control.Label
        uipanel17                      matlab.ui.container.Panel
        uipanel18                      matlab.ui.container.Panel
        txtspg                         matlab.ui.control.Label
        text21                         matlab.ui.control.Label
        SPGwindow                      matlab.ui.control.NumericEditField
        SPGstep                        matlab.ui.control.NumericEditField
        ChronuxAutoConfigCk            matlab.ui.control.CheckBox
        decimateFlag                   matlab.ui.control.CheckBox
        computeSpg_Ck                  matlab.ui.control.CheckBox
        FrequencyPanel                 matlab.ui.container.Panel
        text27                         matlab.ui.control.Label
        text28                         matlab.ui.control.Label
        text30                         matlab.ui.control.Label
        SPGfromFreq                    matlab.ui.control.NumericEditField
        SPGtoFreq                      matlab.ui.control.NumericEditField
        leakageLP                      matlab.ui.control.NumericEditField
        txt                            matlab.ui.control.Label
        SPGresolution                  matlab.ui.control.NumericEditField
        leakageCk                      matlab.ui.control.CheckBox
        ChronuxCk                      matlab.ui.control.CheckBox
        text33                         matlab.ui.control.Label
        filtOrder                      matlab.ui.control.NumericEditField
        notchFrom                      matlab.ui.control.NumericEditField
        notchTo                        matlab.ui.control.NumericEditField
        BpTable                        matlab.ui.control.Table
        NumberofBPsEditFieldLabel      matlab.ui.control.Label
        NumberofBPsEditField           matlab.ui.control.NumericEditField
        DeleteBpBtn                    matlab.ui.control.Button
        MoveDownBpBtn                  matlab.ui.control.Button
        MoveUpBpBtn                    matlab.ui.control.Button
        computeBP_2                    matlab.ui.control.Button
        BPsynch_led2                   matlab.ui.control.Lamp
        uipanel19                      matlab.ui.container.Panel
        PowerwindowsEditFieldLabel     matlab.ui.control.Label
        powerWindow                    matlab.ui.control.NumericEditField
        stepsLabel                     matlab.ui.control.Label
        powerStep                      matlab.ui.control.NumericEditField
        saveParams_btn                 matlab.ui.control.Button
        loadParams_btn                 matlab.ui.control.Button
        uipanel21                      matlab.ui.container.Panel
        analyzeWhileConc_ck            matlab.ui.control.CheckBox
        concat_btn                     matlab.ui.control.Button
        clearConc_btn                  matlab.ui.control.Button
        concat_group                   matlab.ui.container.ButtonGroup
        concat_tr                      matlab.ui.control.RadioButton
        concat_time                    matlab.ui.control.RadioButton
        concat_ck                      matlab.ui.control.CheckBox
        uipanel20                      matlab.ui.container.Panel
        powerSpectraCk                 matlab.ui.control.CheckBox
        autoSavePSck                   matlab.ui.control.CheckBox
        analyzePWdistributionCk        matlab.ui.control.CheckBox
        autoSavePWck                   matlab.ui.control.CheckBox
        TrRej_ck                       matlab.ui.control.CheckBox
        multiChAnalyzer                matlab.ui.control.CheckBox
        singleUnitExtraction_ck        matlab.ui.control.CheckBox
        hit_ck                         matlab.ui.control.CheckBox
        checkbox_sleepscoring          matlab.ui.control.CheckBox
        peakAnalyzerCheckBox           matlab.ui.control.CheckBox
        ZebraSaysTab                   matlab.ui.container.Tab
        outable                        matlab.ui.control.Table
        MetricsoftheentiretraceLabel   matlab.ui.control.Label
        MetricsoftheevokedresponsessingletrialsandaveragesLabel  matlab.ui.control.Label
        MetricsofasynchronouseventsburstsandseizuresLabel  matlab.ui.control.Label
        outEvochedResponses            matlab.ui.control.Table
        outEventsSummary               matlab.ui.control.Table
        CopytableButton                matlab.ui.control.Button
        CopyOutableSelection           matlab.ui.control.Button
        DeleteSelRows                  matlab.ui.control.Button
        CopyEvochedSelection           matlab.ui.control.Button
        EventDetectionTab              matlab.ui.container.Tab
        uipanel2_3                     matlab.ui.container.Panel
        AnalysisresultsPanel           matlab.ui.container.Panel
        gauss1param                    matlab.ui.control.Label
        gauss2param                    matlab.ui.control.Label
        text1                          matlab.ui.control.Label
        text2_2                        matlab.ui.control.Label
        baselineThr                    matlab.ui.control.NumericEditField
        eventThr                       matlab.ui.control.NumericEditField
        autoThr                        matlab.ui.control.CheckBox
        AnalysissettingsPanel          matlab.ui.container.Panel
        SelectedBPLabel                matlab.ui.control.Label
        detectionBP_dropDown           matlab.ui.control.DropDown
        text8_2                        matlab.ui.control.Label
        fromH                          matlab.ui.control.NumericEditField
        toH                            matlab.ui.control.NumericEditField
        binNumber                      matlab.ui.control.NumericEditField
        uipanel3_2                     matlab.ui.container.ButtonGroup
        powerLogCk                     matlab.ui.control.RadioButton
        radiobutton2                   matlab.ui.control.RadioButton
        analyzeData                    matlab.ui.control.Button
        trialSlider_2                  matlab.ui.control.Spinner
        chSlider_2                     matlab.ui.control.Spinner
        chText_2                       matlab.ui.control.Label
        trialText_2                    matlab.ui.control.Label
        ComputeUSandDSPanel_2          matlab.ui.container.Panel
        TabGroup3                      matlab.ui.container.TabGroup
        StatesmergingTab               matlab.ui.container.Tab
        conMethod                      matlab.ui.container.ButtonGroup
        noConcCk                       matlab.ui.control.RadioButton
        joinCk                         matlab.ui.control.RadioButton
        joinOffsetChk                  matlab.ui.control.RadioButton
        text3_2                        matlab.ui.control.Label
        text4_3                        matlab.ui.control.Label
        minDuration                    matlab.ui.control.NumericEditField
        maxInterruption                matlab.ui.control.NumericEditField
        erodeCk                        matlab.ui.control.CheckBox
        removeCk                       matlab.ui.control.CheckBox
        removePosUSCheckBox            matlab.ui.control.CheckBox
        FurthercomputationTab          matlab.ui.container.Tab
        doubleHemCheckBox              matlab.ui.control.CheckBox
        otherHem                       matlab.ui.control.NumericEditField
        computePwsCheckBox             matlab.ui.control.CheckBox
        PanelLowFreq                   matlab.ui.container.Panel
        USslopes_ck                    matlab.ui.control.CheckBox
        multiPeakWavesCheckBox         matlab.ui.control.CheckBox
        BPforMultipeakWavesSpinnerLabel  matlab.ui.control.Label
        detectionBPmultiPeak_spinner   matlab.ui.control.Spinner
        SelectdeltabandLabel           matlab.ui.control.Label
        PowerduringUpstateLabel        matlab.ui.control.Label
        powerUS_dropDown               matlab.ui.control.DropDown
        pwinDSCk                       matlab.ui.control.CheckBox
        ExportTab                      matlab.ui.container.Tab
        expTracesCheckBox              matlab.ui.control.CheckBox
        TimewindowbeforeaftersEditFieldLabel  matlab.ui.control.Label
        exp_beforeUS                   matlab.ui.control.NumericEditField
        exp_afterUS                    matlab.ui.control.NumericEditField
        ExportdistributionandthrforcurrentchCheckBox  matlab.ui.control.CheckBox
        extractEvents                  matlab.ui.control.Button
        uipanel1_2                     matlab.ui.container.Panel
        saveHCD                        matlab.ui.control.Button
        text9                          matlab.ui.control.Label
        traslateLeft                   matlab.ui.control.Button
        traslateRight                  matlab.ui.control.Button
        leftTime                       matlab.ui.control.NumericEditField
        rightTime                      matlab.ui.control.NumericEditField
        replot                         matlab.ui.control.Button
        histoPlot                      matlab.ui.control.UIAxes
        plotStates                     matlab.ui.control.UIAxes
        statesTrack                    matlab.ui.control.UIAxes
        TabGroup4                      matlab.ui.container.TabGroup
        ROCTab                         matlab.ui.container.Tab
        BestthresholdEditFieldLabel    matlab.ui.control.Label
        BestthresholdEditField         matlab.ui.control.NumericEditField
        errorRocAxes                   matlab.ui.control.UIAxes
        rocAxes                        matlab.ui.control.UIAxes
        SpectraTab                     matlab.ui.container.Tab
        uipanel4_3                     matlab.ui.container.Panel
        savePWS                        matlab.ui.control.Button
        SaveDownstSpec                 matlab.ui.control.Button
        text10                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        minPw                          matlab.ui.control.NumericEditField
        maxPw                          matlab.ui.control.NumericEditField
        minFreq                        matlab.ui.control.NumericEditField
        maxFreq                        matlab.ui.control.NumericEditField
        powerSpectra                   matlab.ui.control.UIAxes
        Tables                         matlab.ui.container.Tab
        uipanel5_4                     matlab.ui.container.Panel
        saveSegmentedStates            matlab.ui.control.Button
        UStable                        matlab.ui.control.Table
        exportTab_btn                  matlab.ui.control.Button
        SaveStartImagingButton         matlab.ui.control.Button
        CopyTableButton                matlab.ui.control.Button
        DStablePanel                   matlab.ui.container.Panel
        DSTable                        matlab.ui.control.Table
        ExportDStabBtn                 matlab.ui.control.StateButton
        CopyDStabBtn                   matlab.ui.control.StateButton
        Tab                            matlab.ui.container.Tab
        AutoTab                        matlab.ui.container.Tab
        ZebraAutoPanel                 matlab.ui.container.Panel
        AutoSetContrasts_bt            matlab.ui.control.Button
        AutoGo_bt                      matlab.ui.control.Button
        autoMode_bt                    matlab.ui.control.Button
        NumberofconditionsLabel        matlab.ui.control.Label
        AutoNConds_txt                 matlab.ui.control.NumericEditField
        uipanel2_4                     matlab.ui.container.Panel
        text2_3                        matlab.ui.control.Label
        AutoFileNameIn_edit            matlab.ui.control.EditField
        AutoOutFileName_edit           matlab.ui.control.EditField
        text3_3                        matlab.ui.control.Label
        AutoMainFolder_edit            matlab.ui.control.EditField
        text4_4                        matlab.ui.control.Label
        uipanel1_3                     matlab.ui.container.Panel
        AutoSpg_ck                     matlab.ui.control.CheckBox
        AutoTable_ck                   matlab.ui.control.CheckBox
        AutoTrace_ck                   matlab.ui.control.CheckBox
        uipanel4_4                     matlab.ui.container.Panel
        AutoSpectra_ck                 matlab.ui.control.CheckBox
        AutoWinSpectra_ck              matlab.ui.control.CheckBox
        AutoWinSpectra2_ck             matlab.ui.control.CheckBox
        fromEditFieldLabel             matlab.ui.control.Label
        AutoSpectraFrom_edit           matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        AutoSpectraTo_edit             matlab.ui.control.NumericEditField
        fromEditField_2Label           matlab.ui.control.Label
        AutoSpectraFrom2_edit          matlab.ui.control.NumericEditField
        toEditField_2Label             matlab.ui.control.Label
        AutoSpectraTo2_edit            matlab.ui.control.NumericEditField
        ChannelselectionButtonGroup    matlab.ui.container.ButtonGroup
        allCh_rb                       matlab.ui.control.RadioButton
        selCh_rb                       matlab.ui.control.RadioButton
        selCh_edit                     matlab.ui.control.NumericEditField
        sound_ck                       matlab.ui.control.CheckBox
        auto_clearStoredLfp            matlab.ui.control.CheckBox
    end

    properties (Access = public)
        PathSeparator % character used by the system to separate between folders. It may be '\' or '/'
        zebraPath % zebra app folder path
        
        notch % app.notch is an array with the two param
        notchOrder % order 6 for the notch filter % MIGHT BE UNUSED
        dtaComplete % Complete path and file name
        file_in % selected file name
        dir_in % directory of selected file
        expID
        fileInNum % keep track of the number of analyzed files for the output table
        eventNum % keep track of the number of analyzed events
        cnt % counter for full trace summary table
        EPcnt % counter for the summary table of evoched responses
        insetW % width of the inset window (s)
        lp % width of the low pass filter window % MIGHT BE UNUSED
        gainAmp % total gain of the ampification of the recording
        xnow
        
        sp % sampling period in s
        acqF % sampling frequency
        nCh % number of channels
        nTrials % number of trials
        currentTrial
        currentCh
        dtaLen % total length of the data 
        x % Time vector
        params % structure of parameters for calculating the spectrogram
        fileTime % the time at which the file was opened
        freqN % number of frequencies of spectrogram
        
        LFP % matrix containing the raw recording. 3-D array: channel, trial, time
        bandPassed_LFP % 4-D array: band, channel, trial, time
        workLFP
        LPfiltLeakage %lowpassed LFP for spectral leakage correction
        
        spg
        spgl
        spgt
        spgw
        spgPlot
        meanLFP
        meanBP
        meanSpg
        meanSpgPlot
        meanPWS
        EIrat
        meanEIrat
        EPamplitude
        EIresponse
        EPoffset
        templ
        SPGlowDB
        SPGhighDB
        templateConv
        ntemp
        templatePeakSigned
        BP4power
        pwsf % Vector of frequencies for the spectra (1)
        pwsf2 % Vector of frequencies for the spectra (2)
        
        tmin
        tmax
        storedLFP
        filterOrder
        frequencyBand % stores the lower and upper bounds of the frequency bands
        frequencyBand_ck % for each band, if it gets 0 the band is skipped during the analysis
        BPpowerL % 1-D array: n elements. Each i-element contains the number of "interesting" elements to be considered in BPpower for the i-band. n is the total number of bands.
        BPpower % structure. Its dimension equals the number of BPs. Its fields are:
        % time: time vector of that BP rms.
        % power: rms power.
        BPpowerSP % the same as BPpowerL but for the sampling period.
        BPcomputed % logic array that indicates if each band has been calculated.
        dtaSummary
        eventSummary
        respSummary
        dtaPWsummary
        dtaPWlocalSummary
        outtableSelectedIndices % Indices of selected data in outable table
        outEvochedSelectedIndices % Indices of selected data in outEvochedResponses table
        BpTableSelIndices % Indices of selected data in BpTable table
        
        minLFP
        maxLFP
        
        % Checks
        deleakedFlag
        autoMode
        
        selectedBands

        
        timeOffset   % offset on time (seconds)
        timeOffset_i % offset on time (indices)
        
        paramsStruct %structure for saving and loading parameters automatically
        
        selection % a variable stored in ZebraMain, useful for zebraAuto
        
        % Variables for ZebraAuto
        auto %scalar structure with all the variables for zebraAuto
        %         AutoDlgIn % The cell array containing the folders to be opened by ZebraAuto
        %         AutoFolders % This is the list of folders used by ZebraAuto
        
        % https://undocumentedmatlab.com/blog/property-value-change-listeners
        
        %% Variables for EventDetection
        evDet %scalar structure storing all eventDetection variables.
        
        %% Stored handles of children apps
        spectreApp %ZebraSpectre
        SUapp % SingleUnit app
        
        BP_4_plot % array with one element per band pass; if the i-th element is 1 (0), the i-th BP will (won't) be plotted
        BP_color % matrix with dimension #BPs x 3; stores color (r-g-b) for each BP
        BP_color_default % 8 default colors for BPs. Initialized in the OpeningFunction
        
        chEditListener % Listener for the property 'Value' of Ch number edit field.
        alreadySkipped % initalized to 0; gets 1 if the first trial is discarded during the trial extraction.
        %if so, the option "skipFirstTrial" is ignored.
        RMS % stores RMS for each BP
        xCorr % Description
        peakAnalyzer_handle % Description
        
        orChI % order of neuronexus channels
        probeImg % image of the selected probe
        multiChPlot % structure for multiple ch plots, with fields 'plotScales' and 'show'
        % plotScales (double): scale applied to each channel when multiplotting
        % show (logical): do/don't show a channel
        spg_computed % logical
        leakageLPchanged % Description
    end


    methods (Access = public)
        
        function computeBPandNotch(app)
            %%
            % March 12, 2018
            % code has been modified to able/enable the BP computations
            
            % first: check if the notch filter is selected.
            if app.notchFilter.Value
                D = fdesign.bandstop('N,F3dB1,F3dB2',2,2*app.notch(1)*app.sp,2*app.notch(2)*app.sp);
                myFilt = design(D);
                app.workLFP = [];
                app.workLFP(1:app.nCh,1:app.nTrials,1:app.dtaLen) = permute( filtfilt(myFilt.sosMatrix,myFilt.ScaleValues, permute(app.LFP,[3,2,1])), [3,2,1]);
                disp('Notch filter applied')
            else
                app.workLFP = app.LFP;
            end
            % computations are performed starting from the workLFP file
            
            app.filterOrder = get(app.filtOrder,'Value');
            % compute band passed data
            % Variable number of frequency bands, ENRICO 2019/04/17, GAB 2019/05/23
            app.bandPassed_LFP = NaN(app.NumberofBPsEditField.Value,app.nCh,app.nTrials,app.dtaLen);
            app.BPcomputed = zeros(app.NumberofBPsEditField.Value,1);
            
            %             initialize the mean band_passedLFP trace
            app.meanBP = nan(app.NumberofBPsEditField.Value,app.nCh,app.dtaLen);
            for i = 1:app.NumberofBPsEditField.Value %number of bands
                if app.frequencyBand_ck(i)
                    if app.frequencyBand(i,1) == 0 % lower bound = 0 means low-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,2)*app.sp,'low');  %Low pass filter
                    elseif app.frequencyBand(i,2) == Inf % upper bound = inf means high-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,1)*app.sp, 'high');% high pass and single units
                    else % band-pass
                        [b,a] = butter(app.filterOrder,2*app.frequencyBand(i,:)*app.sp);
                    end
                    app.bandPassed_LFP(i,:,:,:) = computeFilteredData(app, b, a);
                    app.BPcomputed(i) = 1;
                    
                    %also compute the mean filtered lfp
                    skip1 = app.skip1trialCk.Value;
                    app.meanBP(i,:,:) = nan;
                    if app.nTrials > 1
                        app.meanBP(i,1:app.nCh,1:app.dtaLen) = mean(app.bandPassed_LFP(i,:,1+skip1-app.alreadySkipped:app.nTrials,:),3);
                    end
                    fprintf('Bandpass %i computed\n',i)
                else
                    app.BPcomputed(i) = 0;
                    %                     app.meanBP remains full of nan
                end
            end
            %                   % compute the LP data for spectral leakage correction
            if app.leakageCk.Value
                [b,a] = butter(app.filterOrder,2*app.leakageLP.Value*app.sp,'low');  %Low pass filter
                app.LPfiltLeakage = computeFilteredData(app, b, a);
                fprintf('Bandpass for leakage correction computed\n')
            end
            % envelope computation in beta+gamma band for the automatic ID of events
            %tmp = abs(app.bandPassed_LFP(2,:)+app.bandPassed_LFP(3,:));
            %app.envelope = abs(hilbert(tmp));
            % computer rolling average
            %app.rollingAV = sgolayfilt(tmp,3,1001);
        end
        
        function filteredLFP = computeFilteredData(app, b, a)
            %% b, a are the descriptor of the filter
%             filteredLFP = zeros(app.nCh,app.nTrials,app.dtaLen);
%             for i = 1:app.nCh
%                 %                 if ~((app.excludeChFlag.Value)&&(i==app.excludeCh.Value))
%                 for j = 1:app.nTrials
%                     filteredLFP(i,j,:) = filtfilt(b, a, squeeze(app.workLFP(i,j,:)));
%                 end
%                 disp(['Band ' num2str(k) ', ch ' num2str(i) ' computed.'])
%                 %                 end
%             end
            filteredLFP = [];
            filteredLFP(1:app.nCh,1:app.nTrials,1:app.dtaLen) = permute(filtfilt(b,a,permute(app.workLFP,[3,2,1])),[3,2,1]);
        end
        
        function computePowerInTime(app)
            %% May 8, 2017
            % Here we compute the short time RMS power for the band passed
            % data. Its is stored in an array of structure called
            % app.BPpower whose number of elements equals the number of
            % frequency bands.
            
            % The computation is performed on a window whose length is given by the
            % larger of 1) the time window of the SPG computation (powerWindow) or
            % 2) the lower period of the bandwidth.
            % Therefore each STRMS plot has its own sampling, so each elemnt of
            % the structure contains two fields: time and power.
            
            window = app.powerWindow.Value;
            step_i = round(app.powerStep.Value/app.sp);
            
            % First, preallocation of the array app.BPpower
            % This array has different lengths for the different bands. The max
            % length is given by the window size determined by the value of
            % ChronuxWin.
            
            app.BPpower = struct('power',[],'time',[]); %
            
            for ibp = 1:length(app.frequencyBand_ck)
                if app.BPcomputed(ibp)
                    fprintf('Compute power for bandpass %i \n', ibp)
                    % max between the choosen window and the lower period of
                    % the badpass
                    win = max(window,1/app.frequencyBand(ibp,1));
                    % the half-window must be smaller than half the trace
                    halfwin_i = min(round(win./app.sp/2),floor(app.dtaLen-1)/2);
                   
                    
                    tmprms = [];
                    SamplePoints = uint32((halfwin_i+1):step_i:(app.dtaLen-halfwin_i));
                    tmprms(1:app.nCh,1:app.nTrials,1:app.dtaLen) = sqrt(...
                                                                    movmean(app.bandPassed_LFP(ibp,:,:,:).^2,...
                                                                    [halfwin_i, halfwin_i],4,...
                                                                    'Endpoints','shrink'));
                    
                    app.BPpower(ibp).power = tmprms(:,:,SamplePoints);
                    app.BPpower(ibp).time = app.x(SamplePoints);
                else
                    app.BPpower(ibp).power = [];
                    app.BPpower(ibp).time = [];
                end
            end
        end
        
        
        function openFile(app, selection, path_in)
            
            % this function open and process the file specified in [path_in selection]
            
            %% 
            app.file_in = selection;
            app.dir_in = path_in;
            app.dtaComplete=[path_in, selection];
            set(app.plotSlate,'title', app.dtaComplete);
            
            % Output to console of status and timing
            tic
            disp([num2str(toc) ' Begin to load data file'])
            % initialize all arrays
            LFPin = [];
            app.LFP = [];
            app.bandPassed_LFP = [];
            app.workLFP = [];
            app.spg = [];
            app.spgPlot = [];
            app.meanLFP = [];
            app.meanBP = [];
            app.meanSpg = [];
            app.meanSpgPlot = [];
            app.meanPWS = [];
            app.EIrat = [];
            app.meanEIrat = [];
            app.EPamplitude = [];
            app.EIresponse = [];
            app.EPoffset = [];
            app.templ = [];
            
            % flags to indicate the frequency domain computations that have been
            % performed on the data set
            app.BPcomputed(1:end) = 0;
            
            app.EPcnt = 0;
            app.xnow = -100;
            exCh = get(app.excludeCh,'Value');
            sCh = get(app.synchCh,'Value');
            app.deleakedFlag = 0;       % this flag is set to 1 if SPGs have been deleaked.


            flagEx  = app.excludeChFlag.Value;
            flagExtSynch =  app.internalSynchCk.Value;
            flagSkipFirstTrial = app.skip1trialCk.Value;
            app.nTrials = 1; % The number of trials is initialized to 1.
            alreadySkipped = 0; % may take 1, while extracting trials
            
            % This function opens a specified file either in several formats (ASCII,binary...).
            %   the variable nCh holds the number of channels present in the
            %       data file.
            %   the varialbe nTr holds the number of trials in each
            %       channel.
            %
            % The resulting LFP data will be a 3D matrix of nCh * nTrials * Time
            %%
            %             ENRICO 20200225 Everything is now loaded in mV, then they are
            %             changed according to gain selection and to mV/uV selection.
            
            % Now the processing pipelines differentiate depending on the input mode.
            switch app.dataFormat.Value
                
                case 'AxoP'
                    set(app.mVck,'Value',1);
                    % in this modality data are generated by the Axopatch program and
                    % are saved as binary data. sp and Ch number obtained from data header
                    disp([num2str(toc) ' Begin loading of Axopatch data'])
                    [LFPin,si,h] = abfload(app.dtaComplete);
                    % si    scalar           the sampling interval in micro s
                    % h     struct           information on file (selected header parameters)
                    % number of channels
                    LFPin = LFPin';    % abfloat return the channels as columns. Traspose to set them as rows
                    % conversion to the selected measure unit.
                    % WE ASSUME INPUT FILE IS EXPRESSED IN V
                    if app.mVck.Value
                        LFPin = LFPin.*1000;
                    else
                        LFPin = LFPin.*1000000;
                    end
                    setSP(app, 1/si*1000000) % si is expressed in microseconds
                    setCh(app, h.nADCNumChannels)
                    app.dtaLen = h.dataPtsPerChan; 
                    app.LFP = reshape(LFPin,[size(LFPin,1),1,size(LFPin,2)]);
                    if flagEx && ~flagExtSynch    % if ext synch the channel is excluded only from the output
                        app.LFP(exCh,:,:) = [];
                    end
                    if flagExtSynch
                        extract_trials(app);
                    end
                    
                case 'National Instr'
                    ismillivolt = app.mVck.Value;
                    disp([num2str(toc) ' Begin loading of National Instruments EEG data.'])

                    % ----------
                    % Gab 2019/05/30: faster and easier way for loading column-ordered files.
                    %                It also automatically detects the number of channels.
                    %                     Now the file is read directly as a matrix.
                    tmpIn = readmatrix(app.dtaComplete);
                    
                    % prepare the LFPin matrix with the correct number
                    % of channels and samples, and with just 1 trial
                    LFPin = nan(size(tmpIn,2),1,size(tmpIn,1));
                    
                    % properly store the input file in app.LFP
                    LFPin(:,1,:) = tmpIn';
                    app.LFP = LFPin;
                    
                    l = size(LFPin);
                    app.dtaLen = l(3);
                    app.nCh = l(1); %Gab
                    
                    if flagExtSynch
                        % Trial extraction
                        extract_trials(app);
                    end
                    
                    % Gab 2020-06-18: moved after trial extraction.
                    %                     if flagEx
                    if flagEx && ~flagExtSynch
                        app.LFP(exCh,:,:) = [];
                        app.nCh = app.nCh-1;
                    end
                    
                    % data from NationalInstruments are in Volts. Convert
                    % it in milliV or microV
                    if ismillivolt
                        app.LFP = 1000*app.LFP;
                    else
                        app.LFP = 1000000*app.LFP;
                    end
                    
                case 'EDF format'
                    disp([num2str(toc) ' Begin loading of EDF data.'])
                    [hdr, recIn] = edfread(app.dtaComplete);
                    setSP(app, hdr.frequency(1));
                    % since the SP has been likely changed, the frequencies of the
                    % bandwidht filters should be taken care of
                    setGain(app, 1000)
                    set(app.microVck,'Value',1)
                    setCh(app, size(recIn,1));
                    app.dtaLen = length(recIn);
                    % set(app.SPGwindow,'Value','32')
                    % set(app.SPGstep,'Value','2')
                    app.nTrials = 1;
                    for k=1:app.nCh
                        app.LFP (k,1,:) = recIn(k,:);
                    end
                    
                case 'EEG Laura'
                    set(app.mVck,'Value',1);
                    setSP(app, 512)
                    setGain(app, 5000)
                    setCh(app, 2)
                    set(app.synchCh, 'Value', 1)
                    set(app.SPGwindow,'Value',16)
                    set(app.SPGstep,'Value',2)
                    % Gab 2019/05/30: I think this submode is not working anymore
                    disp([num2str(toc) ' Begin loading of IN EEG data.'])
                    % this is required because these data use a ',' as separator of the
                    % decimal part. How stupid is this???
                    % create an handle for the file opened in read mode
                    fid=fopen(app.dtaComplete,'r');
                    c = textscan(fid,'%s');
                    % Close the data file
                    fclose(fid);
                    l = size(c{1});
                    app.dtaLen = l(1)/2-1;  % remove first row
                    LFPin = zeros(app.nCh,app.dtaLen);
                    for i=1:app.dtaLen
                        % replace the comma with the decimal point
                        for j=1:app.nCh
                            runningIndex = 2 + app.nCh * (i-1) + j;
                            LFPin (j,i) = str2double(strrep(c{1}{runningIndex},',','.'));
                        end
                    end
                    app.LFP = LFPin;
                    if flagEx && ~flagExtSynch    % if ext synch the channel is excluded only from the output
                        app.LFP(exCh,:,:) = [];
                    end
                    if flagExtSynch
                        extract_trials(app);
                    end
                    
                case 'IMM MEA'
                    disp([num2str(toc) ' Begin loading of IMM MEA data.'])
                    setGain(app, 1000);
                    % the data are contained in a structure called channelPlotData
                    load(app.dtaComplete)
                    app.nCh = 32;
                    set (app.chEdit,'Value','32');
                    
                    name = fieldnames(channelPlotData);
                    % extract the sampling period from channel0 data structure
                    sp = channelPlotData.channel0.time(1,2);
                    % NOTE: this SP is only approximate. THIS SHOULD BE FIXED!
                    sp = 0.0001003394;
                    setSP(app, 1/sp)
                    % preallocate the input array
                    fullDataSet = zeros(app.nCh,1,length(channelPlotData.(name{1}).value));
                    % load all the channels
                    for i=1:app.nCh
                        % run on the entire cell array
                        tmp = channelPlotData.(name{i}).value;
                        fullDataSet (i,1,:) = tmp(:);
                    end
                    
                    if flagExtSynch
                        disp([num2str(toc) ' Extract single trials from a multi-trials data set.'])
                        % extract the single trials
                        tmp = [];
                        stimOrigin = channelPlotData.Stimulation.time(1,6);
                        traceOrigin = channelPlotData.channel0.time(1,1);
                        stimTimes = channelPlotData.Stimulation.time(:,6) - traceOrigin;
                        app.nTrials = length(stimTimes);
                        intervals = stimTimes(2:app.nTrials)-stimTimes(1:app.nTrials-1);
                        app.dtaLen = floor(min(intervals)/app.sp + 1);
                        pnt = app.dtaLen;
                        % prealocate LFP
                        app.LFP = zeros(app.nCh,app.nTrials,pnt);
                        % now extract the data segments
                        for j=1:app.nTrials
                            beginIndex = floor(stimTimes(j)/app.sp + 1);
                            for i=1:app.nCh
                                app.LFP(i,j,1:pnt) = fullDataSet (i,1,beginIndex:beginIndex+pnt-1);
                                % filter the initial and final transient (trigger artifact)
                                app.LFP(i,j,1:10) = app.LFP(i,j,20:-1:11);
                                app.LFP(i,j,pnt:-1:pnt-30) = app.LFP(i,j,pnt-30:-1:pnt-60);
                            end
                        end
                    else
                        app.LFP = fullDataSet;
                        app.nTrials = 1;
                        app.dtaLen = length(app.LFP);
                    end
                    app.expID = '';
                    
                case 'NewCastle'
                    set(app.mVck,'Value',1);
                    disp([num2str(toc) ' Begin loading of .mat data'])
                    load(app.dtaComplete)
                    %                     The structure "rec" has been loaded.
                    channelList = fieldnames(rec);
                    
                    FsTmp = rec.(channelList{1}).Fs;
                    dtaLenTmp = length(rec.(channelList{1}).trace);
                    
                    LFPin = zeros(length(channelList),1,dtaLenTmp);
                    for i = 1:length(channelList)
                        if FsTmp~=rec.(channelList{i}).Fs || dtaLenTmp~=length(rec.(channelList{i}).trace)
                            error('The app does not support channels with different sampling frequency or length.')
                        end
                        LFPin(i,1,:) = rec.(channelList{i}).trace;
                    end
                    
                    setSP(app,FsTmp)
                    setCh(app,length(channelList))
                    app.dtaLen = dtaLenTmp;
                    app.LFP = LFPin;
                    if flagEx && ~flagExtSynch    % if ext synch the channel is excluded only from the output
                        app.LFP(exCh,:,:) = [];
                    end
                    if flagExtSynch
                        extract_trials(app);
                    end
                    
                case 'Meyer VEP'
                    disp([num2str(toc) ' Begin loading of Meyer ASCII data.'])
                    % since the SP has been likely changed, the frequencies of the
                    % bandwidht filters should be taken care of
                    setGain(app, 1)
                    %                     set(app.microVck,'Value',1)
                    %setCh (hObject, handles, 3);
                    set(app.SPGwindow,'Value',32)
                    set(app.SPGstep,'Value',2)
                    %                     % create an handle for the file opened in read mode
                    %                     fid=fopen(app.dtaComplete,'r');
                    %                     c = textscan(fid,'%s');
                    %                     % Close the data file
                    %                     fclose(fid);
                    %                     app.expID = [c{1}{1} ' ' c{1}{2}];
                    %                     % create an array with the indexes of all cells that contain the string
                    %                     % '2048'. The data will begin immediately after the last cell pointed by the
                    %                     % index array. The length of the array is equal to the number of trials*3.
                    %                     index = find(strcmp(c{:}, '2048'));
                    %                     l = size(index);
                    %                     ENRICO 20200225 Changed to use readmatrix
                    fid = fopen(app.dtaComplete,'r');
                    
                    st = 'null';
                    i = 1;
                    while ~strcmp(st,'')
                        st = fgetl(fid);
                        if i ==1
                            expname = regexp(st,'^\w+\s','match');
                            app.expID = expname{1};
                            app.expID = app.expID(1:end-1);
                        end
                        
                        if strcmp(st,'')
                            EndRowIdx = i;
                        elseif feof(fid)
                            error('No correct file structure was found')
                        elseif strcmp(st(1:2),'01')
                            StartRowIdx = i;
                        end
                        i = i + 1;
                    end
                    st = fgetl(fid);
                    dtaLenTmp = str2double(regexp(st,'^\d+','match'));
                    app.dtaLen = dtaLenTmp;
                    fclose(fid);
                    
                    numCh = EndRowIdx-StartRowIdx;
                    %GABRIELE 2018/01/27; allows to open file whith any number of channels
                    setCh(app, numCh)
                    
                    %                     c now is a 2D matrix: time x (channels x trials)
                    c = readmatrix(app.dtaComplete,'FileType','text','NumHeaderLines',7,'OutputType','double','DecimalSeparator',',');
                    %                     Check that the number of points recorded is the same
                    %                     as the number that was read from the file.
                    if app.dtaLen~=size(c,1)
                        error('The length of the trace read from the file is not compatible with the actual length of the array.')
                    end
                    numTr = size(c,2)/numCh;
                    if numTr ~= round(numTr)
                        error('The number of resulting traces is not integer.')
                    end
                    app.nTrials = numTr;
                    app.LFP = permute(reshape(c,app.dtaLen,numCh,numTr),[2,3,1]);
                    setSP(app, app.dtaLen/app.SweepTimeEdit.Value) %GABRIELE 2018/01/27; frequency is calculated as trialPoints/trialTime
                    
                    % %                     app.nTrials = l(1)/numCh;
                    %                     startC = index(app.nTrials*numCh)+1;
                    %                     [endC,g] = size(c{:});


                    %                     % loop on all points of each trial
                    %                     for j=1:app.dtaLen   %GABRIELE 2018/01/27; allows to open file whith any number of channels
                    %                         % loop on all trials
                    %                         for i=1:app.nTrials
                    %                             % run on the entire cell array
                    %                             runningIndex = startC + (j-1)*app.nTrials*numCh + (i-1)*numCh;
                    %                             % first, replace all the ',' with the decimal point
                    %                             for k=1:numCh
                    %                                 app.LFP (k,i,j) = str2double(strrep(c{1}{runningIndex+k-1},',','.'));
                    %                             end
                    %                         end
                    %                     end
                    %                     % loop on all points of each trial
                    %                     for j=1:app.dtaLen    %GABRIELE 2018/01/27; allows to open file whith any number of channels
                    %                         % loop on all trials
                    %                         for i=1:app.nTrials
                    %                             % run on the entire cell array
                    %                             runningIndex = startC + (j-1)*app.nTrials*numCh + (i-1)*numCh;
                    %                             % first, replace all the ',' with the decimal point
                    %                             for k=1:numCh
                    %                                 app.LFP (k,i,j) = str2double(strrep(c{1}{runningIndex+k-1},',','.'));
                    %                             end
                    %                         end
                    %                     end
                    
                case 'SEP'
                    % Better be safe...
                    setSP(app, 32768);
                    setGain(app, 1000);
                    set (app.microVck,'Value',1);
                    % create an handle for the file opened in read mode
                    fid=fopen(app.dtaComplete,'r');
                    c = textscan(fid,'%s');
                    % Close the data file
                    fclose(fid);
                    app.expID = [c{1}{1} ' ' c{1}{2}];
                    % create an array with the indexes of all cells that contain the string
                    % '1639'. The data will begin immediately after the last cell pointed by the
                    % index array. The length of the array is equal to the number of trials*3.
                    index = find(strcmp(c{:}, '1639'));
                    l = size(index);
                    app.nCh = l(1);
                    app.nTrials = 1;
                    app.dtaLen = 1639;
                    
                    % startC point to the beginning of the data
                    startC = index(app.nCh)+1;
                    [endC,g] = size(c{:});
                    
                    % loop on all points of each trial
                    for j=1:app.dtaLen
                        % loop on all channels
                        for i=1:app.nCh
                            % run on the entire cell array
                            runningIndex = startC + (j-1)*app.nCh + (i-1);
                            app.LFP (i,1,j) = str2double(strrep(c{1}{runningIndex},',','.'));
                        end
                    end
                    % Now remove the artefact that sometimes pops out at the very beginning of the record
                    clsW = int32(app.dtaLen/10);
                    fixIt(1:app.nCh) = app.LFP(1:app.nCh,1,clsW);
                    for k=1:app.nCh
                        app.LFP(k,1,1:clsW) = fixIt(k);
                    end
                    app.workLFP = app.LFP;    % Copied here since there is nothing to filter in these data
                    
                case 'TRC import'
                    % open the dialogue window for TRC data import
                    % The TRCimport must be opened by passing the file path and it returns the structure TRC that contina
                    % the data, all the associated info and the events.
                    TRCoutput = cell([40 1]);
                    [TRCoutput{1:40}] = importTRCfilesOptions(app.dir_in,app.file_in,app.dtaComplete);
                    disp([num2str(toc) ' End of TRC import'])
                    %TRCimportData = zebraguidata (TRCimportDialogue);
                    % let's fill the data structure with the imported data
                    set(app.microVck,'Value',1);   % select microV unit
                    %         setGain (hObject, handles, 1000);
                    app.expID = TRCoutput{1};
                    nCh = TRCoutput{9};
                    app.SPGlowDB = -80;                             % set limits for spectrogram plot
                    set(app.lowerDB,'Value',app.SPGlowDB)
                    app.SPGhighDB = 0;
                    set(app.upperDB,'Value',app.SPGhighDB)
                    
                    setCh(app, nCh)
                    app.dtaLen = TRCoutput{11};
                    acqF = TRCoutput{12};
                    setSP (app, acqF);
                    app.nTrials = length(TRCoutput{26}) - 1;     % if no events nTrials = -1 !
                    LFPin = zeros(app.nCh, app.dtaLen);      % preallocation of LFPin
                    LFPin = TRCoutput{16};
                    if (app.nTrials>1)
                        set (app.skip1trialCk,'value',0);       % no need to skip the first trial
                        stimTrigger (1:app.nTrials) = 0;        % preallocation of trigger
                        for j=1:app.nTrials
                            stimTrigger (j) = TRCoutput{26}(j+1).latency;
                            % the first trigger is skipped because it is associated to the
                            % beginning of the equiluminant grey and not to a chekerboard stimulus.
                        end
                        %Compute the inter-trial distance.
                        trialInterdistance (1:app.nTrials-1) = stimTrigger(2:app.nTrials)-stimTrigger (1:app.nTrials-1);
                        % Lets find the minimal inter trial distance
                        minLen = min(trialInterdistance);      % this will be the new app.dtaLen
                        % Let's extract the trials from the available data channels.
                        disp([num2str(toc) ' Extract trials from the data channels.'])
                        
                        % Initialize the variable size ENRICO 11/12/2018
                        temp = zeros(app.nCh, app.nTrials, minLen);
                        
                        for i=1:app.nCh
                            for j=1:app.nTrials
                                temp(i,j,1:minLen) = LFPin(i,stimTrigger(j):stimTrigger(j)+minLen-1);
                            end
                        end
                        app.LFP = [];
                        app.LFP = temp;
                        app.dtaLen = minLen;
                    else
                        app.nTrials = 1;
                        app.LFP(1:app.nCh,1,1:app.dtaLen) = LFPin(1:app.nCh,1:app.dtaLen);
                    end
                case 'Neuronexus'
                    app.microVck.Value = 1;
                    
                    reader = allegoXDatFileReader();
                    NeuroStruct = reader.getAllegoXDatAllSigs(app.dtaComplete(1:end-10));
                    
                    setSP(app, 1/(NeuroStruct.timeSamples(2)-NeuroStruct.timeSamples(1)))
                    app.x = (NeuroStruct.timeSamples-NeuroStruct.timeSamples(1));
                    app.dtaLen = length(app.x);
                    app.nTrials = 1;
                    
                    % apply a gain to the analog channels
                    if size(NeuroStruct,1)>=64
                        % a probe is connected
                        aiCh = [65,66];
                    else
                        % no probe; the frist channels are AI1 and AI2;
                        aiCh = [1,2];
                    end
                    % the gain factor corrects for the gain set by the user
                    % and contains a conversion factor from Volts into microVolts
                    gainFactor = 10^6./[app.AI1_gainEditField.Value;app.AI1_gainEditField.Value];
                    NeuroStruct.signals(aiCh,:) = NeuroStruct.signals(aiCh,:).*gainFactor;
                    
                    if isempty(app.orChI)
                        setCh(app, size(NeuroStruct.signals,1))
                        app.LFP = double(reshape(NeuroStruct.signals,[app.nCh,1,app.dtaLen]));
                        % set specific gain to Analog Input channels, which
                        % are the sixth and fifth to last:
%                         aiCh = app.nCh-5:app.nCh-4;
                    else
                        setCh(app, numel(app.orChI.ord))
                        app.LFP = double(reshape(NeuroStruct.signals(app.orChI.ord,:),[app.nCh,1,app.dtaLen]));
                        % set specific gain to Analog Input channels, which
                        % are the fourth and third to last:
%                         aiCh = app.nCh-3:app.nCh-2;
                    end
                    
                    if app.SubtractMean.Value
                        m = mean(app.LFP(1:end-4,:,:),1);
                        %                         m = reshape(m,1,1,[]);
                        app.LFP(1:end-4,:,:) = app.LFP(1:end-4,:,:) - m;
                    end
                    
                    if flagExtSynch
                        extract_trials(app)
                    end
                    
                    app.workLFP = app.LFP;
                    
                case 'Matlab'
                    disp([num2str(toc) ' Begin loading of .mat data'])
                    load(app.dtaComplete)
                    
                    app.LFP = data.lfp;
                    app.nCh = size(app.LFP,1);
                    app.nTrials = size(app.LFP,2);
                    app.dtaLen = size(app.LFP,3);
                    setSP(app, 1/data.sp)
                    if isfield(data,'timeoffset')
                        app.trTimeOffset_txt.Value = data.timeoffset;
                    end
                    if isfield(data,'microvoltsCheck')
                        app.microVck.Value = logical(data.microvoltsCheck);
                    end
                    
                    if flagExtSynch
                        extract_trials(app)
                    end
                    
            end
            
            % end of specific loading routines
            app.workLFP = app.LFP;
            disp([num2str(toc) ' End of data loading.'])
            
            % standardize
            if app.Standardize_CheckBox.Value
                mu = mean(app.LFP,[2 3]);
                stan = std(app.LFP,0,[2 3]);
                app.LFP = (app.LFP-mu)./stan;
                app.workLFP = app.LFP;
            end
            
            % Trial concatenation
            % Concatenate multiple traces if you want to analyze them
            % alltogether. New traces will be concatenated to the current
            % one as new trials.
            if app.concat_ck.Value && app.concat_tr.Value
                if isempty(app.storedLFP)
                    % this one is the first of the series of traces be
                    % concatenated
                    app.storedLFP = app.LFP(:,flagSkipFirstTrial+1:end,:);
                else
                    % some traces have already been loaded. In order to
                    % concatenate them properly, their lengths must
                    % coincide. Cut the longest one accordigly to the length
                    % of the shortest.
                    storeDim = size(app.storedLFP);
                    if storeDim(1) ~= app.nCh %quality control
                        error('Mismatch in the number of channels')
                    end
                    storeLen = min([storeDim(3), app.dtaLen]);
                    app.storedLFP = cat(2, app.storedLFP(:,:,1:storeLen), app.LFP(:,flagSkipFirstTrial-alreadySkipped+1:end,1:storeLen));
                end
            elseif app.concat_ck.Value && app.concat_time.Value
                if isempty(app.storedLFP)
                    % this one is the first of the series of traces be
                    % concatenated
                    app.storedLFP = app.LFP(:,1,:);
                else
                    storeDim = size(app.storedLFP);
                    if storeDim(1) ~= app.nCh %quality control
                        error('Mismatch in the number of channels')
                    end
                    app.storedLFP = cat(3, app.storedLFP, app.LFP);
                end
            else
                app.storedLFP = [];
            end
            
            % While concatenating traces, ypu can choose between two options concerning the opening pipeline:
            %   - When opening a new trace, just add it to app.storedLFP. No further
            %       computation will be performed until the "Concatenate now" button
            %       is pressed (Default).
            %   - When opening a new trace, add it to app.storedLFP, update
            %       app.LFP (by calling concatenateTraces(app) and go on with
            %       the computation routine (by calling openFile_partII(app) ).
            %       This is done if app.analyzeWhileConc_ck is checked.
            if app.analyzeWhileConc_ck.Value
                concatenateTraces(app)
            elseif ~app.concat_ck.Value
                openFile_partII(app)
            end
            
        end
        
        function extract_trials(app)
            % This function is called from inside "openFile" whenver the input
            % trace has to be divided in trials based on an internal
            % "synchronization channel".
            
            % 2019/11/12, GAB: up to date, this function is shared by the
            % following openFile submodes:
            %    'AxoP'
            %    'EEG Laura'
            %    'National Instr'
            %    'NewCastle'
            
            disp([num2str(toc) ' Extract single trials from a multitrial data set.'])
            % first, analyze the synch channel to extract the timing of each trial
            % binarize the trigger channel
            sCh = get(app.synchCh,'Value');
            %             for i=1:app.dtaLen
            %             %   TTL protocol
            %                if (app.LFP(sCh,1,i)<2), app.LFP (sCh,1,i) = 0;
            %                else
            %                    app.LFP(sCh,1,i) = 5;
            %                end
            %             end
            synchHigh = squeeze(app.LFP(sCh,1,:) >= 0.9);
            % synchDiff is a sort of derivative of the ttl signal.
            % it gets +5 when the ttl rises
            % it gets -5 when the ttl falls
            % it gets  0 when the ttl does not change
            synchDiff = diff(synchHigh);
            idxStart = find(synchDiff == 1)+1;
            idxStop  = find(synchDiff == -1);
            if idxStop(1)<idxStart(1)
                idxStop(1) = [];
            end
            if idxStart(end)>idxStop(end)
                idxStart(end) = [];
            end
            ttlDuration = idxStop - idxStart ; % duration of ttl signal in samples
            triggerInterdistance = [inf; diff(idxStart)]; % n of samples between two stimuli
            % Legality of the trigger event
            % A trigger event is legal if it meets 3 conditions:
            % 1) it is longer than triggerMinLength (sampling periods)
            % 2) it is at least 100 sp far away from the previous trigger event
            % 3) it is shorter than triggerMaxLength (still in SP units)
            % moreover: - the first trial must be entirely recorderd (offset included)
            %              - the last trial must end before the end of the recording
            legalTrig_logic = idxStart + app.timeOffset_i > 0 &...
                ttlDuration > floor((app.triggerMinLength.Value/1000)/app.sp) &...
                ttlDuration < ceil((app.triggerMaxLength.Value/1000)/app.sp) &...
                triggerInterdistance > ceil(app.trialMinLength.Value/app.sp);
            
            idxStart_legal = idxStart(legalTrig_logic);
            % update trial interdistance
            trialInterdistance_legal = [diff(idxStart_legal); inf];
            % Lets find the minimal inter trial distance
            minLen = min(trialInterdistance_legal(1:end-1)); % this will be the new app.dtaLen
            
            trialCounter = length(idxStart_legal);
            if ((app.dtaLen - idxStart(end)) < minLen) && legalTrig_logic(end)
                %if the last stimulus is valid but the recording stops too soon,
                % it means bad synch: the data finishes before the end of the last
                % sweep. So, skip the last stimulus.
                trialCounter = trialCounter - 1;
            end
            % store the parsed lfp in a temporary variable
            temp = nan(app.nCh,trialCounter,minLen);
            for tr = 1:trialCounter
                temp(:,tr,:) = app.LFP(:,1,idxStart_legal(tr)+app.timeOffset_i:idxStart_legal(tr)+minLen-1+app.timeOffset_i);
            end
            % store it in the LFP property
            app.LFP = [];
            app.LFP = temp;
            app.nTrials = trialCounter;
            app.expID = '';
            app.dtaLen = minLen;
        end
        
        function concatenateTraces(app)
            app.LFP = app.storedLFP;
            app.workLFP = app.LFP;
            app.dtaLen = size(app.LFP,3);
            app.nTrials = size(app.LFP,2);
            openFile_partII(app)
        end
        
        function openFile_partII(app)
            
            %%--flag copied from OpenFile function-----------------------------------
            exCh = app.excludeCh.Value;
            sCh = app.synchCh.Value;
            
            flagEx  = app.excludeChFlag.Value;
            flagExtSynch =  app.internalSynchCk.Value;
            flagSkipFirstTrial = app.skip1trialCk.Value;
            
            % Now the processing pipelines differentiate depending on the input mode.
            % 2019/05/25 Gab: modified because dataFormat is now a DropDown (it was a RadioButtonGroup previously)
            flagN   = strcmp(app.dataFormat.Value,'Default');
            flagL   = strcmp(app.dataFormat.Value,'EEG format');
            flagMV  = strcmp(app.dataFormat.Value,'Meyer VEP');
            flagSSP = strcmp(app.dataFormat.Value,'SEP');
            flagAxP = strcmp(app.dataFormat.Value,'AxoP');
            flagTRC = strcmp(app.dataFormat.Value,'TRC import');
            flagMEA = strcmp(app.dataFormat.Value,'IMM MEA');
            flagEDF = strcmp(app.dataFormat.Value,'EDF format');
            flagMat = strcmp(app.dataFormat.Value,'Matlab');
            %%-----------------------------------------------------------------------
            
            % now set the values for the ch and trial sliders based on the values
            % defined in the preceding code. If ch and trials are = 1 the control is
            % not updated and is made not visible.
            
            % Set all the stuff related to the number of channels
            app.currentCh = 1;
            app.currentTrial = 1;
            app.chEdit_Fcn() % gab, 2019/05/30
            app.trialEdit_Fcn() % gab, 2019/06/01
            app.multiChPlot.scales = ones(app.nCh,1);
            app.multiChPlot.show = true(app.nCh,1);
            
            %% also set channel and trial slider in the SingleUnit App, if it is open
            if ~isempty(app.SUapp)
                app.SUapp.setSpinners();
            end
            
            app.fileInNum = app.fileInNum + 1;
            drawnow    % refresh the GUI
            
            % Apply gain
            app.LFP = app.LFP./app.gainAmp;            % scale the LFP for the relative gain
            app.dtaLen = size(app.LFP,3);
            
            % GAB 30/10/2018
            % Update the time offset (both in time and indices)
            % The offset is useful when the "Synch on Ch" option is checked.
            % In that case, the trace is divided in trials based on the selected synchronization channel.
            % For every trial, time is measured relatively to the beginning of an event on the synchronization channel.
            % If "time offset" is set to 0, then trials are stored from t = 0 s to t = dta_len.
            % However, someone can be interested in looking at the peri-stimulus region,
            % including time BEFORE the stimulus onset.
            % The "time offset" allows to do this, since trials are stored from t = 0 + timeOffset
            % to t = dta_length + timeOffset.
            % So, for exemple, setting trTimeOffset.Value to 0.5, we can also look up to 0.5
            % seconds before the stimulus onset.
            
            if ~strcmp(app.dataFormat.Value,'Meyer VEP')
                app.timeOffset = app.trTimeOffset_txt.Value; %offset in seconds
                app.timeOffset_i = round(app.timeOffset/app.sp);
                % time Offset must be a multiple of the sampling period:
                app.timeOffset = app.timeOffset_i * app.sp;
                app.trTimeOffset_txt.Value = app.timeOffset;
            else
                app.timeOffset_i = 0;
                app.timeOffset = 0;
            end
            % Compute the time vector called app.x
            app.x = 0+app.timeOffset:app.sp:double(app.sp*(app.dtaLen-1))+app.timeOffset; %GAB 2019/02/04: offset added
            
            % set t axes
            app.tmin = app.x(1); %GAB: now tmin is no more 0: let's consider the offset
            app.tmax = app.x(end); %GAB: now tmin is no more dtaLen*sp: let's consider the offset
            app.tminTxt.Value = app.tmin;
            app.tmaxTxt.Value = app.tmax;
            
            % break point here if you want to access the loaded data before computing the
            % band-passed data set.
            if ~flagSSP
                disp([num2str(toc) ' Begin computation of band passed data.'])
                computeBPandNotch(app)   % elements of handles will be modified at this call
                computePowerInTime(app)   % temporal profile of power
            end
            disp([num2str(toc) ' Compute the data spectrograms.'])
            computeSpectrogram(app)
            
            % Update the status "led" indicator to GREEN because now band passed
            % LFP and BPpower are computed according to the settings
            % that are visible in the user interface
            app.BPsynch_led1.Color = 'g';
            app.BPsynch_led2.Color = 'g';
            
            % ---------------------------------------------------------------------------------------------------------------------
            
            disp([num2str(toc) ' Passing data to the active external processor.'])
            % now verify whether the external processors are active and, if they are, call
            % them up passing them the proper data.
            
            if app.powerSpectraCk.Value
                % call the ZebraSpectre code for spectra computation
                % we are here after opening a new data. Set the proper default values in the ZebraSpectre GUI
                %                 Enrico 20190410
                %                 All the settings for the spectra time window calculation
                %                 have been commented out, so the spectra are calculated on
                %                 the default values that are in the zebraspectre figure
                %                 app.spectreApp.fromTtxt.Value = 0;      % beginning of the time window for spectre computation
                %                 app.spectreApp.toTtxt.Value = app.tmax; % end of the time window for spectre computation
                %
                %                 app.spectreApp.fromT2txt.Value = 0;      % beginning of the second time window for spectre computation
                %                 app.spectreApp.toT2txt.Value = app.tmax; % end of the second time window for spectre computation
                
                app.spectreApp.computeSpectra_function() % Calculate and plot the spectrum
            end
            
            if app.analyzePWdistributionCk.Value
                analyzePowerDistribution(app)
            end
            
            if app.nTrials > 1
                disp([num2str(toc) ' Compute averages on trials.'])
                % compute al relevant averages. First, mean LFP
                % compute mean band passed data
                % compute mean spectrogram and 'gamma' band power
                computeMeanOnTrials(app)
            end
            
            % Insert a breakpoint here if you want to access directly to the averaged
            % trials. app.meanLFP (nCh, 1:app.dtaLen)
            
            
            disp([num2str(toc) ' Plot data.'])
            app.ymaxTxt_lfp.Value = inf;  % reset main axes y limits
            app.yminTxt_lfp.Value = -inf; % reset main axes y limits
            
            
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
            
            disp([num2str(toc) ' Create summary table.'])
            % SUMMARY TABLE
            % compute the total RMS power in the five bands
            if ~flagSSP
                nBP = app.NumberofBPsEditField.Value; % BP number
                app.RMS = nan(nBP,app.nCh,app.nTrials); % initialization of the RMS vector
                for iCh = 1:app.nCh
                    if ~(flagExtSynch && iCh==sCh) % if ext synch the channel is excluded from the output
                        if ~(flagExtSynch && flagEx && iCh==exCh) % exclude channel
                            for iTr = 1:app.nTrials
                                app.cnt = app.cnt + 1;
                                for iBP = 1:nBP
                                    if app.BPcomputed(iBP)
                                        tmp(1:app.dtaLen) = app.bandPassed_LFP(iBP,iCh,iTr,1:app.dtaLen);
                                        app.RMS(iBP,iCh,iTr) = rms(tmp);
                                    end
                                end
                                % now we place this data in a summary table that will be loaded with a
                                % number of fun fact about the data...
                                % data must be converted to the cell format in the following statements
                                
                                app.dtaSummary(app.cnt,1) = cellstr(app.dir_in);
                                app.dtaSummary(app.cnt,2) = cellstr(app.file_in);
                                %                                 if app.autoMode
                                %                                     app.dtaSummary(app.cnt,3) = cellstr('0');
                                %                                 else
                                %                                     app.dtaSummary(app.cnt,3) = cellstr(app.fileTime);
                                %                                 end
                                switch class(app.fileTime)
                                    case 'char'
                                        app.dtaSummary(app.cnt,3) = cellstr(app.fileTime);
                                    case 'double'
                                        app.dtaSummary(app.cnt,3) = cellstr(num2str(app.fileTime));
                                    otherwise
                                        app.dtaSummary(app.cnt,3) = cellstr('0');
                                end
                                app.dtaSummary(app.cnt,4) = num2cell(iCh);
                                app.dtaSummary(app.cnt,5) = num2cell(iTr);
                                app.dtaSummary(app.cnt,6:6+nBP-1) = reshape(num2cell((app.RMS(:,iCh,iTr))),1,[]); % RMS power for all the BPs arranged as a row
                                app.dtaSummary(app.cnt,6+nBP) = cellstr('Comments here');
                            end
                        end
                    end
                end
                app.outable.Data = app.dtaSummary;
            else
                % Put here something if you want to compute 'some' metric of the SSP
                % power...
            end
            disp('Computation completed')
        end
        
        function outBPpowers(app, bpSel, timeFrom, timeTo, fileName)
            % find the indices corresponding to the selected time interval
            plottedIdx = (app.BPpower(bpSel).time >= timeFrom) & (app.BPpower(bpSel).time <= timeTo);
            % initialize the output variable
            tmpOut = nan(sum(plottedIdx),app.nCh+1); % +1 because there is one more column, for time
            % the first column is the time column
            tmpOut(:,1) = reshape(app.BPpower(bpSel).time,[],1);
            % the column from the second to the last will contain the channels
            tmpOut(:,2:end) = squeeze(app.BPpower(bpSel).power(:,app.currentTrial,:))';
            % prepare output format
            fmtSt = [];
            for ii=1:app.nCh      % add 1 for the x axis.
                fmtSt=[fmtSt '%10.8f '] ;
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in fileName];
            fid = fopen(fileOut,'w');
            fprintf(fid,fmtSt,tmpOut'); % transpose because fprintf works on column order
            fclose(fid);
        end
        
        
        function plotMultiBand(app)
            % Here we plot the multi band window according to the selected bands
            flagSSP = strcmp(app.dataFormat.Value,'SEP');
            
            if (~flagSSP)
                tmp = [];
                cla(app.wide_plot_BP)
                hold(app.wide_plot_BP, 'on')
                flagTr = app.plotTrCk.Value;
                flagMd = app.plotMeanFlag.Value;
                
                % 2019/05/26 Gab: all BPs in a for loop
                for selBP = 1:size(app.frequencyBand,1)
                    if app.BP_4_plot(selBP) && app.BPcomputed(selBP)
                        if flagTr
                            tmp(1:app.dtaLen) = app.bandPassed_LFP(selBP,app.currentCh, app.currentTrial, 1:app.dtaLen);
                            plot(app.wide_plot_BP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color',app.BP_color(selBP,:),'LineWidth',0.5);
                        end 
                        if flagMd
                            tmp(1:app.dtaLen) = app.meanBP(selBP, app.currentCh,1:app.dtaLen);
                            plot(app.wide_plot_BP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color',app.BP_color(selBP,:),'LineWidth',2);
                        end
                    end
                end
                
                axis(app.wide_plot_BP, [app.tmin, app.tmax, app.yminTxt_BP.Value, app.ymaxTxt_BP.Value])
                app.wide_plot_BP.XTick = [];
                flagUnits = app.microVck.Value;
                if flagUnits, app.wide_plot_BP.YLabel.String = 'LFP (microV)';
                else
                    app.wide_plot_BP.YLabel.String = 'LFP (mV)';
                end
            end
            app.wide_plot_BP.InnerPosition(1) = app.wide_plot_up.InnerPosition(1);
            app.wide_plot_BP.InnerPosition(3) = app.wide_plot_up.InnerPosition(3);
        end
        
        function plotWideWindow(app)
            %% The plot function requires a column vector. If the data is a matrix, the X axis moves
            % along the row. If more than n>1 columns are present, the function will
            % plot the various data in different colours.
            
            % Find out the index of the left and right limits of the plot window
            tMin = app.tminTxt.Value;
            tMax = app.tmaxTxt.Value;
%             % convert the limits to array indexes
%             tMinI = floor(tMin/app.sp+1 - app.timeOffset_i); %GAB 2019/02/24: offset added
%             tMaxI = floor(tMax/app.sp+1 - app.timeOffset_i); %GAB 2019/02/24: offset added
            % quality control
%             if tMinI <= 1, tMinI = 1;
%             end
%             
%             if tMaxI > app.dtaLen, tMaxI = app.dtaLen;
%             end
%             tlogic = (app.x >= tMin) && (app.x <= tMax);

            app.minLFP = min(app.workLFP(app.currentCh, app.currentTrial,:));
            app.maxLFP = max(app.workLFP(app.currentCh, app.currentTrial,:));
            traceOut = [];
            % save axes inner position for later
            inPos = app.wide_plot_up.InnerPosition;
            
            % axes(app.wide_plot_up)
            cla(app.wide_plot_up)
            %            app.wide_plot_up.ColorOrderIndex = 0; % Not sure what this is for. Enrico 11/10/2018
            hold(app.wide_plot_up, 'on')
            
            flagMd = app.plotMeanFlag.Value;     % plot mean trace
            flagMP = app.plotMultiCh.Value;      % plot all channels
            flagTr = app.plotTrCk.Value;         % plot single trials
            flagAutoOff = app.autoOffCk.Value;
            flagTempl = app.plotTemplateCk.Value;
            
            if (flagAutoOff==0)
                range = 0;  % take this out if you want to auto-offset the averages
            else
                range = (app.maxLFP-app.minLFP)/2;
            end
            
            if flagMP       % plot all channels
                % if multi channel no template!
                
                % 2019/05/26 Gab: possibility to differently rescale channels
                scalingFactors = app.multiChPlot.scales;
                traceOut(1:app.nCh,1:app.dtaLen) = app.LFP(1:app.nCh,app.currentTrial,1:app.dtaLen) .* scalingFactors;
%                 traceOut(1:app.nCh,:) = app.LFP(1:app.nCh,app.currentTrial,tlogic) .* scalingFactors;
                
                
                % add a vertical offset
                traceOut = traceOut - (0:app.nCh-1)'.*range;
                
                % prepare visibility array
                options = ["off","on"];
                isvisib = options(uint16(app.multiChPlot.show)+1)';
                
                % next: plot multiplot with the same colors
                app.wide_plot_up.ColorOrderIndex = 1;
                app.wide_plot_up.NextPlot = 'add';
                if flagTr   % plot the individual trials
                    % the additional properties are necessary to stop the plot to
                    % intercepts the mouse clicks
%                     app.multiChPlot.plot = plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI), 'HitTest', 'off');
                    app.multiChPlot.plot = plot(app.wide_plot_up, app.x,traceOut, 'HitTest', 'off');
                    arrayfun(@(myobj,prop)(set(myobj,'Visible',prop)),app.multiChPlot.plot,isvisib);
                end
                if (flagMd && app.nTrials > 1)  % plot all means with offset
                    % 2019/05/26 Gab: possibility to differently rescale channels in the multiplot
                    traceOut = [];
                    traceOut(1:app.nCh,1:app.dtaLen) = app.meanLFP(1:app.nCh,1:app.dtaLen) .* scalingFactors;
                    % add vertical offset
                    traceOut = traceOut - (0:app.nCh-1)'.*range;
                    
                    app.wide_plot_up.ColorOrderIndex = 1;
%                     app.multiChPlot.plotMean = plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI),'LineWidth',2, 'HitTest', 'off');
                    app.multiChPlot.plotMean = plot(app.wide_plot_up, app.x,traceOut,'LineWidth',2, 'HitTest', 'off');
                    arrayfun(@(myobj,prop)(set(myobj,'Visible',prop)),app.multiChPlot.plotMean,isvisib);
                end
                
            else
                if flagTr   % plot the individual trials
                    traceOut(1:app.dtaLen) = app.LFP(app.currentCh,app.currentTrial,1:app.dtaLen);
%                     plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(tMinI:tMaxI),'Color','k', 'HitTest', 'off');
                    plot(app.wide_plot_up, app.x,traceOut,'Color','k', 'HitTest', 'off');
                end
                if (flagMd && app.nTrials > 1)
                    plot(app.wide_plot_up, app.x,app.meanLFP(app.currentCh,:),'Color','k',...
                        'LineWidth', 2, 'HitTest', 'off');
                end
                if (flagTempl && app.nTrials>1 && flagTr)
                    % plot the template for each response with the correct scaling
                    % factor and offset
                    l = length(app.templ); 
                    t0 = app.templateFrom.Value;
                    for ii= 1:app.ntemp
                        a = app.EPamplitude(app.currentCh,app.currentTrial,ii);
                        templ = a * app.templ(app.currentCh,:) + app.EPoffset(app.currentCh,app.currentTrial,ii);
                        x0 = t0 + app.dtaLen*app.sp/2 * (ii-1);
                        xend = x0 + l*app.sp;
                        xtempl = x0:app.sp:xend;
                        plot (app.wide_plot_up, xtempl(1:l), templ(1:l),'LineWidth',2, 'HitTest', 'off');
                    end
                end
            end
            
            flagUnits = app.microVck.Value;
            if flagUnits, app.wide_plot_up.YLabel.String = 'LFP (microV)';
            else
                app.wide_plot_up.YLabel.String = 'LFP (mV)';
            end
            %             if isempty(event) %Gab: set y axis limits
            %                 axis(app.wide_plot_up, [tMin tMax -inf inf]);
            %                 app.ymaxTxt_lfp.Value = app.wide_plot_up.YLim(2);
            %                 app.yminTxt_lfp.Value = app.wide_plot_up.YLim(1);
            %             else
            %                 axis(app.wide_plot_up, [tMin tMax event.yLim(1) event.yLim(2)]);
            %             end
            axis(app.wide_plot_up, [tMin, tMax, app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value]);
            
            
            app.wide_plot_up.InnerPosition(2) = inPos(2);
            app.wide_plot_up.InnerPosition(4) = inPos(4);
            app.wide_plot_up.InnerPosition(1) = inPos(1);
            app.wide_plot_up.InnerPosition(3) = inPos(3);
            drawnow
        end
        
        
        function setCh(app, nCh)
            app.nCh = nCh;
            set (app.chEdit,'Value',app.nCh);
            app.currentCh = 1;
            chEdit_Fcn(app) %Gab, 2019/05/29
        end
        
        function setGain(app, gain)
            app.gainAmp = gain;
            set(app.gain,'Value', app.gainAmp);
        end
        
        function setSP(app, freq)
            % This function set the sampling period, frequency and all the relative numers in the GUI
            app.sp = 1/freq;        % sampling period in s
            app.acqF = freq;        % sampling frequency in Hz
            set (app.spEdit,'Value',app.sp);
            set (app.sf,'Value',freq/1000);
            app.timeOffset_i = round(app.timeOffset/app.sp);
        end
        
        function startSpectraPlot(app)
            % this function causes the refresh of the spectra plots by activating a
            % callback in the ZebraSpectre figure
            
            %GAB 11/03/2018
            %   if the condition is not true, it means that Spectre window is open but
            %   spectra have not been calculated (and plotted) already.
            if ~isempty(app.spectreApp.powerSpectra.Children)
                %ZebraSpectre('maxFreq_Callback',app.spectreData.maxFreq,[],app.spectreData)
                app.spectreApp.plotPowerSpectra()
            end
        end
        
        function templUpdateFunction(app)
            inf_i = round(app.hRoi.Vertices(1,1)/app.sp)+1 - app.timeOffset_i; %index of the roi interval left edge
            sup_i = round(app.hRoi.Vertices(3,1)/app.sp)+1 - app.timeOffset_i; %index of the roi interval right edge
            [~,p100_i] = min(app.meanLFP(app.currentCh,inf_i:sup_i));
            p100_i = p100_i +inf_i-1;
            p100_t = (p100_i-1)*app.sp + app.timeOffset;
            bef = str2double(app.p100bef.String);
            aft = str2double(app.p100after.String);
            app.templateFrom.String = num2str(p100_t-bef);
            app.templateTo.String = num2str(p100_t+aft);
        end
        
        %% eventDetection functions:::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::
        function plotStatesFun(app)
            len = app.dtaLen;
            spp = app.sp;
            app.evDet.tmin = app.leftTime.Value;
            app.evDet.tmax = app.rightTime.Value;
            
            cla(app.plotStates)
            time = app.x; %GAB 2019/02/24
            plot(app.plotStates,time,app.evDet.LFP,'black');
            
            for usi=1:app.evDet.NUS
                i1 = app.evDet.upstates.fromI(usi);
                i2 = app.evDet.upstates.toI(usi);
                plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'red');
            end
            
            for usi=1:app.evDet.NDS
                i1 = app.evDet.downstates.fromI(usi);
                i2 = app.evDet.downstates.toI(usi);
                plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'green');
            end
            app.plotStates.XLim = [app.evDet.tmin app.evDet.tmax];
            app.plotStates.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            
            %axes(app.statesTrack);
            set(app.statesTrack, 'XTick', []);
            cla(app.statesTrack)
            plot (app.statesTrack,app.evDet.timeSPG,app.evDet.SPG);
            
            app.statesTrack.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            xl = app.statesTrack.XLim;
            if app.autoThr.Value
                app.evDet.highThrLine = line(app.statesTrack, [app.evDet.timeSPG(1) app.evDet.timeSPG(end)], [app.evDet.autoevThr app.evDet.autoevThr], 'color', 'r');
                app.evDet.lowThrLine = line(app.statesTrack, [app.evDet.timeSPG(1) app.evDet.timeSPG(end)], [app.evDet.autoblThr app.evDet.autoevThr], 'color', 'g', 'LineStyle', '--');
            else
                app.evDet.highThrLine = line(app.statesTrack, [app.evDet.timeSPG(1) app.evDet.timeSPG(end)], [app.eventThr.Value app.eventThr.Value], 'color', 'r');
                app.evDet.lowThrLine = line(app.statesTrack, [app.evDet.timeSPG(1) app.evDet.timeSPG(end)], [app.baselineThr.Value app.baselineThr.Value], 'color', 'g', 'LineStyle', '--');
            end
            app.statesTrack.XLim = [app.evDet.tmin app.evDet.tmax];
        end
        
        function refineEdges(app)
            erodeFlag = get (app.erodeCk,'Value');
            if erodeFlag
                % now refine the edges of the states
                for usi=1:app.evDet.NUS
                    % check the edges of the US: if they are farther away from the median
                    % than 2*SD the event is eroded to 2*SD. Check first left edge.
                    edgeI = app.evDet.upstates.fromI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV(usi))<0.5*app.evDet.upstates.SD(usi))
                        % erode to the right
                        if edgeI==app.dtaLen, break
                        end
                        edgeI = edgeI+1;
                        app.evDet.upstates.fromI(usi) = edgeI;
                        value = tempData(edgeI);
                        % check not to have passed the end of the US!
                    end
                    edgeI = app.evDet.upstates.toI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV)<2*app.evDet.upstates.SD)
                        if edgeI>1
                            % erode to the left
                            edgeI = edgeI-1;
                            app.evDet.upstates.toI(usi) = edgeI;
                            value = tempData(edgeI);
                            % check not to have passed the beginning of the US!
                        else
                            break
                        end
                    end
                    % now update the upstate definition
                    app.evDet.upstates.toT(usi) = (app.evDet.upstates.toI(usi)-1)*spp;
                    app.evDet.upstates.fromT(usi) = (app.evDet.upstates.fromI(usi)-1)*spp;
                    app.evDet.upstates.deltaT(usi) = app.evDet.upstates.toT(usi)-app.evDet.upstates.fromT (usi);
                    
                    app.evDet.upstates.medianV(usi) = median(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                    app.evDet.upstates.SD(usi) = std(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                end
            end
        end
        
        
        function DeleteRowTable(app,mytable,SelIndices)
            % Enrico
            mytable.Data(min(SelIndices(:,1)):max(SelIndices(:,1)) , : ) = [];
        end
        

        function BPsynch(app,idx)
            % 2019/05/26 Gab.
            %             This function updates the variables relative to band pass windows when BPs are edited.
            %                 idx: if this function is called because a group of BPs has been deleted from the
            %                             delete button (DeleteBpBtn), idx represents the indices of the removed BPs
            %                      if this function is called because the number of BPs has been edited (by NumberofBPsEditField),
            %                             idx value takes is Inf, meaning that changes regard the last BPs
            %                      if this function is called by the startupFcn or when a BP window is edited in the table,
            %                             idx = 0. In this mode BP_color and BP_4_plot are not changed
            
            
            % update app variables related to BPs
            app.frequencyBand_ck = cell2mat(app.BpTable.Data(:,2));
            app.frequencyBand = cell2mat(app.BpTable.Data(:,3:4));
            
            % by default, the enabled BPs are selected for being plotted
            app.BP_4_plot = app.frequencyBand_ck;
            
            % adjust the dropDown object for BP plot selection
            oldBPs = size(app.BPplot_drop.Items,2); % the old number of BPs
            nBP = app.NumberofBPsEditField.Value; % the new number of BPs
            newItems = [ repmat('BP ',nBP,1), num2str((1:nBP)','% 2u') ];
            app.BPplot_drop.Items = cellstr(newItems);
            if nBP < oldBPs
                app.BPplot_drop.Value = app.BPplot_drop.Items{end};
            end
            
            % adjust the dropDown object for BP selection in EventDetection
            app.detectionBP_dropDown.Items = [cellstr(newItems);{'SPG envelope'}];
            app.powerUS_dropDown.Items = [cellstr(newItems);{'SPG envelope'}]; % gab 2020-08-22
            if nBP < oldBPs
                app.BPplot_drop.Value = app.BPplot_drop.Items{end};
            end
            % also adjust the spinner for the Multipeak Waves analysis
            if nBP > 1
                app.detectionBPmultiPeak_spinner.Limits(2) = nBP;
                app.detectionBPmultiPeak_spinner.Enable = 1;
            else
                app.detectionBPmultiPeak_spinner.Value = 1;
                app.detectionBPmultiPeak_spinner.Enable = 0;
            end
            
            % update the upper limit of the slider for the selection of the
            % BP to be used for the analysis of the power during response
            if nBP>1
                app.sliderBPpower.Limits(2) = nBP;
                app.sliderBPpower.Enable = 'on';
                sliderBPpower_Callback(app,[])
            else
                app.sliderBPpower.Limits(2) = Inf;
                app.sliderBPpower.Value = 1;
                sliderBPpower_Callback(app,[])
                app.sliderBPpower.Enable = 'off';
            end
            
            % Update the number of columns in the first ZebraSays table
            %Update column names
            oldColNames = app.outable.ColumnName; % store old names
            oldData = app.outable.Data; % store old values
            txt = repmat(' RMS',nBP,1);
            BPcolNames = [newItems, txt]; % prepare column names by appending the string 'RMS' to the BP names
            newColNames = [oldColNames(1:5);cellstr(BPcolNames);oldColNames(end)];
            app.outable.ColumnName = newColNames;
            
            % Update the table content
            if ~isempty(app.outable.Data)
                switch idx(1)
                    case 0
                        % do nothing
                    case inf
                        % the number of BPs has been edited
                        if nBP < oldBPs
                            % we have less BPs now. "Cut" the table
                            newData = [oldData(:,1:5+nBP), oldData(:,end)];
                            app.outable.Data = newData;
                            app.dtaSummary = newData;
                        elseif nBP > oldBPs
                            % we have more BPs now. Add their columns
                            % before the last one and also move the comments to the last one.
                            newData = [oldData(:,1:end-1), cell(size(oldData,1),nBP-oldBPs), oldData(:,end)]; % add new empty columns before the last (comments)column
                            %                             newData(:,size(oldData,2)) = cell(size(oldData,1),1); % remove the comments from the uncorrect location
                            %                             newData(:,end) = oldData(:,end); % copy the comments in the last column
                            app.outable.Data = newData;
                            app.dtaSummary = newData;
                        end
                    otherwise
                        % some BPs have been removed, so remove its column
                        newData = oldData;
                        newData(:,5+idx) = [];
                        app.outable.Data = newData;
                        app.dtaSummary = newData;
                end
            end
            
            
            % manage BP colors:
            switch idx(1)
                case 0
                    % do nothing
                case inf
                    % the number of BPs has been edited
                    newColors = [];
                    if nBP < oldBPs
                        % we have less BPs now. "Cut" the color and selection arrays
                        newColors = app.BP_color(1:nBP,:);
                        app.BP_color = newColors;
                        
                    elseif nBP > oldBPs
                        % new BPs have been added. Assign default colors to them
                        
                        % First, if the number of BPs used is more than the default BP colors,
                        % add grey entries to BP_color_default
                        defCols = size(app.BP_color_default,1);
                        if nBP > defCols
                            newColors = ones(nBP,3).*0.5;
                            newColors(1:defCols,:) = app.BP_color_default;
                            app.BP_color_default = newColors;
                        end
                        
                        newColors = [app.BP_color; app.BP_color_default(oldBPs+1:nBP,:)];
                        app.BP_color = newColors;
                    end
                otherwise
                    % a BP has been removed, so remove it also from the color
                    % matrix
                    app.BP_color(idx,:)  = [];
            end
            
            % Update the status "led" indicator
            switch idx(1)
                case {0 inf}
                    % turn the led RED. You need to call computeBP to
                    % synchronize Zebra with the current changes
                    %         % Set sunchronizationLED color to red
                    app.BPsynch_led1.Color = 'r';
                    app.BPsynch_led2.Color = 'r';
                otherwise
                    % do nothing: when a BP is removed, the corresponding
                    % bandPassed_LFP and BPpower are removed too. LED remain unchanged
            end
        end
        
    end
    
    methods (Access = public)
        
        function SelTab2Clip(app,mytable,selectedIndices)
            %         selectedIndices is a vector [a b c d], in which a and b are the
            %         row and column number of the top-left element of the selection. c
            %         and d are the row and column number of the bottom-right element.
            
            %         To copy the data from the table of zebrasays to the clipboard
            str = '';
            for i = selectedIndices(1):selectedIndices(3)
                for j = selectedIndices(2):selectedIndices(4)
                    if j == selectedIndices(4)
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f',str,mytable.Data{i,j});
                        end
                    else
                        if isa(mytable.Data{i,j}, 'char')
                            str = sprintf('%s\t', str, mytable.Data{i,j});
                        else
                            str = sprintf('%s%f\t',str, mytable.Data{i,j});
                        end
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard('copy',str);
        end
        
        function BP_4_plot_updateFcn(app,tab,event)
            app.BP_4_plot = [event.Source.Data{:,1}];
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            % Update the Selection button
            app.BPplot_ck.Value = app.BP_4_plot(selBP);
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end
        
        function chEdit_Fcn(app)
            
            app.chEdit.Value = app.nCh;
            
            if app.nCh>1
                %set(app.chSlider_1, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                set(app.chText_1,'Text',['Ch ' num2str(app.currentCh)])
                set(app.chText_2,'Text',['Ch ' num2str(app.currentCh)])
                set(app.chSlider_1, 'Limits', [1 app.nCh])
                set(app.chSlider_2, 'Limits', [1 app.nCh])
                set(app.chSlider_1, 'Value', 1)
                set(app.chSlider_2, 'Value', 1)
                
                set(app.chSlider_1,'Visible','on')
                set(app.chSlider_2,'Visible','on')
                set(app.chText_1,'Visible','on')
                set(app.chText_2,'Visible','on')
            else
                set(app.chSlider_1,'Visible','off')
                set(app.chSlider_2,'Visible','off')
                set(app.chText_1,'Visible','off')
                set(app.chText_2,'Visible','off')
            end
            app.currentCh = 1;
            
            %             % Gab 2019/05/29 Update the table of chanels' gain (to be implemented soon) and the
            %             % table of scale factors for the multichannel plot
            %             oldCh = size(app.PlotScaleTab.Data,1);
            %             if app.nCh < oldCh
            %                 % "cut" the old table
            %                 %                 app.chGainTable.Data = app.chGainTable.Data(1:app.nCh,:); % to be implemented
            %                 app.PlotScaleTab.Data = app.PlotScaleTab.Data(1:app.nCh,:);
            %             else
            %                 % add new entries. New gain values are set to 1 by default
            %                 %                 app.chGainTable.Data = [app.chGainTable.Data; [(oldCh+1:app.nCh)', ones(app.nCh-oldCh,1)] ]; % to be implemented
            %                 app.PlotScaleTab.Data = [app.PlotScaleTab.Data; [(oldCh+1:app.nCh)', ones(app.nCh-oldCh,1)] ];
            %             end
        end
        
        function trialEdit_Fcn(app)
            app.trialsEdit.Value = app.nTrials;
            
            if app.nTrials>1
                %set(app.chSlider_1, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                set(app.trialText_1,'Text',['Trial ' num2str(app.currentTrial)])
                set(app.trialText_2,'Text',['Trial ' num2str(app.currentTrial)])
                set(app.trialSlider_1, 'Limits', [1 app.nTrials])
                set(app.trialSlider_2, 'Limits', [1 app.nTrials])
                set(app.trialSlider_1, 'Value', 1)
                set(app.trialSlider_2, 'Value', 1)
                
                set(app.trialSlider_1,'Visible','on')
                set(app.trialSlider_2,'Visible','on')
                set(app.trialText_1,'Visible','on')
                set(app.trialText_2,'Visible','on')
            else
                set(app.trialSlider_1,'Visible','off')
                set(app.trialSlider_2,'Visible','off')
                set(app.trialText_1,'Visible','off')
                set(app.trialText_2,'Visible','off')
            end
            app.currentTrial = 1;
        end


        function exportUStraces(app)
            % GAB 2020/04/13
            % exports LFP traces around US starting points.
            
            % turn time intervals into indices
            beforeI = round(app.exp_beforeUS.Value/app.sp);
            afterI = round(app.exp_afterUS.Value/app.sp);
            %             app.evDet.upstates.fromI
            cCh = app.currentCh;
            cTr = app.currentTrial;
            
            % store in a cell array the LFP windows around the beginning of every US. If a
            % window exceed the trace limits, an empty cell is returned.
            traces = arrayfun(@(x) extractTrace(app.LFP,cCh,cTr,beforeI,afterI,x), app.evDet.upstates.fromI, 'UniformOutput',false);
            % Also store the indices of the selected upstates. Exclude the
            % indices corresponding to the US that are going to be excluded
            % in the following line of this code (i.e. the empty ones).
            idx = 1:length(traces);
            idx(cellfun(@isempty,traces)) = [];
            % Turn the cell array into a matrix, since all the LFP windows has
            % the same lwngth. Empty cells are removed.
            lfp.traces = cell2mat(traces)';
            % define the time vector
            lfp.timeVect = (0-beforeI:size(lfp.traces,2)-1-beforeI) *app.sp;
            
            % do the same for the power envelope.
            cBP = find(strcmp(app.detectionBP_dropDown.Value,app.detectionBP_dropDown.Items));
            % find the sampling period of the rms power
            sp_env = app.BPpower(cBP).time(2)-app.BPpower(cBP).time(1);
            % turn time intervals into indices of the rms power
            beforeI_env = round(app.exp_beforeUS.Value/ (sp_env));
            afterI_env  = round(app.exp_afterUS.Value/ (sp_env));
            % get rms traces as for lfp
            traces = arrayfun(@(x) extractEnv(app.BPpower(cBP),cCh,cTr,beforeI_env,afterI_env,x,sp_env), app.evDet.upstates.fromT, 'UniformOutput',false );
            env.traces = cell2mat(traces)';
            env.timeVect = ((0:size(env.traces,2)-1)-beforeI_env) * sp_env;
            
            % do the same for the contralateral hemisphere
            if app.doubleHemCheckBox.Value
                tracesOtherHem = arrayfun(@(x) extractTrace(app.LFP,app.otherHem.Value,cTr,beforeI,afterI,x), app.evDet.upstates.fromI, 'UniformOutput',false);
                tracesOtherHem = cell2mat(tracesOtherHem)';
                % concatenate to the other hemisph generating another dimension
                tmp = cat(3,lfp.traces,tracesOtherHem);
                % reassign traces. Now its dimensions are: hemisphere*upstate*time
                lfp.traces = permute(tmp,[3,1,2]);
                
                % do the same for the power
                tracesOtherHem = cell2mat(arrayfun(@(x) extractEnv(app.BPpower(cBP),app.otherHem.Value,cTr,beforeI_env,afterI_env,x,sp_env), app.evDet.upstates.fromT, 'UniformOutput',false ))';
                % concatenate to the other hemisph generating another dimension
                tmp = cat(3,env.traces,tracesOtherHem);
                env.traces = permute(tmp,[3,1,2]);
            end
            
            
            % save the traces
            %             UStraces = struct('time',timeVect,'traces',traces);
            save([app.dtaComplete(1:end-4) '_UStraces_Ch ' num2str(cCh) '.mat'],'lfp','env','idx')
            
            
            function y = extractTrace(LFP,cCh,cTr,beforeI,afterI,x)
                y = [];
                if ( (x-beforeI) > 0 ) && ( (x+afterI) <= size(LFP,3) )
                    y = squeeze(LFP(cCh,cTr,x-beforeI:x+afterI));
                end
            end
            
            function y = extractEnv(env,cCh,cTr,beforeI,afterI,x,sp)
                y = [];
                % x is a time! Turn it into an index of the rms power
                x_i = round(x/sp)+1;
                if ( (x-beforeI*sp) >= 0 ) && ( (x_i+afterI) <= length(env.time) )
                    y = squeeze(env.power(cCh,cTr,x_i-beforeI:x_i+afterI));
                end
            end
            
        end
        
        function updateEvDetectAxesLimits(app)
            %GAB 2020/04/13
            axis(app.plotStates,[app.leftTime.Value, app.rightTime.Value, -inf, inf])
            axis(app.statesTrack,[app.leftTime.Value, app.rightTime.Value, -inf, inf])
        end
        
        function PlotHist(app, eventThr, baselineThr)
            % plot the histogram
            % axes (app.histoPlot);
            cla(app.histoPlot)
            bar(app.histoPlot,app.evDet.Hcenters,app.evDet.Hnelements);
            %axis ([leftH rightH 0 inf], 'auto y');
            app.histoPlot.XLim = [app.fromH.Value app.toH.Value];
            app.histoPlot.YLim = [0 inf];
            app.histoPlot.YLimMode = 'auto';
            plot(app.histoPlot,app.evDet.Hcenters,app.evDet.GaussFunct,'Color',[1 0 1],'LineWidth',2);
            yl = app.histoPlot.YLim;
            % Gab 2020-05-02
            gauss = @(x,a,mu,sig) ( a*exp( -1*(x-mu).^2 ./ 2 ./ sig.^2 ));
            if app.evDet.mu(1) < app.evDet.mu(2)
                plot(app.histoPlot,app.evDet.Hcenters,gauss(app.evDet.Hcenters,app.evDet.ampl(1),app.evDet.mu(1),app.evDet.sigma(1)),'g','LineWidth',1.5)
                plot(app.histoPlot,app.evDet.Hcenters,gauss(app.evDet.Hcenters,app.evDet.ampl(2),app.evDet.mu(2),app.evDet.sigma(2)),'r','LineWidth',1.5)
            else
                plot(app.histoPlot,app.evDet.Hcenters,gauss(app.evDet.Hcenters,app.evDet.ampl(1),app.evDet.mu(1),app.evDet.sigma(1)),'r','LineWidth',1.5)
                plot(app.histoPlot,app.evDet.Hcenters,gauss(app.evDet.Hcenters,app.evDet.ampl(2),app.evDet.mu(2),app.evDet.sigma(2)),'g','LineWidth',1.5)
            end
            if eventThr == baselineThr
                line(app.histoPlot,[eventThr eventThr],[yl(1) yl(2)], 'color', 'r');
                line(app.histoPlot,[baselineThr baselineThr],[yl(1) yl(2)], 'color', 'g', 'LineStyle', '--');
            else
                line(app.histoPlot,[eventThr eventThr],[yl(1) yl(2)], 'color', 'r');
                line(app.histoPlot,[baselineThr baselineThr],[yl(1) yl(2)], 'color', 'g');
            end
        end
        
        
        function [stateStruct,nStates] = processStatesOld(app,stateI)
            % old algorithm
            lengthState = length(stateI);
            firstI = stateI(1);
            firstTime = app.evDet.timeSPG (firstI);
            stateStruct.fromT (1) = firstTime;
            stateStruct.fromI (1) = int32(1+firstTime/app.sp); % spp is the sampling period, this converts the time to the LFP index
            nStates = 1; % keeps track of the number of up states
            
            for i=2:lengthState
                nextI = stateI(i); % loop through all the indexes with higher than threshold value
                if nextI==firstI+1
                    % continue the old US
                    firstI=nextI;
                    if i == lengthState
                        % we have reached the end of the track. close the US
                        endTime = app.evDet.timeSPG (firstI);
                        stateStruct.toT (nStates) = endTime;
                        stateStruct.toI (nStates) = int32(1+endTime/app.sp);
                        stateStruct.deltaT (nStates) = endTime-stateStruct.fromT (nStates);
                        stateStruct.enable (nStates) = 1;
                    end
                else
                    firstTime = app.evDet.timeSPG (firstI);
                    % beginning of a new US. Close the old US
                    stateStruct.toT (nStates) = firstTime;
                    stateStruct.toI (nStates) = int32(1+firstTime/app.sp);
                    stateStruct.deltaT (nStates) = firstTime-stateStruct.fromT (nStates);
                    stateStruct.enable (nStates) = 1;
                    nStates = nStates + 1;
                    % open new US
                    stateStruct.fromT (nStates) = app.evDet.timeSPG (nextI);
                    stateStruct.fromI (nStates) =  int32(1+app.evDet.timeSPG (nextI)/app.sp);
                    firstI = nextI;
                end
            end
            % if the last US does not end, discard it
            stateStruct.fromT = stateStruct.fromT(1:length(stateStruct.toT));
            stateStruct.fromI = stateStruct.fromI(1:length(stateStruct.toI));
            
            nStates = length (stateStruct.toI);      % just to make sure...
        end
        
        function exportDistrThr(app)
            distr = struct('Hcenters',app.evDet.Hcenters,'Hnelements',app.evDet.Hnelements,...
                'ampl',app.evDet.ampl,'mu',app.evDet.mu,'sigma',app.evDet.sigma,...
                'eventThr', app.eventThr.Value,'baseThr',app.baselineThr.Value);
            save([app.dtaComplete(1:end-4) '_PowerDistr_Ch ' num2str(app.currentCh) '.mat'],'distr')
        end
        
        function analyzeData_Function(app)
            analyzeData_Callback(app, [])
        end
        
        function extractEvents_Function(app)
            extractEvents_Callback(app, [])
        end
        
        function exportTab_btn_Function(app)
            exportTab_btn_Callback(app, [])
        end
        
        function chSlider_1_Function(app)
            chSlider_1_Callback(app, [])
        end
        
        function openFile_Function(app,selection,path_in)
            openFile(app,selection,path_in)
        end
        
        function loadParams_btn_Function(app)
            loadParams_btn_Callback(app,[])
        end
        function call_concat_ck_Callback(app)
            concat_ck_Callback(app,[])            
        end
        
        function evDet_plotSpectra(app)
            cla(app.powerSpectra);
            lim = [app.powerSpectra.XLim, app.powerSpectra.YLim];
            % plot the mean traces
            plot(app.powerSpectra,app.evDet.fUS,10*log10(app.evDet.pssUS.'),'red'); % spectra in decibels
            plot(app.powerSpectra,app.evDet.fDS,10*log10(app.evDet.pssDS.'),'green');
            lim(1) = get(app.minFreq,'Value');
            lim(2) = get(app.maxFreq,'Value');
            lim(3) = get(app.minPw,'Value');
            lim(4) = get(app.maxPw,'Value');
            app.powerSpectra.XLim = [lim(1) lim(2)];
            app.powerSpectra.YLim = [lim(3) lim(4)];
            set(app.powerSpectra,'XScale','log');
            app.powerSpectra.XLabel.String = 'Frequency (Hz)';
            app.powerSpectra.YLabel.String ='Magnitude (dB)';
        end
        
        function autoSave(app)
            %Table
            if app.AutoTable_ck.Value
                tmp = app.auto.outTab;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_tables.mat'], 'tmp');
            end
            
            %Spectra
            if app.AutoSpectra_ck.Value
                tmp = app.auto.outSpec;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_spectra.mat'], 'tmp');
            end
            
            %Spectrogram
            if app.AutoSpg_ck.Value
                tmp = app.auto.outSpg;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_spg.mat'], 'tmp');
            end
            
            %Traces
            if app.AutoTrace_ck.Value
                tmp = app.auto.outTraces;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_traces.mat'],'tmp')
            end
            
            %Source folders
            folds = app.auto.AutoFolders;
            save([app.AutoOutFileName_edit.Value(1:end-4) '_sources.mat'],'folds')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             Determine the operating system
            if ismac
                % Code to run on Mac platform
                app.PathSeparator = '/';
            elseif isunix
                % Code to run on Linux platform
                app.PathSeparator = '/';
            elseif ispc
                % Code to run on Windows platform
                app.PathSeparator = '\';
            else
                disp('Platform not supported')
            end
            
            % store the app folder
            tmp = mfilename('fullpath');
            sepind = regexp(tmp, filesep);
            app.zebraPath = tmp(1:sepind(end));
            
            app.sp = app.spEdit.Value; % sampling period in s
            app.acqF = 1/app.sp; % sampling frequency
            app.insetW = 5; % width of the inset window (s)
            app.lp = 0.1; % width of the low pass filter window % MIGHT BE UNUSED
            app.dir_in = '';
            app.gainAmp = app.gain.Value; % gain setting of the amplifier
            app.nCh = app.chEdit.Value; % number of channels
            app.alreadySkipped = 0;
            
            
            app.nTrials = app.trialsEdit.Value; % number of trials
            app.currentCh = app.chSlider_1.Value; % current channel
            
            sliderBPpower_Callback(app,[])
            
            app.filterOrder = app.filtOrder.Value; % butterworth filter order
            app.notch = [app.notchFrom.Value app.notchTo.Value]; % notch filter for 50 Hz removal
            app.BpTable.Data = [{'BP 1'}, {true},    {0},   {8};...
                {'BP 2'}, {true},  {0.5},   {4};...
                {'BP 3'}, {true},    {9},  {25};...
                {'BP 4'}, {true},   {40}, {100};...
                {'BP 5'}, {true},  {300}, {Inf}];
            app.NumberofBPsEditField.Value = size(app.BpTable.Data, 1); % This is the number of bands
            app.BP_color_default = [  0,      0,      0;... %black   for BP1
                0,      1,      1;... %cyan    for BP2
                1,      0,      0;... %red     for BP3
                0,      1,      0;... %green   for BP4
                0,      0,      1;... %blue    for BP5
                1,      0,      1;... %magenta for BP6
                1,      1,      0;... %yellow  for BP7
                0.5,    0.5,    0.5];   %grey    for BP8
            app.BP_color = app.BP_color_default(1:app.NumberofBPsEditField.Value,:);
            app.BP_4_plot = ones(app.NumberofBPsEditField.Value,1);
            BPsynch(app,0) % store the info of BpTable in app.frequencyBand and app.frequencyBand_ck
            
            app.notchOrder = 3; % order 6 for the notch filter % MIGHT BE UNUSED
            app.xnow = -999;
            
            app.fileInNum = 0; % keep track of the number of analyzed files for the output table
            app.eventNum = 0; % keep track of the number of analyzed events
            app.cnt = 0; % counter for full trace summary table
            app.EPcnt = 0; % counter for the summary table of evoched responses
            
            % the following structure is required for passing the proper parameters to
            % the Chronux routines.
            app.params = struct('tapers',[1 2],'Fs',0.001,'fpass',[0 10],'pad',0,'err',[]);
            
            % app.dtaSummary = struct('f1','file name','f2',1,'f3',2,'f4',3,'f5',4,'f6','Comments');
            
            % Definition of a cell array to store the output table
            % The fields are consistent with te GUIDE definition of outable provided in
            % the ZebraSays figure.
            % dtaSummary holds the complete file name time of file last modification
            % (it corresponds to the original creation time) and the RMS powers in the 5 bands
            
            app.dtaSummary = {'dir' 'file' 'time' 1 2 3 4 5 6 7 'notes'};
            
            app.eventSummary = {'dir' 'file' 1 2 3 4 5 6 7 8 9 10 11 12 13 'news'};
            % Cell array for the detected events
            % Field description:
            % 1: event number in the given file
            % 2: time at the centre of the event
            % 3: duration of the event
            % 4-6: RMS power of the baseline in the three bands
            % 7-9: RMS power of the event
            % 10-12: power normalised to the baseline
            %
            % 14: news and views (editable text)
            
            app.respSummary = {'file' 1 2 3 4 5 6 7 8 9 10 'news'};
            % Cell array for trial responses: field description
            % 1: file name
            % 2: channel number
            % 3: trial number
            % 4: repeat number
            % 5: Peak response
            % 6: time to peak
            % 7: Mean BP1 baseline power (Db)
            % 8: Mean BP1 response power (Db)
            % 9: normalized power (8-7)
            % 10: BP1 power multiplied by the scaled template
            % 11: comments
            
            app.dtaPWsummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            app.dtaPWlocalSummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            % Cell array for power distribution: field description
            % 1: file name
            % 2: channel number
            % 3: time of data file creation
            % 4: channel
            % 5: trial
            % 6: band pass index (1-4 corresponding to BP1-3 and HP
            % 7: Shannon entropy
            % 8: main mode: mean log of power
            % 9: main mode: std
            % 10: main mode: relative area
            % 11: secondary mode: mean of log of power
            % 12: secondary mode: std
            % 13: secondary mode: relative area
            
            % Initialize timeOffset variable
            app.timeOffset = app.trTimeOffset_txt.Value;
            app.timeOffset_i = round(app.timeOffset/app.sp);
            
            % All sliders must be invisible in the beginning
            set([app.chText_1,...
                app.chText_2,...
                app.chSlider_1,...
                app.chSlider_2,...
                app.trialText_1,...
                app.trialText_2,...
                app.trialSlider_1,...
                app.trialSlider_2],...
                'Visible','off')
            
            % set axes so that they are aligned
            
            app.wide_plot_up.PositionConstraint = 'innerposition';
            innerpos = app.wide_plot_up.InnerPosition;
            app.wide_plot_BP.PositionConstraint = 'innerposition';
            app.wide_plot_BP.InnerPosition(1,3) = innerpos(1,3);
            app.spectraWide.PositionConstraint = 'innerposition';
            app.spectraWide.InnerPosition(1,3) = innerpos(1,3);
            
            %GAB
            app.autoMode = 0;
            app.storedLFP = [];
            app.spg_computed = false;
            
            %%Initialize ZebraAuto variables
            app.auto.AutoDlgIn = [];
            
            %%Initialize the structure for multi ch plot scales
            app.multiChPlot = struct('scales',[],'show',[]);
        end

        % Value changed function: SPGlogCk
        function SPGlogCk_Callback(app, event)
            plotSpectrogram(app)
        end

        % Callback function
        function ROI4template_bt_Callback(app, event)
            if isprop(app,'hRoi')
                delete(app.hRoi);
            end
            app.hRoi = drawrectangle(app.wide_plot_up);
            templUpdateFunction(app);
        end

        % Selection changed function: SPGmode
        function SPGmode_SelectionChangeFcn(app, event)
            plotSpectrogram(app)
        end

        % Callback function
        function SweepTimeEdit_Callback(app, event)
            %GABRIELE 2018/01/27: useful for setting trial time in MeyerVEP mode
            app.sp = get(app.SweepTimeEdit.Value)/2048;
            set(app.spEdit,'Value',app.sp);
            app.acqF = 1/app.sp;
        end

        % Value changed function: TrRej_ck
        function TrRej_ck_Callback(app, event)
            if get(hObject,'Value')
                app.trialRej=trialRejection;
                app.trialRejData=zebraguidata(app.trialRej);
            else
                close(app.trialRej)
                app.trialRejData=[];
            end
        end

        % Value changed function: analyzePWdistributionCk
        function analyzePWdistributionCk_Callback(app, event)
            
            if app.analyzePWdistributionCk.Value
                % open the child process
                app.analyzePW = analyzePWdistribution(app);      % handle to the child figure
            else
                % close the child process
                close(app.analyzePW);
                app.analyzePW = [];
            end
        end

        % Value changed function: analyzeWhileConc_ck
        function analyzeWhileConc_ck_Callback(app, event)
            if app.analyzeWhileConc_ck.Value
                app.concat_ck.Value = 1;
            end
        end

        % Value changed function: autoOffCk
        function autoOffCk_Callback(app, event)
            if (app.fileInNum>0), plotWideWindow(app)
            end
        end

        % Callback function
        function bpCheck1_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Callback function
        function bpCheck2_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Callback function
        function bpCheck3_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: chSlider_1
        function chSlider_1_Callback(app, event)
            app.currentCh = app.chSlider_1.Value;
            % update the text
            set(app.chText_1,'Text',['Ch ' num2str(app.currentCh)])
            % update the other slider too
            set(app.chSlider_2,'Value',app.currentCh)
            set(app.chText_2,'Text',['Ch ' num2str(app.currentCh)])
            
            % Replot
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
            if get(app.powerSpectraCk,'Value')
                startSpectraPlot(app)
            end
        end

        % Value changed function: checkbox_sleepscoring
        function checkbox_sleepscoring_Callback(app, event)
            % Didi October 2018: open the sleep scoring program to score NREM sleep
            % episodes in the australian data, based on delta/theta ratio, and
            % determine power in delta band in these episodes
            
            if app.checkbox_sleepscoring.Value
                % open the program
                app.S_SC = sleepscoring(app);
                app.S_SCData = zebraguidata(app.S_SC);
                
            else
                close(app.S_SC)
            end
        end

        % Button pushed function: clearConc_btn
        function clearConc_btn_Callback(app, event)
            % hObject    handle to clearConc_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.storedLFP = [];
        end

        % Button pushed function: computeBP, computeBP_2
        function computeBP_Callback(app, event)
            %% This function computes and plots the filtered data. It must be evoked after
            % changing any filter settings to update the filtered data.
            % May 7, 2017.
            % I have added the temporal profile of the power in BP1, BP2, BP3 and HP
            
            if (app.fileInNum) % of course the following code is executed only if a data has been loaded
                computeBPandNotch(app)
                computePowerInTime(app)  % temporal profile of power
                plotMultiBand(app)
                if app.leakageLPchanged
                    computeSpectrogram(app)
                    plotSpectrogram(app)
                end
                
                disp('Computation of band passed data completed.')
                
                % Update the status "led" indicator to GREEN because now band passed
                % LFP and BPpower are computed according to the settings
                % that are visible in the user interface
                app.BPsynch_led1.Color = 'g';
                app.BPsynch_led2.Color = 'g';
            end
        end

        % Button pushed function: concat_btn
        function concat_btn_Callback(app, event)
            % hObject    handle to concat_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            concatenateTraces(app)
        end

        % Value changed function: concat_ck
        function concat_ck_Callback(app, event)
            % hObject    handle to concat_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if app.concat_ck.Value
                app.concat_btn.Enable = 'on';
                app.concat_group.Enable = 'on';
            else
                app.concat_btn.Enable = 'off';
                app.concat_group.Enable = 'off';
            end
        end

        % Value changed function: dataFormat
        function dataFormatPanel_SelectionChangeFcn(app, event)
            %              2019/05/25, Gab: Converted from a RadioButtonGroup to a DropDown
            %             leak = get(app.leakageLP,'Value');
            
            newFmt = app.dataFormat.Value; % selected format
            if strcmpi(newFmt,'EEG format')
                setSP(app, 512);
                setCh(app, 2);
                setGain(app, 5000);
                set (app.EEGck,'Value',1);
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB);
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB);
                
                % set the correct spectral bands
                % app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
                %                 app.BpTable.Data = [1   1   0   4;
                %                                     2   1   0.5 4;
                %                                     3   1   12  16;
                %                                     4   1   30  80;
                %                                     5   1   100 inf];
            end
            
            if strcmpi(newFmt,'Meyer VEP')
                % set all required parameters
                %setSP (hObject, handles, 4096);
                %                 setSP(app, 2048/get(app.SweepTimeEdit,'Value')); %gab
                %                 setCh(app, 3)
                %                 setGain(app, 1)
                set(app.microVck,'Value',1)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -30; %gab
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -10; %gab
                set(app.upperDB,'Value',app.SPGhighDB)
                
                % set the correct spectral bands
                %                 app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
                %                 app.BpTable.Data = [1   1   0   4;
                %                                     2   1   0.5 4;
                %                                     3   1   12  16;
                %                                     4   1   30  80;
                %                                     5   1   100 inf];
                %
                %GABRIELE 2018/01/27
                set(app.spEdit,'Enable','off')
                set(app.SweepTimeEdit,'Enable','on')
                app.SweepTimeEdit.Value = 1;
            else
                %                 if strcmpi(event.OldValue.Text,'Meyer VEP') %meyerVEP has been deselected
                set(app.spEdit,'Enable','on')
                set(app.SweepTimeEdit,'Enable','off')
                %                 end
            end
            
            if strcmpi(newFmt,'SEP')
                % set all required parameters
                setSP(app, 32768)
                setCh(app, 2)
                set(app.microVck,'Value',1)
                setGain(app, 1000)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB)
                
                % this mode does not perform spectral analysis
            end
            % now update app.frequancyBand vector
            %             BPsynch(app)
            
            if strcmpi(newFmt,'Neuronexus')
                dircontent = dir([app.zebraPath 'NNprobes']);
                dircontent(strcmp({dircontent.name}, '.') | strcmp({dircontent.name}, '..')) = []; % elimination of ghost folders
                isfolder = [dircontent(:).isdir]';
                folder = {dircontent(isfolder).name};
                app.SelectprobeDropDown.Items = [{'All channels'}, folder]; % set options of drop-down tab for probe selection
                
                app.SelectprobeDropDown.Enable = 'on';
                SelectprobeDropDownValueChanged(app,[])
                app.ShowprobeButton.Enable = 'on';
                app.AI1_gainEditField.Enable = 'on';
                app.AI2_gainEditField.Enable = 'on';
                app.gain.Enable = 'off';
                app.gain.Value = 1;
                app.gain_Callback([])
            else
                app.SelectprobeDropDown.Enable = 'off';
                app.AI1_gainEditField.Enable = 'off';
                app.AI2_gainEditField.Enable = 'off';
                app.ShowprobeButton.Enable = 'off';
                app.gain.Enable = 'on';
            end
        end

        % Value changed function: gain
        function gain_Callback(app, event)
            app.gainAmp = app.gain.Value;
        end

        % Value changed function: hit_ck
        function hit_ck_Callback(app, event)
%             % Hint: get(hObject,'Value') returns toggle state of hit_ck
%             if get(hObject,'Value')
%                 app.responsometer = Responsometer(app);
%                 %app.responsometerData = zebraguidata(app.responsometer);
%             else
%                 % close the child process
%                 close(app.responsometer);
%                 %app.responsometerData = [];
%                 app.responsometer = [];
%             end
        end

        % Button pushed function: loadParams_btn
        function loadParams_btn_Callback(app, event)
            paramsStruct = [];
            uiopen('*.mat');
            figure(app.ZebraMainFig)
            if isempty(paramsStruct)
                return
            end
            app.paramsStruct = paramsStruct;
            for i = 1 : height(paramsStruct.mainParamsTable)
                %                 i
                %                 if i==24
                %                     disp('eccoci')
                %                 end
                try
                    app.(paramsStruct.mainParamsTable.Name(i)).Value = paramsStruct.mainParamsTable.Value(i);
                    if ~isempty(app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn)
                        try
                            app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.mainParamsTable.Name(i)),[])
                        catch ME
                            %                             warning('off','backtrace')
                            %                             warning(strcat("Error while executing ", paramsStruct.mainParamsTable.Name(i), "'s Callback"))
                            %                             warning(ME.message)
                            %                             disp(newline)
                        end
                    end
                catch Err
                    warning('off','backtrace')
                    warning(strcat("Error setting ", paramsStruct.mainParamsTable.Name(i), "'s Value"))
                    warning(Err.message)
                    disp(newline)
                end
            end
            
            % load bandpasses
            app.BpTable.Data = app.paramsStruct.BpTableData;
            
            
            if isfield(paramsStruct, 'spectreParamsTable')
                disp(['spectre debugging' newline])
                app.spectreApp.loadParamsSpectre()
                disp([newline newline newline])
            end
            
            app.BpTable.Data = app.paramsStruct.BpTableData;
            BPsynch(app,inf) %prova
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end

        % Value changed function: lowerDB
        function lowerDB_Callback(app, event)
            app.SPGlowDB = app.lowerDB.Value;
            if (app.fileInNum>0), plotSpectrogram(app)
            end
        end

        % Value changed function: multiChAnalyzer
        function multiChAnalyzer_Callback(app, event)
            
            if app.multiChAnalyzer.Value
                % open the child process
                app.xCorr = multiChannelAnalyzer_App(app);  % handle to the multi channel analyzer
                %                 app.xCorrData = zebraguidata(app.xCorr);
            else
                if isvalid(app.xCorr)
                    app.xCorr.delete
                end
            end
        end

        % Value changed function: notchFilter
        function notchFilter_Callback(app, event)
            computeBP_Callback(app, event)
            if app.powerSpectraCk.Value
                computeSpectra_function(app.spectreApp)
            end
        end

        % Value changed function: notchFrom
        function notchFrom_Callback(app, event)
            app.notch(1) = app.notchFrom.Value;
        end

        % Value changed function: notchTo
        function notchTo_Callback(app, event)
            app.notch(2) = app.notchTo.Value;
        end

        % Button pushed function: openDir
        function openDir_Callback(app, event)
            flagN = get(app.normalDataRB,'Value');
            if (flagN), fmtSt = '*.dat';
            end
            dirArray = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            selection = uigetdir('C:\Users\Gix\Documents\Work\Science\')
            if selection ~= 0
                fileName = '\ltp001.dat';
                dirArray = dir([selection '\Dir_*']);
                ndir = length(dirArray);
                for i=1:ndir
                    path_in = [selection, app.PathSeparator, dirArray(i).name];
                    fileInfo = dir([path_in fileName]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,fileName,path_in)
                end
            end
        end

        % Value changed function: plotEnvelopeCk
        function plotEnvelopeCk_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
            end
        end

        % Value changed function: plotMeanFlag
        function plotMeanFlag_Callback(app, event)
            if app.fileInNum>0
                if app.plotMeanFlag.Value
                    computeMeanOnTrials(app) %Gab 2019/05/26
                end
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: plotMultiCh
        function plotMultiCh_Callback(app, event)
            if app.plotMultiCh.Value
                app.ChscalefactorsButton.Enable = 'on';
            else
                app.ChscalefactorsButton.Enable = 'off';
            end
            if (app.fileInNum>0)
                plotWideWindow(app)
                %                 plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: plotTrCk
        function plotTrCk_Callback(app, event)
            if app.fileInNum>0
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: powerSpectraCk
        function powerSpectraCk_Callback(app, event)
            if app.powerSpectraCk.Value
                % open the child process
                app.spectreApp = ZebraSpectreApp(app);  % handle to the power spectra window
            else
                app.spectreApp.delete
                app.spectreApp = [];
            end
        end

        % Selection changed function: predefinedBW
        function predefinedBW_SelectionChangeFcn(app, event)
            % select default values for bandpass filters
            source = event.NewValue.Text;
            
            if strcmp(source, 'Zebrafish')
                app.NumberofBPsEditField.Value = 5;
                app.BpTable.Data = [num2cell((1:5)'), num2cell(true(5,1)), num2cell([0 8; 0.4 4; 4 8; 8 12; 100 inf])];
            end
            
            if strcmp(source, 'EEG VEP')
                app.NumberofBPsEditField.Value = 5;
                app.BpTable.Data = [num2cell((1:5)'), num2cell(true(5,1)), num2cell([0 4; 0.5 4; 6 12; 12 30; 100 inf])];
            end
            
            if strcmp(source, 'Mouse')
                app.NumberofBPsEditField.Value = 5;
                app.BpTable.Data = [num2cell((1:5)'), num2cell(true(5,1)), num2cell([0 8; 0.5 4;6 12; 30 80; 100 inf])];
            end
            
            if strcmp(source, 'Newcastle')
                app.NumberofBPsEditField.Value = 5;
                app.BpTable.Data = [num2cell((1:5)'), num2cell(true(5,1)), num2cell([0 8; 0.5 4; 20 60; 70 300; 300 inf])];
            end
            % Synchronize all the stuff related to bandpasses
            BPsynch(app,inf)
        end

        % Button pushed function: recomputeSPG
        function recomputeSPG_Callback(app, event)
            computeSpectrogram(app)
            if (app.nTrials>1), computeMeanOnTrials (app);
            end
            % warning: if trial >1 the means have not been recomputed!
            plotSpectrogram(app)
        end

        % Button pushed function: saveBP
        function saveBP_Callback(app, event)
%             % Save as an ascii file the current view of the wide window
%             % If multi plot is on, all channels are saved as separate columns,
%             % otherwise it is saved only the current channel.
%             % This can be used to save only a small portion of the record depending on
%             % the X limits of the visualisation.
%             
%             % Data are saved with the following order (GAB):
%             % 1) Single trial, multi channels
%             % col 1. time
%             % col from 2 to nBP+1. LFP filtered according to the nBP frequency bands
%             % col from nBP+2 to 2nBP+1 the mean filtered LFP for the nBP frequency
%             % bands, if "plot Mean" is checked          
            
            
            selBP = app.saveBP_EditField.Value;
            
            assert(selBP<=app.NumberofBPsEditField.Value,['BP' num2str(selBP) ' does not exist'])
            
            if app.BPcomputed(selBP)
                
                % selected channel(s)
                if app.save_allchennelsCheckBox.Value
                    selCh = 1:app.nCh;
                else
                    selCh = app.chSlider_1.Value;
                end
                
                % select current view
                selT = app.x>=app.tminTxt.Value & app.x<=app.tmaxTxt.Value;
                t = app.x(selT)';
                
                % reshape the traces to obtain columns in this order:
                % ch1,               ch2,               ... chM
                % tr1, tr2, ... trN, tr1, tr2, ... trN, ... tr1, tr2, ... trN
                tmp(1:length(selCh),1:app.nTrials,:) = app.bandPassed_LFP(selBP,selCh,:,selT);
                [nCh,nTr,nT] = size(tmp);
                tmpReshaped = reshape( permute(tmp,[3,2,1]), nT,[]);
                 
                % add time in the first column
                tmpOut = cat(2,t,tmpReshaped);
                
                if app.save_downsampleCheckBox.Value
                    % downsample
                    tmpOut = tmpOut(1:app.downsample_factorEditField.Value:end,:);
                end

                % save it
                writematrix(tmpOut,[app.dtaComplete(1:end-4) '_BP' num2str(selBP,'%02.f') '_filtered.dat'],"Delimiter",'tab')
                
                disp('Band passed trace saved')
            else
                disp(['Compute BP' num2str(selBP) ' first!'])
            end
        end

        % Button pushed function: saveCurrentCh
        function saveCurrentCh_Callback(app, event)
            % Save as an ascii file the current view of the wide window
            % If multi plot is on, all channels are saved as separate columns,
            % otherwise it is saved only the current channel.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            fileOut = [app.dtaComplete(1:end-4) '_currentTrace.dat'];
            
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            tmpOut = [];
            
            % selected channel(s)
            if app.save_allchennelsCheckBox.Value
                selCh = 1:app.nCh;
            else
                selCh = app.chSlider_1.Value;
            end
            
            % select current view
            selT = app.x>=app.tminTxt.Value & app.x<=app.tmaxTxt.Value;
            t = app.x(selT)';
            
            if flagMd
                % save all channels of the mean trace. Reserve one column for the
                % ordinates.
                tmpOut (selCh,1:(ito-ifrom+1)) = app.meanLFP (selCh,selT);
            else
                % save the spectra of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.workLFP (selCh,app.currentTrial,selT);
            end
            tmpOut(1,:) = t;
            
            if app.save_downsampleCheckBox.Value
                % downsample
                tmpOut = tmpOut(1:app.downsample_factorEditField.Value:end,:);
            end
            
            writematrix(tmpOut,fileOut,"Delimiter",'tab')
        end

        % Button pushed function: saveParams_btn
        function saveParams_btn_Callback(app, event)
            app.paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox')
                        propListLogic(i) = true;
                    end
                end
            end
            
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'double'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value(i) = app.(paramsTable.Name(i)).Value;
            end
            
            app.paramsStruct.mainParamsTable = paramsTable;
            app.paramsStruct.BpTableData = app.BpTable.Data;
            
            if app.powerSpectraCk.Value
                % if zebraspectre is open, ZebraSpectreApp saves its parameter in the table spectreParamsTable and adds it to the structure app.paramsStruct
                saveParamsSpectre(app.spectreApp)
            end
            
            paramsStruct = app.paramsStruct;
            
            [file,path] = uiputfile('*.mat','Save parameters file','Parameters.mat');
            figure(app.ZebraMainFig)
            
            save([path file],'paramsStruct')
            disp('Parameters saved')
        end

        % Button pushed function: saveSPG
        function saveSPG_Callback(app, event)
            % Save as an ascii file the current view of the SPG, envelope, RMS power and E/I index
            % If plotMean is on it saves the computed means otherwise it saves the current trace.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            %flagMulti = get(app.plotMultiCh,'Value'); yet unusued
            
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            timeFrom = lim(1);
            timeTo = lim(2);
            
            % process the RMS powers for all the calculated BPs.
            % since each file may have different time axis they are saved as separate
            % files
            for selBP = 1:app.NumberofBPsEditField.Value
                if app.BPcomputed(selBP)
                    outBPpowers (app, selBP, timeFrom, timeTo, ['BP ' num2str(selBP,'%02u') '.dat']);
                end
            end
            % process the E/I ratio first, the envelope second
            SPGmin = app.spgt(1);
            SPGmax = app.spgt(app.spgl);
            resolutionSPG = (SPGmax-SPGmin)/(app.spgl-1);
            SPGfrom = int32(timeFrom/resolutionSPG)+1 - app.timeOffset_i; %gab 2019/02/24: offset added
            SPGto =  int32(timeTo/resolutionSPG)+1 - app.timeOffset_i; %gab 2019/02/24: offset added
            
            if (SPGto>app.spgl), SPGto = app.spgl;
            end
            tmpOut = [];
            
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanEIrat (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.EIrat (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            c(1,:) = app.spgt(:);
            % create the format descriptor
            fmtSt='';
            for ii=1:app.nCh      % add 1 for the x axis.
                fmtSt= [fmtSt '%10.8f '];
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in 'EIratio.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
            
            % process the envelope
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanSpgPlot (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.spgPlot (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            
            fileOut = [app.dir_in 'SPGplot.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Callback function
        function screenCaptureBtn_Callback(app, event)
            flag = get(hObject,'Value');
            if flag, scButtonOn(app);
            else
                scButtonOff(app)
            end
        end

        % Button pushed function: scrollLeft
        function scrollLeft_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmin - delta;
%             if app.tmin<0, app.tmin=0;
%             end
            app.tmax = app.tmin + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
%             plotWideWindow(app)
%             plotMultiBand(app)
%             plotSpectrogram(app)
            set([app.wide_plot_up, app.wide_plot_BP, app.spectraWide],'XLim',[app.tmin, app.tmax])
        end

        % Button pushed function: scrollRight
        function scrollRight_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmax;
            app.tmax = app.tmax + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
            set([app.wide_plot_up, app.wide_plot_BP, app.spectraWide],'XLim',[app.tmin, app.tmax])
%             plotWideWindow(app)
%             plotMultiBand(app)
%             plotSpectrogram(app)
        end

        % Button pushed function: select_data
        function select_data_Callback(app, event)
            % Open new data file according to the selected data type
            
            % Check if any accessory window is selected and, if that is the case,
            % closes it.
            
            
            sel_format = get(app.dataFormat,'Value');
            switch sel_format
                case 'National Instr'
                    fmtSt = '*.dat';
                case 'EEG Laura'
                    fmtSt = '*.eeg';
                case 'Meyer VEP'
                    fmtSt = '*.asc';
                case 'SEP'
                    fmtSt = '*.asc';
                case 'AxoP'
                    fmtSt = '*.abf';
                case 'TRC import'
                    fmtSt = '*.trc';
                case 'IMM MEA'
                    fmtSt = '*.mat';
                case 'EDF format'
                    fmtSt = '*.edf';
                case 'NewCastle'
                    fmtSt = '*.mat';
                case 'Neuronexus'
                    fmtSt = '.json';
                case 'Matlab'
                    fmtSt = '.mat';
            end
            
            
%             if app.autoMode    %:::GABRIELE::: 08/12/2017, for AutoOpening
%                 selection = app.auto.selection;
%                 path_in=[app.auto.path_in, app.PathSeparator];
%                 app.fileTime=[]; % needed by OpenFile
%                 openFile(app,selection,path_in)
%             else
                [selection,path_in,filterI] = uigetfile ([app.dir_in fmtSt]);
                
                if isempty(selection) %return button pressed
                    return
                    figure(app.ZebraMainFig)
                end
                
                if app.autofocus.Value %2019/05/26 Gab
                    % Keep the focus on ZebraExplore when opening a new file
                    figure(app.ZebraMainFig)
                end
                
                fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
                if selection ~= 0
                    fileInfo = dir([path_in, selection]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,selection,path_in)
                end
%             end
        end

        % Button pushed function: sendToClipboard
        function sendToClipboard_Callback(app, event)
            h = ZebraExplore;
            hgexport(h,'-clipboard');
        end

        % Value changed function: singleUnitExtraction_ck
        function singleUnitExtraction_ck_Callback(app, event)
            % hObject    handle to singleUnitExtraction_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Didi October 2018: open single units program to extract single units from
            % LFP
            if app.singleUnitExtraction_ck.Value
                % open the single unit program
                app.SUapp = SingleUnits_App(app);
            else
                app.SUapp.delete
                app.SUapp = [];
            end
        end

        % Value changed function: sliderBPpower
        function sliderBPpower_Callback(app, event)
            app.BP4power = app.sliderBPpower.Value;
            app.BPtext.Text = ['Select BP' num2str(app.BP4power)];
        end

        % Value changed function: spEdit
        function spEdit_Callback(app, event)
            app.sp = app.spEdit.Value;
            app.acqF = 1/app.sp;
            app.sf.Value = app.acqF/1000;
            app.timeOffset_i = round(app.timeOffset/app.sp);
        end

        % Button pushed function: tResetBtn
        function tResetBtn_Callback(app, event)
            app.tmin = app.x(1); %GAB 2019/02/24: added a time offset, so tmin is no more 0.
            set(app.tminTxt,'Value',app.tmin)
            app.tmax = app.x(end); %GAB 2019/02/24: added a time offset, so tmax is no more dtaLen*sp.
            set(app.tmaxTxt,'Value',app.tmax)
%             plotWideWindow(app)
%             plotMultiBand(app)
%             plotSpectrogram(app)
            set([app.wide_plot_up, app.wide_plot_BP, app.spectraWide],'XLim',[app.tmin, app.tmax])
        end

        % Button pushed function: templExp_bt
        function templExp_bt_Callback(app, event)
            % hObject    handle to templExp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            templ = app.templ;
            uisave('templ','template.mat')
        end

        % Button pushed function: templImp_bt
        function templImp_bt_Callback(app, event)
            % hObject    handle to templImp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            templTemp = uigetfile;
            app.templ = templTemp;
        end

        % Value changed function: tmaxTxt
        function tmaxTxt_Callback(app, event)
            app.tmax = app.tmaxTxt.Value;
            if (app.tmin < app.tmax)
%                 plotWideWindow(app)
%                 plotMultiBand(app)
%                 plotSpectrogram(app)
                set([app.wide_plot_up, app.wide_plot_BP, app.spectraWide],'XLim',[app.tmin, app.tmax])
            end
        end

        % Value changed function: tminTxt
        function tminTxt_Callback(app, event)
            app.tmin = app.tminTxt.Value;
            if (app.tmin < app.tmax)
%                 plotWideWindow(app)
%                 plotMultiBand(app)
%                 plotSpectrogram(app)
                set([app.wide_plot_up, app.wide_plot_BP, app.spectraWide],'XLim',[app.tmin, app.tmax])
            end
        end

        % Value changed function: trialSlider_1
        function trialSlider_1_Callback(app, event)
            if (app.fileInNum >= 1 && app.nTrials > 1)
                app.currentTrial = app.trialSlider_1.Value;
                % Update the text
                set(app.trialText_1,'Text',['Trial ' num2str(app.currentTrial)]);
                % Update the other slider too
                set(app.trialSlider_2,'Value',app.currentTrial);
                set(app.trialText_2,'Text',['Trial ' num2str(app.currentTrial)]);
                
                % Replot
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: upperDB
        function upperDB_Callback(app, event)
            app.SPGhighDB = app.upperDB.Value;
            if (app.fileInNum>0), plotSpectrogram (app)
            end
        end

        % Value changed function: ymaxTxt_lfp
        function ymaxTxt_lfp_Callback(app, event)
            %        str2double(get(hObject,'Value')) returns contents of ymaxTxt as a double
            %app.ymax = (get(hObject,'Value'));
            %             event = [];
            %             event.yLim = [app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value];
            %             plotWideWindow(app,event)
            %             plotMultiBand(app)
            %             plotSpectrogram(app)
%             plotWideWindow(app)
            axis(app.wide_plot_up, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value]);
            
        end

        % Button pushed function: autoMode_bt
        function autoMode_btButtonPushed(app, event)
            if ~app.autoMode
                app.autoMode = 1;
                app.select_data.Enable = 'off';
                app.AutoGo_bt.Enable = 'on';
                app.autoMode_bt.Text = 'Auto Mode ON';
                app.autoMode_bt.BackgroundColor = 'g';
            else
                app.autoMode = 0;
                app.select_data.Enable = 'on';
                app.AutoGo_bt.Enable = 'off';
                app.autoMode_bt.Text = 'Auto Mode OFF';
                app.autoMode_bt.BackgroundColor = 'r';
            end
        end

        % Button pushed function: AutoSetContrasts_bt
        function AutoSetContrasts_btButtonPushed(app, event)
            nCond = app.AutoNConds_txt.Value;
            
            conds = 1:nCond;
            
            % Create a cell array containing all the string to be put aside each text box
            prompt = arrayfun(@(x) ['specify folders for contrast level' num2str(x)], conds, 'un', 0);
            
            if ~isempty(app.auto.AutoDlgIn) && (size(app.auto.AutoDlgIn,1)==nCond)
                % If app.AutoDlgIn is not empty, AutoDlg is used as preset values of the ZebraAuto folders
                preset = app.auto.AutoDlgIn;
            else
                % If there is nothing in app.AutoDlgIn, it is initialized as an empty cell array
                preset = cell(nCond, 1);
                preset(:) = {''};
            end
            
            title = 'specify folders for analysis (space separated)';
            nlines = 1;
            app.auto.AutoDlgIn = inputdlg(prompt, title, nlines, preset, 'on');

            
            if isempty(app.auto.AutoDlgIn)
                return
            end
            
            for i = 1:nCond
                %                 AutoFolderTmp = strsplit(app.AutoDlgIn{i}, ' ');
                %                 app.AutoFolders{i} = cellfun(@str2double, AutoFolderTmp);
                app.auto.AutoFolders{i} = strsplit(app.auto.AutoDlgIn{i}, ' ');
            end
        end

        % Button pushed function: AutoGo_bt
        function AutoGo_btButtonPushed(app, event)
            % The main path_in is taken from the path that is saved in the ZebraAuto UI
            % And the same is done for the file name
            folder_in = app.AutoMainFolder_edit.Value;
            app.auto.selection = app.AutoFileNameIn_edit.Value;
            
            nCond = app.AutoNConds_txt.Value;
            nFolds = cellfun(@length,app.auto.AutoFolders); %it is useful to know how many folders(=traces) are there for every condition
            if strcmp(app.dataFormat.Value,'Neuronexus')
                app.auto.path_in = folder_in;
                [filestruct] = dir(folder_in);
                filestruct([filestruct(:).isdir]) = [];
                
                % allego files -> only select .xdat files
                allegofiles_logic = arrayfun(@(x)(strcmp(x.name(end-8:end),'data.xdat')),filestruct);
                allegofiles = filestruct(allegofiles_logic);
                clear allogofiles_logic filestruct
                
                % order files according to their creation time
                dat = "";
                for i = 1:numel(allegofiles)
                    dat(i,:) = allegofiles(i).date;
                end
                [~,ord] = sort(dat);
                allegofiles = {allegofiles(ord).name};
%                 file_ind = cellfun(@str2double,app.auto.AutoFolders,'uniformoutput',false);
            end
            
            %initialize the variables.
            
            %compute the number of traces for each condition
            if app.concat_ck.Value
                % if traces from the same condition are concatenated, we end up with a single trace for each condition
                condsLen = ones(1,nCond);
            else
                % otherwise, the number of traces for each condition is stored in nFolds
                condsLen = nFolds;
            end
            if app.AutoTable_ck.Value
                %             outTab is a structure array with n elements,(n = number of conditions)
                %             and one field (Table: a cell array of length m, with
                %             m = number of traces for that condition. Every element of Table
                %             stores the zebraSays table from the corresponding trace).
                app.auto.outTab = struct('Table',[]);
                for i = 1:nCond
                    % outTab(i).Table = cell(1,1,length(outTab(i)));
                    app.auto.outTab(i).Table = cell(1,condsLen(i));
                end
            end
             
            if app.AutoTrace_ck.Value
                %             outTraces is a structure array with n elements,(n = number of conditions)
                %             and three fields: Time, Trace and MeanTrace (fields are cell arrays of length m, with
                %             m = number of traces for that condition). MeanTrace is used
                %             only when nTrials > 1.
                app.auto.outTraces = struct('Time',[],'Trace',[],'MeanTrace',[]);
                for i = 1:nCond
                    app.auto.outTraces(i).Time       = cell(1,condsLen(i));
                    app.auto.outTraces(i).Trace      = cell(1,condsLen(i));
                    app.auto.outTraces(i).MeanTrace  = cell(1,condsLen(i));
                end
            end
            
            if app.AutoSpectra_ck.Value
                %             outSpec is a structure array with n elements,(n = number of conditions)
                %             and six fields: Freq, Pws and MeanPws, Freq2, Pws2, MeanPws2 (fields are cell arrays of length m, with
                %             m = number of traces for that condition). MeanPws is used
                %             only when nTrials > 1. The last three fields are used
                %             only when the second time window for power spectrum
                %             calculation is selected.
                app.auto.outSpec = struct('Freq' ,[],'Pws' ,[],'MeanPws' ,[],...
                    'Freq2',[],'Pws2',[],'MeanPws2',[]);
                for i = 1:nCond
                    app.auto.outSpec(i).Freq     = cell(1,condsLen(i));
                    app.auto.outSpec(i).Pws      = cell(1,condsLen(i));
                    app.auto.outSpec(i).MeanPws  = cell(1,condsLen(i));
                    app.auto.outSpec(i).Freq2    = cell(1,condsLen(i));
                    app.auto.outSpec(i).Pws2     = cell(1,condsLen(i));
                    app.auto.outSpec(i).MeanPws2 = cell(1,condsLen(i));
                end
            end
            
            if app.AutoSpg_ck.Value
                %             outSpg is a structure array with n elements,(n = number of conditions)
                %             and four fields: SpgMat, SpgT and SpgW, MeanSpgMat (fields are cell arrays of length m, with
                %             m = number of traces for that condition). MeanSpgMat is used
                %             only when nTrials > 1.
                app.auto.outSpg = struct('SpgMat',[],'SpgT',[],'SpgW',[],'MeanSpgMat',[]);
                for i = 1:nCond
                    app.auto.outSpg(i).SpgMat        = cell(1,condsLen(i));
                    app.auto.outSpg(i).SpgT          = cell(1,condsLen(i));
                    app.auto.outSpg(i).SpgW          = cell(1,condsLen(i));
                    app.auto.outSpg(i).MeanSpgMat    = cell(1,condsLen(i));
                end
            end
            
            % before startung, clear the stored LFP!
            if app.concat_ck.Value && app.auto_clearStoredLfp.Value
                clearConc_btn_Callback(app)
            end
            
            % Start running ZebraExplore automatically
            for i_Cond=1:nCond
                currFolds = app.auto.AutoFolders{i_Cond}; %currFolds=folders of the current condition
                nTraces = length(currFolds);
                skip = 0; % Useful for "concatenate" condition. If skip = 1, skips the storage of data form a trace
                if nTraces>0
                    for i_Trace = 1:nTraces
                        
                        iCond = i_Cond;
                        disp(['Condition ' num2str(iCond)])
                        iTrace = i_Trace;
                        disp(['Trace ' num2str(iTrace)])
                        
                        switch app.dataFormat.Value
                            case 'National Instr'
                                if str2double(currFolds{iTrace})>9 %we need this because folder names as Dir_03 cannot be returned, i.g.: num2string(03)=3
                                    app.auto.path_in = [folder_in '\Dir_' currFolds{iTrace}];
                                else
                                    app.auto.path_in = [folder_in '\Dir_0' currFolds{iTrace}];
                                end
                                
%                                 select_data_Callback(app,[])
                                openFile(app,app.auto.selection,app.auto.path_in)
                            case 'Neuronexus'
                                currfile = regexp(allegofiles,[app.auto.selection currFolds{iTrace}]);
                                currfile_logic = ~cellfun(@isempty,currfile);
                                if sum(currfile_logic)==0
                                    error(['file ' app.auto.selection currFolds{iTrace} 'not found'])
                                end
                                openFile(app,allegofiles{currfile_logic},[folder_in filesep])
                        end
                        %
                        %                         if app.AutoSelCh_txt.Value ~= 1
                        %                             app.chSlider_1.Value = app.AutoSelCh_txt.Value;
                        %                             chSlider_1_Callback(app,[])
                        %                         end
                        
                        %2018/11/04: if "concatenation is selected", just open all the
                        %"nTraces" files and extract data only at the end of a "condition".
                        if app.concat_ck.Value
                            if iTrace < nTraces
                                skip = 1;
                            else
                                skip = 0;
                                iTrace = 1; %so that the result is stored as it was the "fist trace"
                                %concatenate trials and compute everything in ZebraMain
                                concat_btn_Callback(app, [])
                            end
                        end
                        
                        if ~skip
                            
                            %% process the recording.
                             
                            %%% PLACE CUSTOM FUNCTION HERE %%%
                            t_logic = app.x>=0.05 & app.x<=0.2;
                            [m,m_i] = min(app.meanBP(2,1,t_logic));
                            M = max(app.meanBP(2,1,t_logic));
                            selT = app.x(t_logic);
                            M_t = selT(m_i);
                            a(iCond,iTrace)= M-m;
                            lat(iCond,iTrace) = M_t;
                            if iCond==nCond && iTrace==nTraces
                                save([folder_in filesep 'contrastcurve.mat'],'a','lat')
                            end
                            
                            
                            % channels is a vector containing the number of the channels that have to be considered.
                            if app.selCh_rb.Value %if a specific channel is selected
                                channels = app.selCh_edit.Value;
                                
                                % and select it on the UI
                                app.chSlider_1.Value = channels;
                                chSlider_1_Callback(app, [])
                                
                            elseif app.allCh_rb.Value %all the channels have to be considered
                                channels = 1:app.nCh;
                            else
                                error('Error in the UI elements for channel selection')
                            end
                            
                            %% tables
                            if app.AutoTable_ck.Value
                                %                                 temp = app.outEvochedResponses.Data;
                                %                                 temp2 = {};
                                %                                 for i = 1:length(channels)
                                %                                     temp2 = cat(1,temp2,temp(cell2mat(temp(:,2))==channels(i),:));
                                %                                 end
                                %outTab(iCond).Table{iTrace} = wholeTab(cell2mat(wholeTab(:,4))==1,:);
                                if app.selCh_rb.Value
                                    temp2 = app.outEvochedResponses.Data(cell2mat(app.outEvochedResponses.Data(:,2))==channels,:);
                                else
                                    temp2 = app.outEvochedResponses.Data;
                                end
                                app.auto.outTab(iCond).Table{iTrace} = temp2;
                            end
                            
                            %% spectra
                            if app.AutoSpectra_ck.Value
                                %--set parameters on ZebraSpectre----------------------
                                if app.AutoWinSpectra_ck.Value
                                    app.spectreApp.fromTtxt.Value = app.AutoSpectraFrom_edit.Value;
                                    app.spectreApp.toTtxt.Value = app.AutoSpectraTo_edit.Value;
                                    app.spectreApp.windowCk.Value = 1;
                                    app.spectreApp.subMean_ck.Value = 1;
                                    if app.AutoWinSpectra2_ck.Value
                                        app.spectreApp.fromT2txt.Value = app.AutoSpectraFrom2_edit.Value;
                                        app.spectreApp.toT2txt.Value = app.AutoSpectraTo2_edit.Value;
                                        app.spectreApp.window2Ck.Value = 1;
                                    end
                                end
                                
                                % Make SpectreApp compute the spectra
                                computeSpectra_function(app.spectreApp)
                                
                                app.auto.outSpec(iCond).Freq{iTrace} = app.spectreApp.pwsf;
                                app.auto.outSpec(iCond).Pws{iTrace}  = app.spectreApp.powerS(channels,:,:);
                                if app.nTrials > 1
                                    app.auto.outSpec(iCond).MeanPws{iTrace} = app.spectreApp.meanPWS(channels,:);
                                end
                                
                                if app.AutoWinSpectra2_ck.Value
                                    app.auto.outSpec(iCond).Freq2{iTrace} = app.spectreApp.pwsf2;
                                    app.auto.outSpec(iCond).Pws2{iTrace}  = app.spectreApp.powerS2(channels,:,:);
                                    if app.nTrials > 1
                                        app.auto.outSpec(iCond).MeanPws2{iTrace} = app.spectreApp.meanPWS2(channels,:);
                                    end
                                end
                                
                            end
                            
                            %% spg
                            if app.AutoSpg_ck.Value
                                
                                app.auto.outSpg(iCond).SpgT{iTrace}   = app.spgt;
                                app.auto.outSpg(iCond).SpgW{iTrace}  = app.spgw;
                                
                                app.auto.outSpg(iCond).SpgMat{iTrace} = app.spg(channels,:,:,:);
                                if app.nTrials > 1
                                    app.auto.outSpg(iCond).MeanSpgMat{iTrace} = app.meanSpg(channels,:,:);
                                end
                               
                                
                                %check that time and freq are the same among traces
                                if iTrace > 1
                                    %Compare the current time and frequency vectors with the ones from the 1st trial
                                    checkT = isequal(app.auto.outSpg(iCond).SpgT{iTrace},app.auto.outSpg(iCond).SpgT{1});
                                    checkW = isequal(app.auto.outSpg(iCond).SpgW{iTrace},app.auto.outSpg(iCond).SpgW{1});
                                    if checkT * checkW == 0
                                        error(['SPG: time or frequency mismatch @ condition '...
                                            num2str(iCond) ', trace ' num2str(iTrace)])
                                    end
                                end
                            end
                            
                            %% traces
                            if app.AutoTrace_ck.Value
                                app.auto.outTraces(iCond).Trace{iTrace} = app.LFP(channels,:,:);
                                app.auto.outTraces(iCond).Time{iTrace} = app.x;
                                if app.nTrials > 1
                                    app.auto.outTraces(iCond).MeanTrace{iTrace} = app.meanLFP(channels,:);
                                end
                            end
                        end
                    end
                    
                    %% this is done at the end of each "condition"
                    if app.concat_ck.Value
                        clearConc_btn_Callback(app)
                    end
                else
                    disp(['no selected folders for dataset ' num2str(iCond)])
                end
            end
            
            %% Save
            autoSave(app)
            
            if app.sound_ck.Value
                myTone = load('gong');
                sound(myTone.y,myTone.Fs)
            end
            disp('ho finito!')
        end

        % Button pushed function: SaveDownstSpec
        function SaveDownstSpecButtonPushed(app, event)
            SaveDownstSpectra(app,[])
        end

        % Button pushed function: analyzeData
        function analyzeData_Callback(app, event)
            % This function computes the distribution of the spectral power in the
            % 'envelope' band width. The distribution is fitted by a double gaussian
            % and the limits for the event detection are computed
            
            app.evDet.Hcenters = [];
            app.evDet.cdist = [];
            app.evDet.cdistN = [];
            app.evDet.Hnelements = [];
            app.evDet.normNelements = [];
            app.evDet.highThrLine = [];
            app.evDet.lowThrLine = [];
            
            %             % first, extract the data to be analyzed
            %             dataSource = 9;
            %             if get(app.detectionSPG,'Value')
            %                 dataSource = 0;
            %             end
            %             if get(app.detectionBP1,'Value')
            %                 if app.BPcomputed(2), dataSource = 2;
            %                 end
            %             end
            %             if get(app.detectionBP2,'Value')
            %                 if app.BPcomputed(3), dataSource = 3;
            %                 end
            %             end
            %             if get(app.detectionBP3,'Value')
            %                 if app.BPcomputed(4), dataSource = 4;
            %                 end
            %             end
            %             if get(app.detectionHP,'Value')
            %                 if app.BPcomputed(5), dataSource = 5;
            %                 end
            %             end
            
            % first, extract the data to be analyzed
            dataSource = NaN;
            % Find the selected BP
            selBP = find(strcmp(app.detectionBP_dropDown.Value,app.detectionBP_dropDown.Items));
            if selBP < length(app.detectionBP_dropDown.Items) % it is not SPG envelope, which is always the last element
                if app.BPcomputed(selBP)
                    dataSource = selBP;
                end
            else %SPG envelope is selected
                dataSource = 0;
            end
            
            if ~isnan(dataSource)
                ct = app.currentTrial;
                cCh = app.currentCh;
                if dataSource == 0
                    sl = app.spgl;
                    %                 else
                    %                     sl = app.BPpowerL(dataSource);
                end
                logFlag = get(app.powerLogCk,'Value');
                leftH = get(app.fromH, 'Value');
                rightH = get(app.toH, 'Value');
                bins = get(app.binNumber, 'Value');
                
                temp = []; % just to be sure
                
                
                % 2019/05/28 Gab. Rearranged and adapted to multiple BPs
                % and to the new structure of the variable BPpower
                
                if ~dataSource
                    temp (1:sl) = app.spgPlot(cCh,ct,1:sl);
                else
                    temp = squeeze(app.BPpower(dataSource).power(cCh,ct,:));
                end
                
                
                if logFlag
                    temp = log10(temp);
                    
                end
                
                
                % check about auto scaling or not
                if leftH== Inf
                    [nelements,centers] = hist(temp,bins);
                else
                    %                     xValues = leftH:(rightH-leftH)/bins:rightH;
                    xValues = linspace(leftH,rightH,bins);
                    [nelements,centers] = hist(temp,xValues);
                end
                
                % compute the 1) normalized histo, 2) cumulative distribution, 3)
                % normalized cumulative distribution
                totalN = sum (nelements);
                app.evDet.normNelements = nelements/totalN;
                for j=1:bins
                    app.evDet.cdist (j) = sum(nelements(1:j));
                end
                app.evDet.cdistN = app.evDet.cdist / totalN;
                
                app.evDet.Hcenters = centers;
                app.evDet.Hnelements = nelements;
                
                
                app.evDet.meanPW(cCh,ct) = mean(temp);
                app.evDet.stdPW(cCh,ct) = std(temp);
                nsamples = length(centers);
                nmeasures = sum(nelements);
                
                % Teresa, 19.03.20,moved below
                %                 tll = app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct)/3;
                %                 set(app.baselineThr,'Value',tll);
                %                 tll = app.evDet.meanPW(cCh,ct)+app.evDet.stdPW(cCh,ct)/3;
                %                 set(app.eventThr,'Value',tll);
                
                %f = fit(centers.',nelements.','gauss2');
                
                % fit the histogram with a double gaussian. 10.0266 = 2*2*sqr(2*pi)
                param(2)= app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct); % mean first component
                param(3)= app.evDet.stdPW(cCh,ct)/2;                      % SD first component
                amplitude(1)= nmeasures/(10.0266*param(3));                 % amplitude first component
                param(5)= app.evDet.meanPW(cCh,ct)+2*app.evDet.stdPW(cCh,ct); % mean second component
                param(6)= app.evDet.stdPW(cCh,ct)/2;                      % SD second component
                amplitude(2)= nmeasures/(10.0266*param(6));                 % amplitude first component
                gaussFnc = [];
                
                % July 10, 2017. First, fit with fixed means and STDs to obtain a
                % preliminary estimates of the gaussian amplitudes.
                ampliOut = fminsearch(@gaussFit1,amplitude);
                param(1) = ampliOut(1);
                param(4) = ampliOut(2);
                % next, recompute everything.
                paramOut = fminsearch(@gaussFit,param);
                
                app.evDet.GaussFunct = gaussFnc;
                
                if paramOut(2)>paramOut(5)
                    paramOut = paramOut([4,5,6,1,2,3]);
                end
                % paramOut contains the 6 parameters that define the best fit.
                st = ['Gauss 1; mean:' num2str(paramOut(2),'%2.2f') ' ±SD: ' num2str(abs(paramOut(3)),'%2.2f')];
                set (app.gauss1param, 'Text', st);
                st = ['Gauss 2; mean:' num2str(paramOut(5),'%2.2f') ' ±SD: ' num2str(abs(paramOut(6)),'%2.2f')];
                set (app.gauss2param, 'Text', st);
                % also store them into the evDet structure
                app.evDet.ampl  = [paramOut(1), paramOut(4)];
                app.evDet.mu    = [paramOut(2), paramOut(5)];
                app.evDet.sigma = [abs(paramOut(3)), abs(paramOut(6))];
                
%                 % now compute the state thresholds
%                 xinterc = fzero(@gaussDiff,(paramOut(2)+paramOut(5))/2);
                
                % now compute the ROC curve and the optimal threshold
                gLow = mygauss(centers,app.evDet.ampl(1),app.evDet.mu(1),app.evDet.sigma(1));
                gLow_norm = gLow/sum(gLow);
                gHi = mygauss(centers,app.evDet.ampl(2),app.evDet.mu(2),app.evDet.sigma(2));
                gHi_norm = gHi/sum(gHi);
                % compute hits and false alarms
                hits = nan(size(centers));
                fa = hits;
                for thr=1:length(centers)
                    fa(thr) = sum(gLow_norm(centers>=centers(thr)));
                    hits(thr) = sum(gHi_norm(centers>=centers(thr)));
                end
                % compute the error function
                errMetric = (1-hits)+fa;
                [~,minx] = min(errMetric);
                app.evDet.autoblThr = centers(minx);
                app.evDet.autoevThr = centers(minx);
                % plot the error function and the ROC curve
                
                PlotHist(app, app.evDet.autoblThr, app.evDet.autoblThr);
                
                %plot the error function
                cla(app.errorRocAxes)
                plot(app.errorRocAxes,centers,errMetric)
                plot(app.errorRocAxes,centers(minx),errMetric(minx),'o')
                % plot the ROC curve
                cla(app.rocAxes)
                plot(app.rocAxes,fa,hits)
                scatter(app.rocAxes,fa(minx),hits(minx))
                
                set(app.baselineThr,'Value',app.evDet.autoblThr);
                set(app.eventThr,'Value',app.evDet.autoblThr);
                
                disp('Data analyzed')
            else
                errStr = [app.detectionBP_dropDown.Value ' is not a valid source.'];
                error(errStr)
            end
            
            function res = gaussFit(a)
                % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                % nelements is the array that contains the histogram bin counts: this
                % is the function to fit
                gaussFnc = a(1)*exp(-(centers-a(2)).^2/(2*(a(3)^2))) + a(4)*exp(-(centers-a(5)).^2/(2*(a(6)^2)));
                res = sum((nelements-gaussFnc).^2);
            end
            function res = gaussFit1(a)
                % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                % nelements is the array that contains the histogram bin counts: this
                % is the function to fit
                gaussFnc = a(1)*exp(-(centers-param(2)).^2/(2*(param(3)^2))) + a(2)*exp(-(centers-param(5)).^2/(2*(param(6)^2)));
                res = sum((nelements-gaussFnc).^2);
            end
            function y = gaussDiff(x)
                y = paramOut(1)*exp(-(x-paramOut(2)).^2/(2*(paramOut(3)^2)));
                y = y - paramOut(4)*exp(-(x-paramOut(5)).^2/(2*(paramOut(6)^2)));
            end
            function y = mygauss(x,a,mu,sigma)
                y = a.*exp(-((x-mu).^2)./2./(sigma^2));
            end
        end

        % Button pushed function: exportTab_btn
        function exportTab_btn_Callback(app, event)
            % hObject    handle to exportTab_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmp = app.UStable.Data;
            splitDir = regexp(app.dir_in,app.PathSeparator,'split');
            outName = [app.dir_in, app.PathSeparator,'UStable_Ch' num2str( app.currentCh) '_' splitDir{end-1} '_' app.file_in '.mat'];
            save(outName,'tmp');
        end

        % Button pushed function: extractEvents
        function extractEvents_Callback(app, event)
            % refers to the 'compute' button
            app.evDet.upstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            app.evDet.downstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            
            app.evDet.SPG = [];
            app.evDet.LFP = [];
            app.evDet.LFP_hem2 = [];
            app.evDet.timeSPG = [];
            app.evDet.upstates.fromI = [];
            app.evDet.upstates.toI = [];
            app.evDet.downstates.fromI = [];
            app.evDet.downstates.toI = [];
            app.evDet.USlist = [];
            app.evDet.USlist = {1 1 1 1 1 1 1 1 1 1};
            app.evDet.DSlist = [];
            app.evDet.DSlist = {1 1 1 1 1 1};
            
            % set the threshold for event detection based on the used method
            if get(app.autoThr,'Value') % if autosetting state thresshold is clicked, automatically determine threshold
                lowThr = app.evDet.autoblThr; % this value is calculated later in analyzeData function based on power distribution!
                highThr = app.evDet.autoevThr; % this value is calculated later in analyzeData function based on power distribution!
            else
                lowThr = get(app.baselineThr,'Value'); %otherwise, obtain user defined threshold values
                highThr = get(app.eventThr,'Value');
            end
            
            % Identify the dataset to be analyzed
            dataSource = NaN;
            % Find the selected BP
            selBP = find(strcmp(app.detectionBP_dropDown.Value,app.detectionBP_dropDown.Items));
            if selBP < length(app.detectionBP_dropDown.Items)
                % selBP is not SPG envelope, which is always the last element
                if app.BPcomputed(selBP)
                    dataSource = selBP;
                end
            else
                % SPG envelop is selected
                dataSource = 0;
            end
            
            if ~isnan(dataSource)
                
                % STEP 1) get info about the data source
                ct = app.currentTrial;
                cCh = app.currentCh;
                len = app.dtaLen;
                spp = app.sp;
                logFlag = get(app.powerLogCk,'Value'); % if 'log of envelope power' is checked
                app.evDet.tmin = get(app.leftTime,'Value'); % time values entered by user in the bottom left box
                app.evDet.tmax = get(app.rightTime,'Value');
                
                app.evDet.LFP(1:len) = app.workLFP (cCh,ct,1:len);
                
                % Check if double hemisphere analysis is selected
                if app.doubleHemCheckBox.Value
                    Ch_hem2 = app.otherHem.Value;
                    app.evDet.LFP_hem2(1:len) = app.workLFP(Ch_hem2, ct, 1:len);
                end
                
                app.evDet.timeSPG = [];
                if dataSource == 0          % length of the spectral power file
                    sl = app.spgl;
                    app.evDet.timeSPG = app.spgt;
                else
                    sl = length(app.BPpower(dataSource).time); % length of the vector power of band passed data
                    app.evDet.timeSPG = app.BPpower(dataSource).time; % spectrogram data itself for current BP setting
                end
                
                % 2019/05/28 Gab. Rearranged and adapted to multiple BPs
                % and to the new structure of the variable BPpower
                
                if ~dataSource
                    app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
                else
                    % a BP is selected
                    app.evDet.SPG = squeeze(app.BPpower(dataSource).power(cCh,ct,:));
                end
                
                if logFlag
                    app.evDet.SPG = log10(app.evDet.SPG);
                end
                
                % STEP 2) find timepoints above or below the threshold
                downStates_logical = app.evDet.SPG < lowThr;
                lds = sum(downStates_logical);
                upStates_logical = app.evDet.SPG >= highThr;
                lus = sum(upStates_logical);
                %%% code for the old upstates extraction function. Uncomment
                % if you want to check that the old and the new functions
                % return the same results
                %                 downStatesI = find(app.evDet.SPG< lowThr); % indexes of SPG where it is below threshold (i.e. is downstate)
                %                 upStatesI = find(app.evDet.SPG>highThr);
                % %                 limboBolean = ~(downStatesBolean | upStatesBolean); % I don't understand this, it seems to create an empty logical array.
                %                                                     % Gab: it's a logic array with the indices of time points belonging nor
                %                                                     % to the upstates neither to the downstates
                %                 % now the vector app.evDet.SPGt (downstatesBolean) contains the timing of the
                %                 % upstates. This has to be converted to the indexes of the workLFP file
                %                 lus = length (upStatesI);
                %                 lds = length (downStatesI);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % STEP3) obtain US and DS
                %       GAB 2020/04/13: i have grouped these computation in a
                %       function called "processStates".
                nus = 0; 
                nds = 0;
                if lus>0 % if there's at least one upstate
                    [app.evDet.upstates,nus] = processStates(upStates_logical,app.evDet.timeSPG,app.sp);
                    
                    % discard the first upstate if it started before the
                    % beginning of the trace
                    if app.evDet.upstates.fromI(1) == 1
                        app.evDet.upstates = removeStates(app.evDet.upstates,1);
                        nus = nus-1;
                    end
                    
                    % discard the last upstate if it ended after the end
                    % of the trace
                    if app.evDet.upstates.fromI(end) == app.dtaLen
                        last = length(app.evDet.upstates.fromI);
                        app.evDet.upstates = removeStates(app.evDet.upstates,last);
                        nus = nus-1;
                    end
                    
                    % check if the results of the new and of the old extraction
                    % methods are the same
                    %                     [app.evDet.upstates,nus]    = processStatesOld(app,upStatesI);
                    %                     disp('are US time the same?')
                    %                     check_fromT = isequal(app.evDet.upstatesOld.fromT,app.evDet.upstates.fromT)
                    %                     check_toT = isequal(app.evDet.upstatesOld.toT,app.evDet.upstates.toT)
                    %                     disp('are US indices the same?')
                    %                     check_fromI = isequal(app.evDet.upstatesOld.fromI,app.evDet.upstates.fromI)
                    %                     check_toI = isequal(app.evDet.upstatesOld.toI,app.evDet.upstates.toI)
                end
                if lds>0
                    [app.evDet.downstates,nds]  = processStates(downStates_logical,app.evDet.timeSPG,app.sp);
                end
                
                
                % step 4) defragment DS and US across short state gap. The max size of the filled
                %       gap is defined in the GUI
                %       These are the rules: a brief interruption shorter than maxInterruption is
                %       filled in by assigning to the neighboring US that are fused in a longer
                %       one. Isolated US briefer than minDuration are attributed to the limbo.
                if get(app.removeCk,'Value') %if the remove state interruptions button is clicked
                    maxGap = get(app.maxInterruption,'Value');
                    minL = get(app.minDuration,'Value');
                    
                    % first, perform the fusions since brief US might get
                    % fused together to form a longer and legal US.
                    app.evDet.upstates = removeGaps(app.evDet.upstates,maxGap);
                    
                    % finally, remove short upstates (TODO: and assign them
                    % to the limbo, later)
                    shortUpstates = app.evDet.upstates.deltaT < minL;
                    app.evDet.upstates = removeStates(app.evDet.upstates,shortUpstates);
                    nus = length(app.evDet.upstates.deltaT);
                    
                    % do the same for downstates. Fist, perform the fusion.
                    % GAB: 2020-06-11
                    % WARNING: I included this part for reproducibility,
                    % since it was part of the old code. However, we should
                    % check the "fragment" we're going to include while
                    % fusing two downstates: if it belongs to the limbo, we
                    % can proceed; however, if it belongs to a "discarded"
                    % (i.e. isolated) US, we should not!
                    app.evDet.downstates = removeGaps(app.evDet.downstates,maxGap);
                    
                    % finally, remove short downstates
                    shortDownstates = app.evDet.downstates.deltaT < minL;
                    app.evDet.downstates = removeStates(app.evDet.downstates,shortDownstates);
                    nds = length(app.evDet.downstates.deltaT);
                    
                    %                     %%% OLD DEFRAGMENTATION CODE %%%%%%%%%%%%%%%%%%%%%%%%%%
                    %                     % process US first
                    %                     usi = 2;
                    %                     while usi <= nus % there must be at least two up states to do this operation, loop through from 2 till nus
                    %                         % perform the fusions first since brief US might get fused together
                    %                         % to form a longer and legal US.
                    %                         interval = [app.evDet.upstates.fromT(usi) app.evDet.upstates.toT(usi-1)];
                    %                         distance = interval(1) - interval(2);
                    %                         if (distance<=maxGap)
                    %                             % first fuse the two upstates
                    %                             app.evDet.upstates.toT(usi-1) = app.evDet.upstates.toT(usi);
                    %                             app.evDet.upstates.toI(usi-1) = app.evDet.upstates.toI(usi);
                    %                             app.evDet.upstates.deltaT(usi-1) = app.evDet.upstates.toT(usi-1) - app.evDet.upstates.fromT(usi-1);
                    %                             % second remove the US pointed to by usi
                    %                             app.evDet.upstates.toT(usi) = [];
                    %                             app.evDet.upstates.toI(usi) = [];
                    %                             app.evDet.upstates.fromT(usi) = [];
                    %                             app.evDet.upstates.fromI(usi) = [];
                    %                             app.evDet.upstates.deltaT(usi) = [];
                    %                             nus = nus - 1;
                    %                         else
                    %                             usi = usi+1;
                    %                         end
                    %                     end
                    %                     % remove brief states
                    %                     toBeRemoved = (app.evDet.upstates.deltaT <= minUS);
                    %                     app.evDet.upstates.fromI    (toBeRemoved) = [];
                    %                     app.evDet.upstates.toI      (toBeRemoved) = [];
                    %                     app.evDet.upstates.fromT    (toBeRemoved) = [];
                    %                     app.evDet.upstates.toT      (toBeRemoved) = [];
                    %                     app.evDet.upstates.deltaT   (toBeRemoved) = [];
                    %                     app.evDet.upstates.enable   (toBeRemoved) = [];
                    %                     nus = sum(~toBeRemoved);
                    %                     % process DS
                    %                     for usi=2:nds
                    %                         interval = [app.evDet.downstates.fromT(usi) app.evDet.downstates.toT(usi-1)];
                    %                         distance = interval(1) - interval(2);
                    %                         if (distance<=maxGap)
                    %                             % fuse the contiguous downstates
                    %                             app.evDet.downstates.toT(usi-1) = app.evDet.downstates.toT(usi);
                    %                             app.evDet.downstates.toI(usi-1) = app.evDet.downstates.toI(usi);
                    %                             app.evDet.downstates.deltaT(usi-1) = app.evDet.downstates.toT(usi-1) - app.evDet.downstates.fromT(usi-1);
                    %                             app.evDet.downstates.enable(usi) = 0;
                    %                         end
                    %                     end
                    %                     % remove
                    %                     toBeRemoved = app.evDet.downstates.enable & (app.evDet.downstates.deltaT<= minUS);
                    %                     app.evDet.downstates.fromI  (toBeRemoved) = [];
                    %                     app.evDet.downstates.toI    (toBeRemoved) = [];
                    %                     app.evDet.downstates.fromT  (toBeRemoved) = [];
                    %                     app.evDet.downstates.toT    (toBeRemoved) = [];
                    %                     app.evDet.downstates.deltaT (toBeRemoved) = [];
                    %                     app.evDet.downstates.enable (toBeRemoved) = [];
                    %                     nds = sum(~toBeRemoved);
                    %                     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                end
                
                
                % STEP 5) refine the edges of the state detection. Experimental and not great
                %       not to be used as of March 22, 2017
                refineEdges (app);
                
                % STEP 6) At this stage all US and DS have been extracted and we can compute the
                %       relative metrics.
                %       Compute median, PW and SD of each US and DS.
                
                % Identify the channel for measuring power during US
                dataSourcePw = NaN;
                % Find the selected BP
                selBPpow = find(strcmp(app.powerUS_dropDown.Value,app.powerUS_dropDown.Items));
                if selBPpow < length(app.powerUS_dropDown.Items)
                    % selBP is not SPG envelope, which is always the last element
                    if app.BPcomputed(selBPpow)
                        dataSourcePw = selBPpow;
                    else
                        error(['BP ' num2str(selBPpow) ' has not been computed'])
                    end
                else
                    % SPG envelop is selected
                    dataSourcePw = 0;
                end
                
                for usi=1:nds   % compute DS first since you need this to compute US size
                    i1 = app.evDet.downstates.fromI(usi);
                    i2 = app.evDet.downstates.toI(usi);
                    app.evDet.downstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                    app.evDet.downstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                    %                    app.evDet.downstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                    if  dataSourcePw>0
                        app.evDet.downstates.pw(usi) = rms(squeeze(app.bandPassed_LFP(dataSourcePw,cCh,ct,i1:i2)));
                    else
                        app.evDet.downstates.pw(usi) = 0;
                    end
                    if app.doubleHemCheckBox.Value
                        app.evDet.downstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                        app.evDet.downstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                        app.evDet.downstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                        if  dataSourcePw>0
                            app.evDet.downstates.pw_hem2(usi) = rms(squeeze(app.bandPassed_LFP(dataSourcePw,Ch_hem2,ct,i1:i2)));
                        else
                            app.evDet.downstates.pw_hem2(usi) = 0;
                        end
                    end
                end
                DSsearch = 1;
                dsi1 = app.evDet.downstates.fromI(DSsearch);
                dsi2 = app.evDet.downstates.toI(DSsearch);
                for usi=1:nus
                    i1 = app.evDet.upstates.fromI(usi);
                    i2 = app.evDet.upstates.toI(usi);
                    app.evDet.upstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                    app.evDet.upstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                    %                    app.evDet.upstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                    if  dataSourcePw>0
                        app.evDet.upstates.pw(usi) = rms(squeeze(app.bandPassed_LFP(dataSourcePw,cCh,ct,i1:i2)));
                    else
                        app.evDet.upstates.pw(usi) = 0;
                    end
                    if app.doubleHemCheckBox.Value
                        app.evDet.upstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                        app.evDet.upstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                        %                        app.evDet.upstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                        if  dataSourcePw>0
                            app.evDet.upstates.pw_hem2(usi) = rms(squeeze(app.bandPassed_LFP(dataSourcePw,Ch_hem2,ct,i1:i2)));
                        else
                            app.evDet.upstates.pw_hem2(usi) = 0;
                        end
                    end
                    % now search for the closest DS
                    while (dsi2 < i1) && (DSsearch <= nds) % while the end of the downstate is smaller than the start of the current up
                        % state and smaller than the number of down states
                        DSsearch = DSsearch + 1; % it will increase by one: we found the down state following the current up state
                        if DSsearch <= nds
                            dsi2 = app.evDet.downstates.toI(DSsearch);
                        end
                    end
                    % now DSsearch points to the DS immediatey after unless the data ends
                    % with a US. A second exception is when the data starts with an US
                    if DSsearch == 1     % data begins with US
                        baseline = app.evDet.downstates.medianV(1);
                        if app.doubleHemCheckBox.Value
                            baseline_hem2 = app.evDet.downstates.medianV_hem2(1);
                        end
                    else
                        if DSsearch > nds    % data ends up with a US
                            baseline = app.evDet.downstates.medianV(nds);
                            if app.doubleHemCheckBox.Value
                                baseline_hem2 = app.evDet.downstates.medianV_hem2(nds);
                            end
                        else
                            % OK, this is a middle of the road US!
                            baseline = (app.evDet.downstates.medianV(DSsearch)+app.evDet.downstates.medianV(DSsearch-1))/2;
                            if app.doubleHemCheckBox.Value
                                baseline_hem2 = (app.evDet.downstates.medianV_hem2(DSsearch)+app.evDet.downstates.medianV_hem2(DSsearch-1))/2;
                            end
                        end
                    end
                    % this is added by Didi, in order to access the value later, so that
                    % I can add a downward going filter
                    %                    newmedianV(usi) = app.evDet.upstates.medianV(usi) - baseline;
                    %                    if app.doubleHemCheckBox.Value
                    %                         newmedianV_hem2(usi) = app.evDet.upstates.medianV_hem2(usi) - baseline_hem2;
                    %                    end
                    app.evDet.upstates.medianV(usi) = app.evDet.upstates.medianV(usi) - baseline;
                    if app.doubleHemCheckBox.Value
                        app.evDet.upstates.medianV_hem2(usi) = app.evDet.upstates.medianV_hem2(usi) - baseline_hem2;
                    end
                end
                % This part is added by Didi in November, 2018. Meant to remove
                % upstates from the list that have a positive medianV (i.e. that are
                % just artefacts)
                if app.removePosUSCheckBox.Value
                    positiveUs = app.evDet.upstates.medianV > 0;
                    
                    % now remove the disabled up states from the list as was done above
                    app.evDet.upstates = removeStates(app.evDet.upstates,positiveUs);
                    nus = length(app.evDet.upstates.deltaT);
                end
                
                % update the counter of US and DS
                app.evDet.NUS = nus;
                app.evDet.NDS = nds;
                
                % STEP 7) now create the list
                %       Creation of the list of USs
                app.evDet.USlist (1:nus,1) = num2cell(1:nus); % the first cell contains the up state number
                app.evDet.USlist (1:nus,2) = num2cell(app.evDet.upstates.fromT(1:nus)); % the second cell contains the start
                % time of the corresponding up state
                app.evDet.USlist (1:nus,3) = num2cell(app.evDet.upstates.toT(1:nus));   % then the end time
                app.evDet.USlist (1:nus,4) = num2cell(app.evDet.upstates.deltaT(1:nus)); % and the length of the up state
                app.evDet.USlist (1:nus,5) = num2cell(app.evDet.upstates.medianV(1:nus)); % the median amplitude in mV
                app.evDet.USlist (1:nus,6) = num2cell(app.evDet.upstates.SD(1:nus));    % the standard deviation of the LFP in mV
                app.evDet.USlist (1:nus,7) = num2cell(app.evDet.upstates.pw(1:nus));    % and the power in the frequency band used for the detection
                if app.doubleHemCheckBox.Value
                    % same metrics for the other hemisphere
                    app.evDet.USlist (1:nus,8) = num2cell(app.evDet.upstates.medianV_hem2(1:nus));
                    app.evDet.USlist (1:nus,9) = num2cell(app.evDet.upstates.SD_hem2(1:nus));
                    app.evDet.USlist (1:nus,10) = num2cell(app.evDet.upstates.pw_hem2(1:nus));
                end
                
                % Teresa 202010228: creation of a list for DSs
                if app.pwinDSCk.Value
                    app.evDet.DSlist (1:nds,1) = num2cell(1:nds); % the first cell contains the down state number
                    app.evDet.DSlist (1:nds,2) = num2cell(app.evDet.downstates.fromT(1:nds)); % the second cell contains the start time
                    app.evDet.DSlist (1:nds,3) = num2cell(app.evDet.downstates.toT(1:nds)); % then the end time
                    app.evDet.DSlist (1:nds,4) = num2cell(app.evDet.downstates.deltaT(1:nds)); % and the duration of the down state
                    app.evDet.DSlist (1:nds,5) = num2cell(app.evDet.downstates.medianV(1:nds));
                    app.evDet.DSlist (1:nds,6) = num2cell(app.evDet.downstates.SD(1:nds));
                    app.evDet.DSlist (1:nds,7) = num2cell(app.evDet.downstates.pw(1:nds));
                      if app.doubleHemCheckBox.Value
                          app.evDet.DSlist (1:nds,8) = num2cell(app.evDet.downstates.medianV_hem2(1:nds));
                          app.evDet.DSlist (1:nds,9) = num2cell(app.evDet.downstates.SD_hem2(1:nds));
                          app.evDet.DSlist (1:nds,10) = num2cell(app.evDet.downstates.pw_hem2(1:nds));
                      end
                end
                % END OF THE "STANDARD" CALCULATION.
                
                
                % STEP 8)
                %       Didi February 2019: Add Number of Multipeak Waves for both hemispheres.
                %       Based on the set of three papers Tononi lab: Sleep Homeostasis
                %       and Cortical Synchronization. This analysis is going to use the
                %       Band Passed filter data in the BP1 range. Here, an up state is represented
                %       as a negative deflection of the signal. Multipeak waves are defined as
                %       waves with more than 1 negative peak between 2 zero crossings. So here, I
                %       took the BP1 filtered LFP from the start till the end of each up state and
                %       used the findpeaks function of matlabs Signal Processing Toolbox to find
                %       negative peak number of both hemispheres.
                
                % GAB 2020-06-12: moved it into a function, which is written at
                % the end of this callback
                
                if app.multiPeakWavesCheckBox.Value
                    % Gab 2019/05/28: from now on BPs are not fixed, so it is needed to choose the desired BP for this
                    % analysis. I added a spinner called app.detectionBPmultiPeak_dropDown for this purpose.
                    multiPeakBP = app.detectionBPmultiPeak_spinner.Value;
                    
                    % start by setting up the parameters to find multipeaks of the current hemispere
                    deltaBP = squeeze(app.bandPassed_LFP(multiPeakBP,app.currentCh, app.currentTrial, 1:app.dtaLen));
                    
                    % Now start looping through the up states and find peaks
                    multipeak = findMultipeaks(app.evDet.upstates,deltaBP);
                    
                    % same to find peaks of other hemisphere
                    if app.doubleHemCheckBox.Value
                        deltaBP_hem2 = squeeze(app.bandPassed_LFP(multiPeakBP,Ch_hem2, app.currentTrial, 1:app.dtaLen));
                        multipeak_hem2 = findMultipeaks(app.evDet.upstates,deltaBP_hem2);
                    end
                    
                    % store the results into the upstates list
                    app.evDet.USlist (1:app.evDet.NUS,11) = num2cell(multipeak);
                    if app.doubleHemCheckBox.Value
                        app.evDet.USlist (1:app.evDet.NUS,12) = num2cell(multipeak_hem2);
                    end
                end
                % End of the finding of multipeak waves.
                
                % STEP 9)
                %        Didi February 2019: Finding slopes of the slow waves. Based on the set of three papers
                %        from Tononi lab: Sleep Homeostasis and Cortical Synchronization. Here, we take the
                %        0.5-4 Hz (BP1) filtered LFP, where an up state is represented as a negative delfection.
                %        We define both the average slope, and the maximum slope of the first and second segment
                %        of both hemispheres. Average slope is defined as the amplitude of the most negative peak,
                %        divided by the time of the previous zero crossing till the peak (for the first segment) or
                %        from the peak till the next zero crossing (for the second segment). Maximum slopes were
                %        defined as the maximum of the signal derivative (after applying a 1 millisecond moving average)
                %        following the negative zero crossing (first-segment slope) but before the most negative
                %        peak, or subsequent to the most negative peak (second-segment slope) but prior to the
                %        positive-going zero crossing.
                
                % GAB 2020-06-12: This definition is straightforward and of easy
                % implementation. However, it appears to me that it does
                % not reflect the intuitive concept of rising and
                % falling slope at all!
                if app.USslopes_ck.Value
                    % Start with the current channel (hemisphere)
                    % Set the parameters
                    multiPeakBP = app.detectionBPmultiPeak_spinner.Value; % the same as for multiPeak analysis: delta band.
                    deltaBP = squeeze(app.bandPassed_LFP(multiPeakBP,app.currentCh, app.currentTrial, 1:app.dtaLen));
                    % GAB 2020-06-12: moved all the computation into
                    % the function calcSlopes, which is written at the end
                    % of this callback
                    [avslopesegment1,maxslopesegment1,...
                        avslopesegment2,maxslopesegment2] = calcSlopes(app.evDet.upstates,deltaBP,app.sp);
                    
                    % Now the the other hemisphere
                    if app.doubleHemCheckBox.Value
                        % Get the filtered trace
                        deltaBP_hem2(1:app.dtaLen) = app.bandPassed_LFP(multiPeakBP, Ch_hem2, app.currentTrial, 1:app.dtaLen);
                        % Compute everything
                        [avslopesegment1_hem2,maxslopesegment1_hem2,...
                            avslopesegment2_hem2,maxslopesegment2_hem2] = calcSlopes(app.evDet.upstates,deltaBP_hem2,app.sp);
                    end
                    % Didi February 2019: Add the lopes to the US table
                    app.evDet.USlist(1:app.evDet.NUS,13) = num2cell(avslopesegment1);
                    app.evDet.USlist(1:app.evDet.NUS,14) = num2cell(maxslopesegment1);
                    app.evDet.USlist(1:app.evDet.NUS,15) = num2cell(avslopesegment2);
                    app.evDet.USlist(1:app.evDet.NUS,16) = num2cell(maxslopesegment2);
                    % Other hemisphere, if selected
                    if app.doubleHemCheckBox.Value
                        app.evDet.USlist(1:app.evDet.NUS,17) = num2cell(avslopesegment1_hem2);
                        app.evDet.USlist(1:app.evDet.NUS,18) = num2cell(maxslopesegment1_hem2);
                        app.evDet.USlist(1:app.evDet.NUS,19) = num2cell(avslopesegment2_hem2);
                        app.evDet.USlist(1:app.evDet.NUS,20) = num2cell(maxslopesegment2_hem2);
                    end
                end
                
                % STEP 10)
                %       Add the up state info to the table in the GUI
                set(app.UStable,'Data',app.evDet.USlist);
                % and also add the down state info if required
                if app.pwinDSCk.Value
                    set(app.DSTable,'Data',app.evDet.DSlist);
                end
                
                % STEP 11)
                %       plot the state IDs
                plotStatesFun (app)
                
                % STEP 12)
                %       create the joined files
                if ~app.noConcCk.Value
                    app.evDet.US = joinStates(app.evDet.upstates,app.evDet.LFP,get(app.joinOffsetChk,'Value'));
                    app.evDet.DS = joinStates(app.evDet.downstates,app.evDet.LFP,get(app.joinOffsetChk,'Value'));
                end
                
                % STEP 13)
                %       and finally let's compute the power spectra!
                if app.computePwsCheckBox.Value && ~app.noConcCk.Value
                    
                    % Compute power spectra
                    app.params = struct('tapers',[],'Fs',1,'fpass',[]);
                    flagChronux = get(app.ChronuxCk,'Value');
                    if flagChronux
                        params.tapers = [5 9];
                        params.Fs = app.acqF;
                        params.fpass = [0 1000];
                        [app.evDet.pssUS,app.evDet.fUS] = mtspectrumc(app.evDet.US, params);
                        app.evDet.fUS = app.evDet.fUS';
                        [app.evDet.pssDS,app.evDet.fDS] = mtspectrumc(app.evDet.DS, params);
                        app.evDet.fDS = app.evDet.fDS';
                    else
                        [app.evDet.pssUS,app.evDet.fUS] = pwelch(app.evDet.US,[],[],[],app.acqF);
                        [app.evDet.pssDS,app.evDet.fDS] = pwelch(app.evDet.DS,[],[],[],app.acqF);
                    end
                    
                    % Plot power spectra
                    evDet_plotSpectra(app)
                end
                
                % STEP 14)
                %       export data
                if app.expTracesCheckBox.Value
                    exportUStraces(app)
                end
                if app.ExportdistributionandthrforcurrentchCheckBox.Value
                    exportDistrThr(app)
                end
                
            else
                % If the selected BP has not been computed
                errStr = [app.detectionBP_dropDown.Value ' is not a valid source.'];
                error(errStr)
            end
            disp('Computation of US and DS completed');
            
            %%%%%% USEFUL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Gab 2020-06-12
            
            function [stateStruct,nStates] = processStates(stateLogical,spg_time,lfp_sp)
                % GAB 2020-06-11: new algorithm, parallelized.
                %1) start, end and duration
                der = [diff(stateLogical);0];
                startI = find(der==1)+1;
                endI = find(der==-1);
                
                % quality control:
                if isempty(startI)
                    % there's only one event starting before the recording
                    startI(1) = 1;
                end 
                if isempty(endI)
                    % there's only one event that starts but ends after the
                    % end of the recording
                    endI(1) = length(stateLogical);
                end
                
                % quality control:
                if endI(1)<startI(1)
                    startI = [1; startI];
                end
                if startI(end)>endI(end)
                    endI = [endI; length(stateLogical)];
                end
                %switch from spg indices to time
                stateStruct.fromT = spg_time(startI);
                stateStruct.toT = spg_time(endI);
                %switch from time to lfp indices
                stateStruct.fromI = round(stateStruct.fromT/lfp_sp)+1;
                stateStruct.toI = round(stateStruct.toT/lfp_sp)+1;
                %state duration
                stateStruct.deltaT = stateStruct.toI - stateStruct.fromI;
                %number of states
                nStates = length (stateStruct.toI);
            end
            
            function stateStruct = removeStates(stateStruct,idx)
                % remove elements of index idx from all the fields of the
                % structure stateStruct.
                fields = fieldnames(stateStruct);
                for i = 1:length(fields)
                    stateStruct.(fields{i})(idx) = [];
                end
                %                 stateStruct.fromT(idx) = [];
                %                 stateStruct.toT(idx) = [];
                %                 stateStruct.deltaT(idx) = [];
                %                 stateStruct.fromI(idx) = [];
                %                 stateStruct.toI(idx) = [];
                %                 stateStruct.medianV(idx) = [];
                %                 stateStruct.SD(idx) = [];
                %                 stateStruct.pw(idx) = [];
                %                 stateStruct.medianV_hem2(idx) = [];
                %                 stateStruct.SD_hem2(idx) = [];
                %                 stateStruct.pw_hem2(idx) = [];
            end
            
            function stateStruct = removeGaps(stateStruct,maxGap)
                gaps = stateStruct.fromT(2:end) - stateStruct.toT(1:end-1);
                shortGaps = gaps < maxGap;
                % fuse US separated by short gaps
                stateStruct.fromT(find(shortGaps)+1) = [];
                stateStruct.toT(find(shortGaps)) = [];
                stateStruct.fromI(find(shortGaps)+1) = [];
                stateStruct.toI(find(shortGaps)) = [];
                stateStruct.deltaT = stateStruct.toT - stateStruct.fromT;
            end
            
            function multipeak = findMultipeaks(stateStruct,deltaBP)
                ns = length(stateStruct.deltaT); % number of states
                multipeak = zeros(ns, 1);
                for i = 1:ns
                    cnt = 0;
                    start = stateStruct.fromI(i);
                    stop = stateStruct.toI(i);
                    pks = findpeaks(-deltaBP(start:stop));
                    if isempty(pks)
                        cnt = 0;
                    else
                        cnt = length(pks);
                    end
                    multipeak(i) = cnt;
                end
            end
            
            function [avslopesegment1,maxslopesegment1,avslopesegment2,maxslopesegment2] = calcSlopes(stateStruct,deltaBP,spp)
                ns = length(stateStruct.deltaT); % number of states
                avslopesegment1 = zeros(ns, 1);
                maxslopesegment1 = zeros(ns, 1);
                avslopesegment2 = zeros(ns, 1);
                maxslopesegment2 = zeros(ns, 1);
                
                for currentState = 1:ns
                    st = double(stateStruct.fromI(currentState));
                    en = double(stateStruct.toI(currentState));
                    [pks, loc] = findpeaks(-deltaBP(st:en)); % pks contains the amplitude of the peaks, loc contains the index of the peaks
                    if isempty(pks) % if there are no peaks, the average and max slopes are 0
                        avslopesegment1(currentState) = 0;
                        maxslopesegment1(currentState) = 0;
                        avslopesegment2(currentState) = 0;
                        maxslopesegment2(currentState) = 0;
                    else
                        [mx, indexmx] = max(pks); % indexmx now contains contains the index of pks and loc with the largest peak
                        locmx = loc(indexmx)+(st-1); % locmx now contains the index of the most negative peak
                        
                        % first find first segment
                        posfirst = find(deltaBP(st:locmx)>=0)+(st-1); % contains all the indexes of delta BP higher than 0 before the most negative peak
                        if isempty(posfirst) % if there are no values above 0, we take the max value of the BP1 LFP
                            [zero1, Izero1] = max(deltaBP(st:locmx));
                            Izerocrossing1 = Izero1+(st-1);
                            timezerocrossing1 = Izerocrossing1*spp;
                            Ipeaktozerocrossing1 = locmx-Izerocrossing1;
                            timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;
                        else
                            distance1 = locmx-posfirst;
                            Ipeaktozerocrossing1 = min(distance1);
                            timepeaktozerocrossing1 = Ipeaktozerocrossing1*spp;
                            Izerocrossing1 = locmx-min(distance1);
                            timezerocrossing1 = Izerocrossing1*spp;
                        end
                        avslopesegment1(currentState) = (-pks(indexmx)-deltaBP(Izerocrossing1))/timepeaktozerocrossing1;
                        
                        % same for the second segment
                        possecond = find(deltaBP(locmx:en)>=0)+(locmx-1); % contains all the indexes of delta BP higher than 0 after the most negative peak
                        if isempty(possecond) % if there are no values above 0, we take the max value of the BP1 LFP
                            [zero2, Izero2] = max(deltaBP(locmx:en));
                            Izerocrossing2 = Izero2+(locmx-1);
                            timezerocrossing2 = Izerocrossing2*spp;
                            Ipeaktozerocrossing2 = Izerocrossing2-locmx;
                            timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;
                        else
                            distance2 = possecond-locmx;
                            Ipeaktozerocrossing2 = min(distance2);
                            timepeaktozerocrossing2 = Ipeaktozerocrossing2*spp;
                            Izerocrossing2 = locmx+min(distance2);
                            timezerocrossing2 = Izerocrossing2*spp;
                        end
                        avslopesegment2(currentState) = (deltaBP(Izerocrossing2)+pks(indexmx))/timepeaktozerocrossing2;
                        
                        % then we need to find the max slope. I want to have a moving (non-overlapping) average of 1 ms steps
                        stepT = 1e-3; % so the time step is 1 ms
                        stepI = stepT/spp; % then the step size in index number is this
                        dervector1 = [];
                        dervector2 = [];
                        
                        % First segment 1
                        cnt1 = 1; %set a count value that keeps track of each new time step
                        for ind1 = Izerocrossing1+stepI:stepI:locmx
                            av1 = mean(deltaBP(ind1-stepI:ind1));
                            dervector1(cnt1) = av1;
                            cnt1 = cnt1+1;
                        end
                        % Now dervector contains the average delta power of 1 ms periods
                        derivative1 = diff(dervector1)./stepT; % diff finds the difference between all the points in the input data (so if input data is
                        % is x, diffX) = x(2)-x(1), x(3)-x(2), etc. of length x-1. So if we divide this by the time step between x(2) and x(1), in our case stepT
                        % we found the derivative
                        if isempty(derivative1)
                            maxslopesegment1(currentState) = 0;
                        else
                            maxslopesegment1(currentState) = min(derivative1); % minimun since negative slope so it should be below 0
                        end
                        
                        % Then for the second segment
                        cnt2 = 1; %set a count value that keeps track of each new time step
                        for ind2 = locmx+stepI:stepI:Izerocrossing2
                            av2 = mean(deltaBP(ind2-stepI:ind2));
                            dervector2(cnt2) = av2;
                            cnt2 = cnt2+1;
                        end
                        % Now dervector contains the average delta power of 1 ms periods
                        derivative2 = diff(dervector2)./stepT; % diff finds the difference between all the points in the input data (so if input data is
                        % is x, diffX) = x(2)-x(1), x(3)-x(2), etc. of length x-1. So if we divide this by the time step between x(2) and x(1), in our case stepT
                        % we found the derivative
                        if isempty(derivative2)
                            maxslopesegment2(currentState) = 0;
                        else
                            maxslopesegment2(currentState) = max(derivative2); % maximum since positive slope so it should be above 0
                        end
                    end
                end
            end
            
            function outTrace = joinStates(stateStruct,sourceTrace,offsetSubtraction)
                ifrom = stateStruct.fromI(1);
                ito = stateStruct.toI(1);
                outTrace = sourceTrace(ifrom:ito);
                ns = length(stateStruct.deltaT);
                for i=2:ns
                    ifrom = stateStruct.fromI(i);
                    ito = stateStruct.toI(i);
                    if offsetSubtraction
                        offset = sourceTrace(ifrom)-outTrace(end);
                        outTrace = [outTrace sourceTrace(ifrom:ito)-offset];
                    else
                        outTrace = [outTrace sourceTrace(ifrom:ito)];
                    end
                end
            end
        end

        % Button pushed function: replot
        function replot_Callback(app, event)
            plotStatesFun(app)
        end

        % Button pushed function: saveHCD
        function saveHCD_Callback(app, event)
            % Save the distributions of the integrated spectral power
            % March 22, 2017.
            
            tmpOut1 = [];
            
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOut = [app.dir_in 'PWSdistribution Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            tmpOut1 (1,:) = app.evDet.Hcenters;
            tmpOut1 (2,:) = app.evDet.Hnelements;
            tmpOut1 (3,:) = app.evDet.normNelements;
            tmpOut1 (4,:) = app.evDet.cdist;
            tmpOut1 (5,:) = app.evDet.cdistN;
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f %12.10f %12.10f\n'];  % 5 columns and start new line
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
        end

        % Button pushed function: savePWS
        function savePWS_Callback(app, event)
            % save as two different files the PS computed from the defragmented UP and
            % Downstate cumulative files.
            
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOutUS = [app.dir_in 'USpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            fileOutDS = [app.dir_in 'DSpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            usl = length (app.evDet.fUS);
            tmpOut1 = [];
            tmpOut2 = [];
            % US and DS have to be saved as separate files since they have different x
            % axis due to the variable length of the consolidated data.
            tmpOut1 (1,:) = app.evDet.fUS;
            tmpOut1 (2,:) = app.evDet.pssUS;
            tmpOut1 (3,:) = 10*log10(app.evDet.pssUS);
            
            tmpOut2 (1,:) = app.evDet.fDS;
            tmpOut2 (2,:) = app.evDet.pssDS;
            tmpOut2 (3,:) = 10*log10(app.evDet.pssDS);
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f\n'];       % 3 columns and start new line
            fid = fopen(fileOutUS,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
            fid = fopen(fileOutDS,'w');
            fprintf (fid,fmtSt,tmpOut2);
            fclose(fid);
        end

        % Button pushed function: saveSegmentedStates
        function saveSegmentedStates_Callback(app, event)
            %GAB: saves 2 separate files for up & down states
            currCh=num2str(app.currentCh);
            currTr=num2str(app.currentTrial);
            fmt='%8.6f \n';
            fid=fopen([app.dir_in 'DS_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.DS);
            fid=fopen([app.dir_in 'US_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.US);
            fclose(fid);
        end

        % Button pushed function: traslateLeft
        function traslateLeft_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmin-delta);
            set(app.rightTime,'Value',app.evDet.tmin);
            
            updateEvDetectAxesLimits (app)
        end

        % Button pushed function: traslateRight
        function traslateRight_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmax);
            set(app.rightTime,'Value',app.evDet.tmax+delta);
            
            updateEvDetectAxesLimits(app)
        end

        % Value changed function: yminTxt_lfp
        function yminTxt_lfp_Callback(app, event)
            %             event = [];
            %             event.yLim = [app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value];
            %             plotWideWindow(app,event)
            %             plotMultiBand(app)
            %             plotSpectrogram(app)
%             plotWideWindow(app)
            axis(app.wide_plot_up, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value]);
        end

        % Button pushed function: ResetButton_lfp
        function ResetButton_lfp_Callback(app, event)
            app.ymaxTxt_lfp.Value = inf;
            app.yminTxt_lfp.Value = -inf;
%             plotWideWindow(app)
            axis(app.wide_plot_up, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_lfp.Value, app.ymaxTxt_lfp.Value]);
        end

        % Button pushed function: CopyTableButton
        function CopyTableButtonPushed(app, event)
            % To copy the data from the table to the clipboard
            tablesize = size(app.evDet.USlist);
            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    if j == tablesize(2)
                        str = sprintf('%s%f',str,app.evDet.USlist{i,j});
                    else
                        str = sprintf('%s%f\t',str,app.evDet.USlist{i,j});
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
            
            
        end

        % Button pushed function: SaveStartImagingButton
        function SaveStartImagingButtonPushed(app, event)
            pos = find(app.LFP(2,1,:)>=0);
            start = (pos(1)*app.sp)-app.sp
            stop = (pos(end)*app.sp)-app.sp
        end

        % Button pushed function: CopytableButton
        function CopytableButtonPushed(app, event)
            % To copy the data from the table of zebrasays to the clipboard
            tablesize = size(app.outable.Data);
            
            % Enrico 20190409
            % Created a function to copy data to clipboard from outtable.
            % selectionIndices is a 4-element vector containing the
            % upper-left corner and the bottom-right corner indices
            SelTab2Clip(app,app.outable,[1 1 tablesize(1) tablesize(2)])
        end

        % Button pushed function: sepochsButton
        function sepochsButtonPushed(app, event)
            sfrom = 0;
            sto = 30;
            app.tminTxt.Value = sfrom;
            app.tmaxTxt.Value = sto;
            for ii = 1:(app.x(end)/30)+1
                if ii > 1
                    sfrom = sfrom + 30;
                    sto = sto + 30;
                else
                end
                %if sto > app.x(end)
                %    break
                %end
                [v ifrom] = min(abs(app.x-sfrom));
                [v2 ito] = min(abs(app.x-sto));
                savetr_name_1 = num2str(sfrom);
                savetr_name_2 = num2str(sto);
                fileOut = [app.dir_in 'trace_' app.file_in '_' savetr_name_1 '-' savetr_name_2 '.dat'];
                tmpOut (1:app.nCh,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
                % create the format descriptor
                str = '';
                for row = 1:length(tmpOut)
                    for column = 1:app.nCh
                        if column == app.nCh
                            str = sprintf('%s%f',str, tmpOut(column, row));
                        else
                            str = sprintf('%s%f\t',str, tmpOut(column, row));
                        end
                    end
                    str = sprintf('%s\n',str);
                end
                fid = fopen(fileOut,'w');
                fprintf(fid, str);
                fclose(fid);
            end
            app.tminTxt.Value = sfrom;
            app.tmaxTxt.Value = sto;
            display('saving done!')
        end

        % Cell selection callback: outable
        function outableCellSelection(app, event)
            app.outtableSelectedIndices = event.Indices;
            app.CopyOutableSelection.Enable = 1;
            app.DeleteSelRows.Enable = 1;
        end

        % Button pushed function: CopyOutableSelection
        function CopyOutableSelectionButtonPushed(app, event)
            selectedIndices = [min(app.outtableSelectedIndices(:,1)) min(app.outtableSelectedIndices(:,2))...
                max(app.outtableSelectedIndices(:,1)) max(app.outtableSelectedIndices(:,2))];
            SelTab2Clip(app,app.outable,selectedIndices)
        end

        % Button pushed function: DeleteSelRows
        function DeleteSelRowsButtonPushed(app, event)
            DeleteRowTable(app, app.outable, app.outtableSelectedIndices)
            app.outtableSelectedIndices = [];
            app.CopyOutableSelection.Enable = 0;
            app.DeleteSelRows.Enable = 0;
        end

        % Callback function
        function SelectBPsButtonPushed(app, event)
            nBP = app.NumberofBPsEditField.Value;
            % Create a cell array containing all the strings to be put aside each text box
            prompt = arrayfun(@(x) ['Bandpass n. ' num2str(x)], 1:nBP, 'un', 0);
            
            if ~isempty(app.BpTable.Data)
                % If app.AutoDlgIn is not empty, AutoDlg is used as preset values of the ZebraAuto folders
                preset = app.BpTable.Data;
            else
                % If there is nothing in app.AutoDlgIn, it is initialized as an empty cell array
                preset = cell(nBP, 2);
                preset(:) = {''};
            end
            
            title = 'Write low and high ends for each band';
            nlines = 1; % This is for dimensions of each line
            inputdlgcol(prompt,title,nlines,preset,'off',2);
            
            
        end

        % Button pushed function: CopyEvochedSelection
        function CopyEvochedSelectionButtonPushed(app, event)
            selectedIndices = [min(app.outEvochedSelectedIndices(:,1)) min(app.outEvochedSelectedIndices(:,2))...
                max(app.outEvochedSelectedIndices(:,1)) max(app.outEvochedSelectedIndices(:,2))];
            SelTab2Clip(app,app.outEvochedResponses,selectedIndices)
        end

        % Cell selection callback: outEvochedResponses
        function outEvochedResponsesCellSelection(app, event)
            app.outEvochedSelectedIndices = event.Indices;
            app.CopyEvochedSelection.Enable = 1;
        end

        % Cell selection callback: BpTable
        function BpTableCellSelection(app, event)
            app.BpTableSelIndices = event.Indices;
            app.DeleteBpBtn.Enable = 1;
            app.MoveUpBpBtn.Enable = 1;
            app.MoveDownBpBtn.Enable = 1;
        end

        % Button pushed function: DeleteBpBtn
        function DeleteBpBtnButtonPushed(app, event)
            toBeDeleted = app.BpTableSelIndices;
            DeleteRowTable(app, app.BpTable, toBeDeleted)
            
            % update the number of BPs
            app.NumberofBPsEditField.Value = size(app.BpTable.Data, 1);
            % re-order the BP indices
            app.BpTable.Data(:,1) = num2cell((1:app.NumberofBPsEditField.Value)');
            % update the frequencyBand vector and all the other related variables
            BPsynch(app,toBeDeleted(1))
            
            %           Delete the bandpassed_LFP part that corresponds to the
            %           deleted bands
            if ~isempty(app.bandPassed_LFP)
                app.bandPassed_LFP(min(app.BpTableSelIndices(:,1)):max(app.BpTableSelIndices(:,1)),:,:,:) = [];
            end
            %             Delete the power in time of the deleted bands
            if app.fileInNum > 0
                app.BPpower(min(app.BpTableSelIndices(:,1)):max(app.BpTableSelIndices(:,1))) = [];
            end
            
            app.BpTableSelIndices = [];
            app.DeleteBpBtn.Enable = 0;
            app.MoveDownBpBtn.Enable = 0;
            app.MoveUpBpBtn.Enable = 0;
        end

        % Button pushed function: MoveUpBpBtn
        function MoveUpBpBtn_ButtonPushed(app, event)
            %         The whole selected part is moved of one row up
            SelRowIndices = [min(app.BpTableSelIndices(:,1)), max(app.BpTableSelIndices(:,1))];
            %             Execute only when the first selected row is not the first one
            if SelRowIndices(1) > 1
                nBP = app.NumberofBPsEditField.Value;
                % switch BP indices (to do so, we need to include the 'wrong' index 0)
                newInd = [0:SelRowIndices(1)-2,SelRowIndices(1):SelRowIndices(2),SelRowIndices(1)-1,SelRowIndices(2)+1:nBP];
                % remove index 0
                newInd(1) = [];
                % switch rows
                app.BpTable.Data = app.BpTable.Data(newInd,:);
                if ~isempty(app.bandPassed_LFP)
                    %             Move up the bandpassed_LFP part that corresponds to the
                    %             selected bands
                    app.bandPassed_LFP = app.bandPassed_LFP(newInd,:,:,:);
                end
                %             Move up the power in time of the selected bands
                if app.fileInNum > 0
                    app.BPpower = app.BPpower(newInd);
                end
            end
        end

        % Button pushed function: MoveDownBpBtn
        function MoveDownBpBtn_ButtonPushed(app, event)
            %         The whole selected part is moved of one row down
            SelRowIndices = [min(app.BpTableSelIndices(:,1)), max(app.BpTableSelIndices(:,1))];
            %             Execute only when the last selected row is not the last one
            if SelRowIndices(2) ~= size(app.BpTable.Data,1)
                %                 Now the row after the last selected one can be saved
                %                 and the selected rows are shifted of one row down.
                
                nBP = app.NumberofBPsEditField.Value;
                % switch BP indices (to do so, we need to include the 'wrong' index nBP+1)
                newInd = [1:SelRowIndices(1)-1,SelRowIndices(2)+1,SelRowIndices(1):SelRowIndices(2),SelRowIndices(2)+2:nBP+1];
                % remove index nBP+1
                newInd(nBP+1) = [];
                % switch rows
                app.BpTable.Data = app.BpTable.Data(newInd,:);
                if ~isempty(app.bandPassed_LFP)
                    %             Move up the bandpassed_LFP part that corresponds to the
                    %             selected bands
                    app.bandPassed_LFP = app.bandPassed_LFP(newInd,:,:,:);
                end
                %             Move up the power in time of the selected bands
                if app.fileInNum > 0
                    app.BPpower = app.BPpower(newInd);
                end
            end
        end

        % Cell edit callback: BpTable
        function BpTable_CellEdit(app, event)
            if event.NewData < 0
                uialert(app.ZebraMainFig,'You have to type a positive number.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = 0;
            end
            if ~isscalar(event.NewData) || isnan(event.NewData)
                uialert(app.ZebraMainFig,'Input has to be a scalar number.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = event.PreviousData;
            end
            if (event.NewData > 1/app.sp/2) && (event.NewData ~= inf)
                uialert(app.ZebraMainFig,'You cannot exceed Nyquist frequency.','Invalid input')
                app.BpTable.Data(event.Indices(1),event.Indices(2)) = 1/app.sp/2-1;
            end
            
            BPsynch(app,0) % call the synch function in "zero mode". See the function description for details.
        end

        % Value changed function: NumberofBPsEditField
        function NumberofBPsEditField_ValueChanged(app, event)
            nBP = app.NumberofBPsEditField.Value;
            if size(app.BpTable.Data,1) > nBP
                app.BpTable.Data = app.BpTable.Data(1:nBP, :);
            else
                newTab = [num2cell((1:nBP)'), num2cell(logical(ones(nBP,1))), num2cell(zeros(nBP,1)), num2cell(ones(nBP,1)*(app.acqF/2-1))];
                newTab(1:size(app.BpTable.Data),:) = app.BpTable.Data;
                app.BpTable.Data = newTab;
            end
            BPsynch(app,inf)
        end

        % Value changed function: BPplot_ck
        function BPplot_ck_ValueChanged(app, event)
            
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % Update BP_4_plot
            app.BP_4_plot(selBP) = app.BPplot_ck.Value;
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Button pushed function: BPplotColor_btn
        function BPplotColor_btn_ButtonPushed(app, event)
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % select a color
            c = uisetcolor; % color is returned as an r-g-b triplet with 0-1 range
            figure(app.ZebraMainFig)
            
            % Update BP_color
            app.BP_color(selBP,:) = c;
            
            % Update text color
            app.BPplotColor_btn.FontColor = c;
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Value changed function: BPplot_drop
        function BPplot_drop_ValueChanged(app, event)
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            
            % update the selection button
            app.BPplot_ck.Value = app.BP_4_plot(selBP);
            
            % show the color on the color button
            app.BPplotColor_btn.FontColor = app.BP_color(selBP,:);
        end

        % Callback function
        function PlotScaleTab_CellEdit(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;
            app.autoscaleYck.Value = 0;
            plotWideWindow(app)
        end

        % Button pushed function: DefaultColorsButton
        function DefaultColors_ButtonPushed(app, event)
            % reset BP colors to default colors
            nBP = size(app.BP_color,1);
            app.BP_color = app.BP_color_default(1:nBP,:);
            
            % Find the selected BP
            selBP = find(strcmp(app.BPplot_drop.Value,app.BPplot_drop.Items));
            % update ColorButton color
            app.BPplotColor_btn.FontColor = app.BP_color(selBP,:);
            
            % Update BP and Power plot
            if app.fileInNum
                plotMultiBand(app)
                if app.BP.Value
                    plotSpectrogram(app)
                end
            end
        end

        % Button pushed function: BPplotColorShow_btn
        function BPplotColorShow_btn_ButtonPushed(app, event)
            % Visualize BP colors and selection state in a separate window
            
            % Open a new figure in a specified position relative to Zebra UI
            pos = app.ZebraMainFig.Position;
            f = figure('Name','Colors','Position',[pos(1),pos(2),round(pos(3)/5),round(pos(4)*0.5)]);
            f.MenuBar = 'none';
            f.ToolBar = 'none';
            
            % Store selections and BPs into a cell matrix
            col1 = num2cell(reshape(logical(app.BP_4_plot),[],1));
            col23  = cellstr(string(app.frequencyBand));
            mydata = [col1, col23];
            
            % prepare column specs
            colNames = {'Selected','Lower bound','Upper Bound'};
            colFmts = {'logical','char','char'};
            colEdit = [true, false, false];
            
            T = uitable('Units','normalized','Position',[0 0 1 1],...
                'Data', mydata,...
                'ColumnName', colNames,...
                'ColumnFormat', colFmts,...
                'ColumnEditable', colEdit,...
                'ColumnWidth', {60,75,75},...
                'BackgroundColor',app.BP_color,... % row colors corresponding to BP colors
                'ForegroundColor',[1 1 1],...
                'FontSize', 15,...
                'FontWeight', 'bold'); % white text
            
            % Add a callback so that BP_4_plot can be edited from the table.
            T.CellEditCallback = {@app.BP_4_plot_updateFcn};
        end

        % Callback function
        function autoscaleYck_ValueChanged(app, event)
            %  2019/05/29 Gab. Autoscale channels so that they fit properly in the same multiple
            % channel plot.
            % This function scales all the channels to the currently selected channel and store
            % the scale coefficients in the table named PlotScaleTab.
            if app.autoscaleYck.Value
                calcScaleFactors(app)
                
                % if Multi channel plot is selected, plot the LFP
                if app.plotMultiCh.Value
                    plotWideWindow(app)
                end
            end
        end

        % Value changed function: trialSlider_2
        function trialSlider_2_Callback(app, event)
            if (app.fileInNum >= 1 && app.nTrials > 1)
                app.currentTrial = app.trialSlider_2.Value;
                % Update the text
                set(app.trialText_2,'Text',['Trial ' num2str(app.currentTrial)]);
                % Update the other slider too
                set(app.trialSlider_1,'Value',app.currentTrial);
                set(app.trialText_1,'Text',['Trial ' num2str(app.currentTrial)]);
                
                % Replot
                plotWideWindow(app)
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: chSlider_2
        function chSlider_2_Callback(app, event)
            app.currentCh = app.chSlider_2.Value;
            % update the text
            set(app.chText_2,'Text',['Ch ' num2str(app.currentCh)])
            % update the other slider too
            set(app.chSlider_1,'Value',app.currentCh)
            set(app.chText_1,'Text',['Ch ' num2str(app.currentCh)])
            
            % Replot
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
            if get(app.powerSpectraCk,'Value')
                startSpectraPlot(app)
            end
            
        end

        % Value changed function: autoCLimSpg_ck
        function autoCLimSpg_ckValueChanged(app, event)
            if (app.autoCLimSpg_ck.Value) && (app.fileInNum > 0)
                plotSpectrogram(app)
            end
        end

        % Value changed function: SweepTimeEdit
        function SweepTimeEditValueChanged(app, event)
            setSP(app,app.dtaLen/app.SweepTimeEdit.Value)
        end

        % Value changed function: rightTime
        function rightTimeValueChanged(app, event)
            updateEvDetectAxesLimits(app)
        end

        % Value changed function: leftTime
        function leftTimeValueChanged(app, event)
            updateEvDetectAxesLimits(app)
        end

        % Value changed function: autoThr, baselineThr, eventThr
        function eventThrValueChanged(app, event)
            if app.autoThr.Value
                thrLow = app.evDet.autoblThr;
                thrHigh = app.evDet.autoevThr;
            else
                thrLow = app.baselineThr.Value;
                thrHigh = app.eventThr.Value;
            end
            
            PlotHist(app,thrHigh,thrLow);
            
            if ~isempty(app.evDet.highThrLine)
                if isvalid(app.evDet.highThrLine)
                    app.evDet.highThrLine.YData = [thrHigh thrHigh];
                    app.evDet.lowThrLine.YData = [thrLow thrLow];
                end
            end
        end

        % Value changed function: peakAnalyzerCheckBox
        function peakAnalyzerCheckBoxValueChanged(app, event)
            value = app.peakAnalyzerCheckBox.Value;
            
            if value
                app.peakAnalyzer_handle = peakAnalyzer(app);
            else
                if ~isempty(app.peakAnalyzer_handle)
                    if isvalid(app.peakAnalyzer_handle)
                        app.peakAnalyzer_handle.delete
                    end
                end
            end
            
        end

        % Button pushed function: WindowonimagingButton
        function WindowonimagingButtonPushed(app, event)
            SynchCh = app.ImgSynchChEditField.Value;
            LogicFrames = squeeze(app.LFP(SynchCh,1,:)>2);
            %             Get all the indexes of the first point of each frame.
            StartIdx = find(diff(LogicFrames)==1)+1;
            %             Get the mean period of a frame in number of intervals between
            %             points.
            MeanPeriodIdx = round(mean(diff(StartIdx)));
            %             The beginning of the imaging acquisition is simple: the first
            %             StartIdx.
            app.tminTxt.Value = app.x(StartIdx(1));
            app.tmaxTxt.Value = app.x(StartIdx(end)+MeanPeriodIdx);
            app.tmin = app.tminTxt.Value;
            app.tmax = app.tmaxTxt.Value;
            plotWideWindow(app)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Value changed function: maxFreq, maxPw, minFreq, minPw
        function minPwValueChanged(app, event)
            evDet_plotSpectra(app)
        end

        % Value changed function: detectionBP_dropDown
        function detectionBP_dropDownValueChanged(app, event)
            app.TabGroup3.SelectedTab = app.FurthercomputationTab;
            app.powerUS_dropDown.Value = app.detectionBP_dropDown.Value;
            app.powerUS_dropDown.BackgroundColor = [1 1 0]; % mark it yellow
        end

        % Value changed function: powerUS_dropDown
        function powerUS_dropDownValueChanged(app, event)
            app.powerUS_dropDown.BackgroundColor = [0.94 0.94 0.94]; % remove yellow marker
        end

        % Value changed function: SelectprobeDropDown
        function SelectprobeDropDownValueChanged(app, event)
            SelProbe = app.SelectprobeDropDown.Value;
            if strcmp(SelProbe,'All channels')
                app.orChI = [];
                app.probeImg = [];
            else
                app.orChI = load([app.zebraPath 'NNprobes' filesep SelProbe filesep 'orderedCh.mat']);
                app.probeImg = imread([app.zebraPath 'NNprobes' filesep SelProbe filesep 'image.jpg']);
            end
        end

        % Button pushed function: ShowprobeButton
        function ShowprobeButtonPushed(app, event)
            figure
            imshow(app.probeImg)
        end

        % Value changed function: sf
        function sfValueChanged(app, event)
            app.acqF = app.sf.Value *1000; % Hz
            app.sp = 1/app.acqF;
            app.spEdit.Value = app.sp;
            app.timeOffset_i = round(app.timeOffset/app.sp);
        end

        % Value changed function: SubtractMean
        function SubtractMeanValueChanged(app, event)
            %             % Teresa, provvisorio per togliere la media dai canali NN
            %             value = app.SubtractMean.Value;
            %             if value
            %                 m = mean(squeeze(app.LFP(1:16,:,:)),1);
            %                 m = reshape(m,1,1,[]);
            %                 app.LFP(1:16,:,:) = app.LFP(1:16,:,:) - m;
            %
            %                 computeBPandNotch(app)
            %                 computePowerInTime(app)  % temporal profile of power
            %                 plotMultiBand(app)
            %                 computeSpectrogram(app)
            %                 plotSpectrogram(app)
            %
            %                 disp('Computation of band pass on data - mean completed.')
            %             end
        end

        % Button pushed function: ChscalefactorsButton
        function ChscalefactorsButtonPushed(app, event)
            f =figure('Name','Select channel scale factor','Position',[250,200,350,450],'Numbertitle','off');
            f.MenuBar = 'none';
            f.ToolBar = 'none';
            % colNames = {'ch','show','factor'};
            % colFmts = {'numeric','logical','numeric'};
            % colEdit = [false, true, true];
            colNames = {'Channel','Show','Factor'};
            colFmts = {'numeric','logical','numeric'};
            colEdit = [false,true, true];
            nch = app.nCh;
            mydata = [num2cell((1:nch)'),num2cell(app.multiChPlot.show),num2cell(app.multiChPlot.scales)];
            T = uitable('Units','normalized','Position',[0.1 0.15 0.8 0.8],...
                'Data', mydata,...
                'RowName','',...
                'ColumnName', colNames,...
                'ColumnFormat', colFmts,...
                'ColumnEditable', colEdit,...
                'ColumnWidth', {75,50,135},...
                'ForegroundColor',[0 0 0],...
                'FontSize', 15,...
                'FontWeight', 'bold');
            
            apply_btn = uicontrol(f,'style','pushbutton','units','normalized','string','Apply');
            apply_btn.Position = [0.1, 0.04, 0.2, 0.07];
            apply_btn.UserData = {app,T,f};
            apply_btn.Callback = {@apply_btn_callback};
            %             T.CellEditCallback = {@app.BP_4_plot_updateFcn};
            
            auto_btn = uicontrol(f,'style','pushbutton','units','normalized','string','Auto');
            auto_btn.Position = [0.4, 0.04, 0.2, 0.07];
            auto_btn.UserData = {app,T};
            auto_btn.Callback = {@auto_btn_callback};
            
            reset_btn = uicontrol(f,'style','pushbutton','units','normalized','string','Reset');
            reset_btn.Position = [0.7, 0.04, 0.2, 0.07];
            reset_btn.UserData = T;
            reset_btn.Callback = {@reset_btn_callback};
            
            function reset_btn_callback(btn,ev)
                myobj = btn.UserData;
                myobj.Data(:,2) = num2cell(true(size(myobj.Data,1),1));
                myobj.Data(:,3) = num2cell(ones(size(myobj.Data,1),1));
            end
            function auto_btn_callback(btn,ev)
                %             Gab 2019/06/02
                myapp = btn.UserData{1};
                mytab = btn.UserData{2};
                selCh = myapp.app.currentCh;
                ranges = max(myapp.workLFP,[],[2,3]) - min(myapp.workLFP,[],[2,3]); % the range of the LFP for each channel
                scales = ranges ./ ranges(selCh);
                mytab.Data(:,2) = num2cell(1./scales);
            end
            function apply_btn_callback(btn,ev)
                myapp = btn.UserData{1};
                mytab = btn.UserData{2};
                myfig = btn.UserData{3};
                myapp.multiChPlot.scales = [mytab.Data{:,3}]';
                myapp.multiChPlot.show = [mytab.Data{:,2}]';
                myapp.plotWideWindow;
                close(myfig)
            end
            
        end

        % Button pushed function: savePower_button
        function savePower_buttonButtonPushed(app, event)
            selBP = app.saveBP_EditField.Value;
            
            assert(selBP<=app.NumberofBPsEditField.Value,['BP' num2str(selBP) ' does not exist'])
            
            if app.BPcomputed(selBP)
                
                % selected channel(s)
                if app.save_allchennelsCheckBox.Value
                    selCh = 1:app.nCh;
                else
                    selCh = app.chSlider_1.Value;
                end
                
                selT = app.BPpower(selBP).time>=app.tminTxt.Value & app.BPpower(selBP).time<=app.tmaxTxt.Value;
                t = app.BPpower(selBP).time(selT)';
                
                % reshape the traces to obtain columns in this order:
                % ch1,               ch2,               ... chM
                % tr1, tr2, ... trN, tr1, tr2, ... trN, ... tr1, tr2, ... trN
                tmp(1:length(selCh),1:app.nTrials,:) = app.BPpower(selBP).power(selCh,:,selT);
                if app.SPGlogCk.Value
                    tmp = 10*log10(tmp);
                end
                [nCh,nTr,nT] = size(tmp);
                tmpReshaped = reshape( permute(tmp,[3,2,1]), nT,[]);
                
                % add times as the first column and turn everything into a
                % cell
                tmpOut = cat(2,t,tmpReshaped);

                writematrix(tmpOut,[app.dtaComplete(1:end-4) '_BP' num2str(selBP,'%02.f') '_power.dat'],"Delimiter",'tab')
                disp('BP power saved')
            else
                disp(['Compute BP' num2str(selBP) ' first!'])
            end
        end

        % Button pushed function: ResetButton_BP
        function ResetButton_BPPushed(app, event)
            app.ymaxTxt_BP.Value = inf;
            app.yminTxt_BP.Value = -inf;
            axis(app.wide_plot_BP, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_BP.Value, app.ymaxTxt_BP.Value]);
        end

        % Value changed function: ymaxTxt_BP, yminTxt_BP
        function ymaxTxt_BPValueChanged(app, event)
            axis(app.wide_plot_BP, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_BP.Value, app.ymaxTxt_BP.Value]);
        end

        % Button pushed function: ResetButton_spg
        function ResetButton_spgPushed(app, event)
            app.ymaxTxt_spg.Value = inf;
            app.yminTxt_spg.Value = -inf;
            axis(app.spectraWide, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_spg.Value, app.ymaxTxt_spg.Value]);
        end

        % Value changed function: ymaxTxt_spg, yminTxt_spg
        function ymaxTxt_spgValueChanged(app, event)
            axis(app.spectraWide, [app.tminTxt.Value, app.tmaxTxt.Value, app.yminTxt_spg.Value, app.ymaxTxt_spg.Value]);
        end

        % Value changed function: trTimeOffset_txt
        function trTimeOffset_txtValueChanged(app, event)
            app.timeOffset = app.trTimeOffset_txt.Value;
            app.timeOffset_i = round(app.timeOffset/app.sp);
        end

        % Value changed function: ExportDStabBtn
        function ExportDStabBtnValueChanged(app, event)
            tmp = app.DSTable.Data;
            splitDir = regexp(app.dir_in,app.PathSeparator,'split');
            outName = [app.dir_in, app.PathSeparator,'DStable_Ch' num2str( app.currentCh) '_' splitDir{end-1} '_' app.file_in '.mat'];
            save(outName,'tmp');
        end

        % Value changed function: CopyDStabBtn
        function CopyDStabBtnValueChanged(app, event)

              % To copy the data from the table to the clipboard
            tablesize = size(app.evDet.DSlist);
            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    if j == tablesize(2)
                        str = sprintf('%s%f',str,app.evDet.DSlist{i,j});
                    else
                        str = sprintf('%s%f\t',str,app.evDet.DSlist{i,j});
                    end
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZebraMainFig = uifigure('Visible', 'off');
            app.ZebraMainFig.Name = 'ZebraExplore';
            app.TabGroup = uitabgroup(app.ZebraMainFig);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.plotSlate = uipanel(app.PlotTab);
            app.plotSlate.Title = 'Plot Window';
            app.tminTxt = uieditfield(app.plotSlate, 'numeric');
            app.tminTxt.ValueChangedFcn = createCallbackFcn(app, @tminTxt_Callback, true);
            app.tminTxt.Value = 5;
            app.ymaxTxt_lfp = uieditfield(app.plotSlate, 'numeric');
            app.ymaxTxt_lfp.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_lfp_Callback, true);
            app.ymaxTxt_lfp.Value = Inf;
            app.yminTxt_lfp = uieditfield(app.plotSlate, 'numeric');
            app.yminTxt_lfp.ValueChangedFcn = createCallbackFcn(app, @yminTxt_lfp_Callback, true);
            app.yminTxt_lfp.Value = -Inf;
            app.ResetButton_lfp = uibutton(app.plotSlate, 'push');
            app.ResetButton_lfp.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_lfp_Callback, true);
            app.ResetButton_lfp.Text = {'R'; 'e'; 's'; 'e'; 't'};
            app.scrollLeft = uibutton(app.plotSlate, 'push');
            app.scrollLeft.ButtonPushedFcn = createCallbackFcn(app, @scrollLeft_Callback, true);
            app.scrollLeft.Text = '< Scroll ';
            app.scrollRight = uibutton(app.plotSlate, 'push');
            app.scrollRight.ButtonPushedFcn = createCallbackFcn(app, @scrollRight_Callback, true);
            app.scrollRight.Text = 'Scroll > ';
            app.tResetBtn = uibutton(app.plotSlate, 'push');
            app.tResetBtn.ButtonPushedFcn = createCallbackFcn(app, @tResetBtn_Callback, true);
            app.tResetBtn.Text = 'Reset T';
            app.tmaxTxt = uieditfield(app.plotSlate, 'numeric');
            app.tmaxTxt.ValueChangedFcn = createCallbackFcn(app, @tmaxTxt_Callback, true);
            app.tmaxTxt.Value = 5;
            app.sendToClipboard = uibutton(app.plotSlate, 'push');
            app.sendToClipboard.ButtonPushedFcn = createCallbackFcn(app, @sendToClipboard_Callback, true);
            app.sendToClipboard.Tooltip = 'Copy the inset widows to the system clipboard';
            app.sendToClipboard.Text = 'Clipboard';
            app.ymaxTxt_BP = uieditfield(app.plotSlate, 'numeric');
            app.ymaxTxt_BP.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_BPValueChanged, true);
            app.ymaxTxt_BP.Value = Inf;
            app.yminTxt_BP = uieditfield(app.plotSlate, 'numeric');
            app.yminTxt_BP.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_BPValueChanged, true);
            app.yminTxt_BP.Value = -Inf;
            app.ResetButton_BP = uibutton(app.plotSlate, 'push');
            app.ResetButton_BP.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_BPPushed, true);
            app.ResetButton_BP.Text = {'R'; 'e'; 's'; 'e'; 't'};
            app.ymaxTxt_spg = uieditfield(app.plotSlate, 'numeric');
            app.ymaxTxt_spg.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_spgValueChanged, true);
            app.ymaxTxt_spg.Value = Inf;
            app.yminTxt_spg = uieditfield(app.plotSlate, 'numeric');
            app.yminTxt_spg.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_spgValueChanged, true);
            app.yminTxt_spg.Value = -Inf;
            app.ResetButton_spg = uibutton(app.plotSlate, 'push');
            app.ResetButton_spg.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_spgPushed, true);
            app.ResetButton_spg.Text = {'R'; 'e'; 's'; 'e'; 't'};
            app.wide_plot_BP = uiaxes(app.plotSlate);
            app.wide_plot_BP.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.wide_plot_BP.XTickLabel = '0';
            app.spectraWide = uiaxes(app.plotSlate);
            app.spectraWide.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.spectraWide.XTickLabel = '0';
            app.wide_plot_up = uiaxes(app.plotSlate);
            app.TabGroup2 = uitabgroup(app.PlotTab);
            app.InputDataTab = uitab(app.TabGroup2);
            app.InputDataTab.Title = 'Input Data';
            app.select_data = uibutton(app.InputDataTab, 'push');
            app.select_data.ButtonPushedFcn = createCallbackFcn(app, @select_data_Callback, true);
            app.select_data.Text = 'Open';
            app.excludeChFlag = uicheckbox(app.InputDataTab);
            app.excludeChFlag.Text = 'Exclude Ch:';
            app.internalSynchCk = uicheckbox(app.InputDataTab);
            app.internalSynchCk.Text = 'Synch on Ch:';
            app.uipanel15 = uibuttongroup(app.InputDataTab);
            app.mVck = uiradiobutton(app.uipanel15);
            app.mVck.Tooltip = 'Click here if data are expressed in mV';
            app.mVck.Text = 'mV';
            app.mVck.Value = true;
            app.microVck = uiradiobutton(app.uipanel15);
            app.microVck.Tooltip = 'Click here if data are expressed in micro V';
            app.microVck.Text = 'μV';
            app.autoclear = uicheckbox(app.InputDataTab);
            app.autoclear.Tooltip = 'Clear spreadsheet in Zebra Says when a new data is loaded in';
            app.autoclear.Text = 'Autoclear';
            app.autoclear.Value = true;
            app.openDir = uibutton(app.InputDataTab, 'push');
            app.openDir.ButtonPushedFcn = createCallbackFcn(app, @openDir_Callback, true);
            app.openDir.Text = 'Open Dir';
            app.excludeCh = uieditfield(app.InputDataTab, 'numeric');
            app.excludeCh.Value = 2;
            app.synchCh = uieditfield(app.InputDataTab, 'numeric');
            app.synchCh.Value = 2;
            app.TimeoffsetsLabel = uilabel(app.InputDataTab);
            app.TimeoffsetsLabel.Text = 'Time offset (s):';
            app.trTimeOffset_txt = uieditfield(app.InputDataTab, 'numeric');
            app.trTimeOffset_txt.ValueChangedFcn = createCallbackFcn(app, @trTimeOffset_txtValueChanged, true);
            app.SamplingsEditFieldLabel = uilabel(app.InputDataTab);
            app.SamplingsEditFieldLabel.Text = 'Sampling (s):';
            app.spEdit = uieditfield(app.InputDataTab, 'numeric');
            app.spEdit.ValueChangedFcn = createCallbackFcn(app, @spEdit_Callback, true);
            app.spEdit.Value = 0.0001;
            app.NChannelsLabel = uilabel(app.InputDataTab);
            app.NChannelsLabel.Text = 'N Channels:';
            app.chEdit = uieditfield(app.InputDataTab, 'numeric');
            app.chEdit.Value = 2;
            app.TrialsEditFieldLabel = uilabel(app.InputDataTab);
            app.TrialsEditFieldLabel.Text = 'Trials:';
            app.trialsEdit = uieditfield(app.InputDataTab, 'numeric');
            app.trialsEdit.Editable = 'off';
            app.trialsEdit.Value = 1;
            app.TrialtimesLabel = uilabel(app.InputDataTab);
            app.TrialtimesLabel.Text = 'Trial time (s):';
            app.SweepTimeEdit = uieditfield(app.InputDataTab, 'numeric');
            app.SweepTimeEdit.ValueChangedFcn = createCallbackFcn(app, @SweepTimeEditValueChanged, true);
            app.SweepTimeEdit.Enable = 'off';
            app.GainEditFieldLabel = uilabel(app.InputDataTab);
            app.GainEditFieldLabel.Text = 'Gain:';
            app.gain = uieditfield(app.InputDataTab, 'numeric');
            app.gain.ValueChangedFcn = createCallbackFcn(app, @gain_Callback, true);
            app.gain.Value = 1000;
            app.DataFormatLabel = uilabel(app.InputDataTab);
            app.DataFormatLabel.Text = 'Data Format:';
            app.dataFormat = uidropdown(app.InputDataTab);
            app.dataFormat.Items = {'National Instr', 'AxoP', 'TRC import', 'Neuronexus', 'Matlab', 'Meyer VEP', 'SEP', 'EDF format', 'EEG Laura', 'IMM MEA', 'NewCastle'};
            app.dataFormat.ValueChangedFcn = createCallbackFcn(app, @dataFormatPanel_SelectionChangeFcn, true);
            app.dataFormat.Value = 'National Instr';
            app.autofocus = uicheckbox(app.InputDataTab);
            app.autofocus.Tooltip = {'Keep the focus on ZebraExplore when opening a new file'};
            app.autofocus.Text = 'Autofocus';
            app.autofocus.Value = true;
            app.Standardize_CheckBox = uicheckbox(app.InputDataTab);
            app.Standardize_CheckBox.Tooltip = {'Standardize each channel by subtracting its mean and dividing it for its std'};
            app.Standardize_CheckBox.Text = 'Standardize';
            app.SamplingkHzLabel = uilabel(app.InputDataTab);
            app.SamplingkHzLabel.Text = 'Sampling (kHz):';
            app.sf = uieditfield(app.InputDataTab, 'numeric');
            app.sf.ValueChangedFcn = createCallbackFcn(app, @sfValueChanged, true);
            app.sf.Value = 10;
            app.SelectprobeDropDownLabel = uilabel(app.InputDataTab);
            app.SelectprobeDropDownLabel.Text = 'Select probe:';
            app.SelectprobeDropDown = uidropdown(app.InputDataTab);
            app.SelectprobeDropDown.Items = {'A1x16-3mm-25-177', 'Qtrode'};
            app.SelectprobeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectprobeDropDownValueChanged, true);
            app.SelectprobeDropDown.Enable = 'off';
            app.SelectprobeDropDown.Value = 'A1x16-3mm-25-177';
            app.ShowprobeButton = uibutton(app.InputDataTab, 'push');
            app.ShowprobeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowprobeButtonPushed, true);
            app.ShowprobeButton.Enable = 'off';
            app.ShowprobeButton.Text = 'Show probe';
            app.SubtractMean = uicheckbox(app.InputDataTab);
            app.SubtractMean.ValueChangedFcn = createCallbackFcn(app, @SubtractMeanValueChanged, true);
            app.SubtractMean.Tooltip = {'When opening the record, subtract the average of all probe channels'};
            app.SubtractMean.Text = 'Subtract the mean';
            app.AI1EditFieldLabel = uilabel(app.InputDataTab);
            app.AI1EditFieldLabel.Enable = 'off';
            app.AI1EditFieldLabel.Text = 'AI1';
            app.AI1_gainEditField = uieditfield(app.InputDataTab, 'numeric');
            app.AI1_gainEditField.Enable = 'off';
            app.AI1_gainEditField.Value = 500;
            app.AI2EditFieldLabel = uilabel(app.InputDataTab);
            app.AI2EditFieldLabel.Enable = 'off';
            app.AI2EditFieldLabel.Text = 'AI2';
            app.AI2_gainEditField = uieditfield(app.InputDataTab, 'numeric');
            app.AI2_gainEditField.Enable = 'off';
            app.AI2_gainEditField.Value = 500;
            app.NNexusgainLabel = uilabel(app.InputDataTab);
            app.NNexusgainLabel.Text = 'NNexus gain:';
            app.DisplayControlTab = uitab(app.TabGroup2);
            app.DisplayControlTab.Title = 'Display Control';
            app.SPGmode = uibuttongroup(app.DisplayControlTab);
            app.SPGmode.SelectionChangedFcn = createCallbackFcn(app, @SPGmode_SelectionChangeFcn, true);
            app.SPGmode.Title = 'Spectrogram mode';
            app.SPG = uiradiobutton(app.SPGmode);
            app.SPG.Tooltip = 'Display the spectrogram';
            app.SPG.Text = 'SPG';
            app.SPG.Value = true;
            app.gammaPower = uiradiobutton(app.SPGmode);
            app.gammaPower.Tooltip = 'Display the power in the envelope frequency band';
            app.gammaPower.Text = 'Envelope';
            app.SPGlogCk = uicheckbox(app.SPGmode);
            app.SPGlogCk.ValueChangedFcn = createCallbackFcn(app, @SPGlogCk_Callback, true);
            app.SPGlogCk.Tooltip = 'Use log scale for the linear plots';
            app.SPGlogCk.Text = 'Log';
            app.SPGlogCk.Value = true;
            app.EIbutton = uiradiobutton(app.SPGmode);
            app.EIbutton.Tooltip = 'Display the time course o the power spectra slope in the 20-50 Hz range';
            app.EIbutton.Text = 'E/I';
            app.BP = uiradiobutton(app.SPGmode);
            app.BP.Text = 'BP';
            app.displayLeakCk = uicheckbox(app.SPGmode);
            app.displayLeakCk.Text = 'Leak corrected';
            app.displayLeakCk.Value = true;
            app.ColormapLimitsPanel = uipanel(app.SPGmode);
            app.ColormapLimitsPanel.Title = 'Colormap Limits';
            app.upperDB = uieditfield(app.ColormapLimitsPanel, 'numeric');
            app.upperDB.ValueChangedFcn = createCallbackFcn(app, @upperDB_Callback, true);
            app.upperDB.Enable = 'off';
            app.upperDB.Tooltip = {'Upper limit for power in the spectrogram graph (dB)'};
            app.lowerDB = uieditfield(app.ColormapLimitsPanel, 'numeric');
            app.lowerDB.ValueChangedFcn = createCallbackFcn(app, @lowerDB_Callback, true);
            app.lowerDB.Enable = 'off';
            app.lowerDB.Tooltip = {'Lower limit for power in the spectrogram graph (dB)'};
            app.lowerDB.Value = -60;
            app.MaxLabel = uilabel(app.ColormapLimitsPanel);
            app.MaxLabel.Text = 'Max:';
            app.MinLabel = uilabel(app.ColormapLimitsPanel);
            app.MinLabel.Text = 'Min:';
            app.autoCLimSpg_ck = uicheckbox(app.ColormapLimitsPanel);
            app.autoCLimSpg_ck.ValueChangedFcn = createCallbackFcn(app, @autoCLimSpg_ckValueChanged, true);
            app.autoCLimSpg_ck.Text = 'Auto';
            app.computeEIrat = uicheckbox(app.SPGmode);
            app.computeEIrat.Text = 'compute E/I ratio index';
            app.computeEnvelope = uicheckbox(app.SPGmode);
            app.computeEnvelope.Text = 'compute Gamma  envelope';
            app.BPManagerPanel = uipanel(app.DisplayControlTab);
            app.BPManagerPanel.Title = 'BP Manager';
            app.BPplot_drop = uidropdown(app.BPManagerPanel);
            app.BPplot_drop.Items = {'BP 1'};
            app.BPplot_drop.ValueChangedFcn = createCallbackFcn(app, @BPplot_drop_ValueChanged, true);
            app.BPplot_drop.Tooltip = {'Select BP to be edited'};
            app.BPplot_drop.Value = 'BP 1';
            app.BPplot_ck = uicheckbox(app.BPManagerPanel);
            app.BPplot_ck.ValueChangedFcn = createCallbackFcn(app, @BPplot_ck_ValueChanged, true);
            app.BPplot_ck.Tooltip = {'Show band-passed LFP and power timecourse'};
            app.BPplot_ck.Text = 'Selected';
            app.BPplot_ck.Value = true;
            app.BPplotColor_btn = uibutton(app.BPManagerPanel, 'push');
            app.BPplotColor_btn.ButtonPushedFcn = createCallbackFcn(app, @BPplotColor_btn_ButtonPushed, true);
            app.BPplotColor_btn.Tooltip = {'Change BP color'};
            app.BPplotColor_btn.Text = 'Color';
            app.BPplotColorShow_btn = uibutton(app.BPManagerPanel, 'push');
            app.BPplotColorShow_btn.ButtonPushedFcn = createCallbackFcn(app, @BPplotColorShow_btn_ButtonPushed, true);
            app.BPplotColorShow_btn.Tooltip = {'Open a separate window recapitulating BP settings'};
            app.BPplotColorShow_btn.Text = 'Show BP colors';
            app.DefaultColorsButton = uibutton(app.BPManagerPanel, 'push');
            app.DefaultColorsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultColors_ButtonPushed, true);
            app.DefaultColorsButton.Tooltip = {'Reset BP colors to default ones'};
            app.DefaultColorsButton.Text = 'Default Colors';
            app.computeBP = uibutton(app.DisplayControlTab, 'push');
            app.computeBP.ButtonPushedFcn = createCallbackFcn(app, @computeBP_Callback, true);
            app.computeBP.Tooltip = 'Recompute and plot band pass data';
            app.computeBP.Text = 'Compute BP';
            app.recomputeSPG = uibutton(app.DisplayControlTab, 'push');
            app.recomputeSPG.ButtonPushedFcn = createCallbackFcn(app, @recomputeSPG_Callback, true);
            app.recomputeSPG.Tooltip = 'Compute the spectogram and the associated entities with the current parameters';
            app.recomputeSPG.Text = 'Compute SPG';
            app.BPsynch_led1 = uilamp(app.DisplayControlTab);
            app.BPsynch_led1.Tooltip = {'GREEN = BP computed'; 'RED = ComputeBP is required'; ''};
            app.Panel = uipanel(app.DisplayControlTab);
            app.plotMeanFlag = uicheckbox(app.Panel);
            app.plotMeanFlag.ValueChangedFcn = createCallbackFcn(app, @plotMeanFlag_Callback, true);
            app.plotMeanFlag.Text = 'Plot mean';
            app.plotTrCk = uicheckbox(app.Panel);
            app.plotTrCk.ValueChangedFcn = createCallbackFcn(app, @plotTrCk_Callback, true);
            app.plotTrCk.Text = 'Plot each trial';
            app.plotTrCk.Value = true;
            app.plotTemplateCk = uicheckbox(app.Panel);
            app.plotTemplateCk.Text = 'Plot template';
            app.Panel_2 = uipanel(app.DisplayControlTab);
            app.plotMultiCh = uicheckbox(app.Panel_2);
            app.plotMultiCh.ValueChangedFcn = createCallbackFcn(app, @plotMultiCh_Callback, true);
            app.plotMultiCh.Text = 'Multi ch plot';
            app.autoOffCk = uicheckbox(app.Panel_2);
            app.autoOffCk.ValueChangedFcn = createCallbackFcn(app, @autoOffCk_Callback, true);
            app.autoOffCk.Tooltip = 'Automatic offset when plotting multiple channels';
            app.autoOffCk.Text = 'Auto offset';
            app.ChscalefactorsButton = uibutton(app.Panel_2, 'push');
            app.ChscalefactorsButton.ButtonPushedFcn = createCallbackFcn(app, @ChscalefactorsButtonPushed, true);
            app.ChscalefactorsButton.Enable = 'off';
            app.ChscalefactorsButton.Text = {'Ch scale'; 'factors'};
            app.SaveTab = uitab(app.TabGroup2);
            app.SaveTab.Title = 'Save';
            app.uipanel5_3 = uipanel(app.SaveTab);
            app.uipanel5_3.Title = 'Save Current View';
            app.saveCurrentCh = uibutton(app.uipanel5_3, 'push');
            app.saveCurrentCh.ButtonPushedFcn = createCallbackFcn(app, @saveCurrentCh_Callback, true);
            app.saveCurrentCh.Tooltip = 'Save data in the current view ';
            app.saveCurrentCh.Text = 'Trace';
            app.saveSPG = uibutton(app.uipanel5_3, 'push');
            app.saveSPG.ButtonPushedFcn = createCallbackFcn(app, @saveSPG_Callback, true);
            app.saveSPG.Enable = 'off';
            app.saveSPG.Text = 'SPG';
            app.saveBP = uibutton(app.uipanel5_3, 'push');
            app.saveBP.ButtonPushedFcn = createCallbackFcn(app, @saveBP_Callback, true);
            app.saveBP.Tooltip = 'Save the BP filtered data ONLY within the display window. Sensitive to multi-channel flag.';
            app.saveBP.Text = 'BP filtered';
            app.sepochsButton = uibutton(app.uipanel5_3, 'push');
            app.sepochsButton.ButtonPushedFcn = createCallbackFcn(app, @sepochsButtonPushed, true);
            app.sepochsButton.Text = '30 s epochs';
            app.WindowonimagingButton = uibutton(app.uipanel5_3, 'push');
            app.WindowonimagingButton.ButtonPushedFcn = createCallbackFcn(app, @WindowonimagingButtonPushed, true);
            app.WindowonimagingButton.Text = 'Window on imaging';
            app.syncchEditFieldLabel = uilabel(app.uipanel5_3);
            app.syncchEditFieldLabel.Text = 'sync ch:';
            app.ImgSynchChEditField = uieditfield(app.uipanel5_3, 'numeric');
            app.ImgSynchChEditField.Value = 3;
            app.savePower_button = uibutton(app.uipanel5_3, 'push');
            app.savePower_button.ButtonPushedFcn = createCallbackFcn(app, @savePower_buttonButtonPushed, true);
            app.savePower_button.Tooltip = 'Save data in the current view ';
            app.savePower_button.Text = 'BP power in time';
            app.save_allchennelsCheckBox = uicheckbox(app.uipanel5_3);
            app.save_allchennelsCheckBox.Text = 'All chennels';
            app.BPEditFieldLabel = uilabel(app.uipanel5_3);
            app.BPEditFieldLabel.Text = 'BP';
            app.saveBP_EditField = uieditfield(app.uipanel5_3, 'numeric');
            app.saveBP_EditField.Value = 1;
            app.save_downsampleCheckBox = uicheckbox(app.uipanel5_3);
            app.save_downsampleCheckBox.Tooltip = {'Does not apply to BP power'};
            app.save_downsampleCheckBox.Text = 'Downsample';
            app.factorEditFieldLabel = uilabel(app.uipanel5_3);
            app.factorEditFieldLabel.Text = 'factor:';
            app.downsample_factorEditField = uieditfield(app.uipanel5_3, 'numeric');
            app.downsample_factorEditField.Value = 20;
            app.SelectchannelandtrialPanel = uipanel(app.PlotTab);
            app.SelectchannelandtrialPanel.Title = 'Select channel and trial';
            app.trialSlider_1 = uispinner(app.SelectchannelandtrialPanel);
            app.trialSlider_1.Limits = [1 Inf];
            app.trialSlider_1.RoundFractionalValues = 'on';
            app.trialSlider_1.ValueChangedFcn = createCallbackFcn(app, @trialSlider_1_Callback, true);
            app.trialSlider_1.Tooltip = {'Select the trial to visualize'};
            app.trialSlider_1.Value = 1;
            app.chSlider_1 = uispinner(app.SelectchannelandtrialPanel);
            app.chSlider_1.Limits = [1 Inf];
            app.chSlider_1.RoundFractionalValues = 'on';
            app.chSlider_1.ValueChangedFcn = createCallbackFcn(app, @chSlider_1_Callback, true);
            app.chSlider_1.Tooltip = {'Select the channel to visualize'};
            app.chSlider_1.Value = 1;
            app.chText_1 = uilabel(app.SelectchannelandtrialPanel);
            app.chText_1.Text = 'Ch 1';
            app.trialText_1 = uilabel(app.SelectchannelandtrialPanel);
            app.trialText_1.Text = 'Trial 1';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.dataProcessing = uipanel(app.SettingsTab);
            app.dataProcessing.Title = 'Data Processing';
            app.uipanel4 = uibuttongroup(app.dataProcessing);
            app.uipanel4.Title = 'Multitrial Analysis Options';
            app.TrialanalysisPanel = uipanel(app.uipanel4);
            app.TrialanalysisPanel.Title = 'Trial analysis';
            app.text16 = uilabel(app.TrialanalysisPanel);
            app.text16.Text = 'Trial analysis, baseline (s):';
            app.text17 = uilabel(app.TrialanalysisPanel);
            app.text17.Text = 'Trial analysis, response (s):';
            app.baselineFrom = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.baselineFrom.Limits = [0 Inf];
            app.baselineFrom.Value = 0.03;
            app.baselineTo = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.baselineTo.Limits = [0 Inf];
            app.baselineTo.Value = 0.08;
            app.respFrom = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.respFrom.Limits = [0 Inf];
            app.respFrom.Value = 0.09;
            app.respTo = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.respTo.Limits = [0 Inf];
            app.respTo.Value = 0.35;
            app.templateCk = uicheckbox(app.TrialanalysisPanel);
            app.templateCk.Tooltip = 'Template-based response analysis';
            app.templateCk.Text = 'Template analysis';
            app.templateCk.Value = true;
            app.smooth_ck = uicheckbox(app.TrialanalysisPanel);
            app.smooth_ck.Tooltip = 'filter the trace before template calculation';
            app.smooth_ck.Text = 'Smoothing';
            app.smooth_ck.Value = true;
            app.templExp_bt = uibutton(app.TrialanalysisPanel, 'push');
            app.templExp_bt.ButtonPushedFcn = createCallbackFcn(app, @templExp_bt_Callback, true);
            app.templExp_bt.Tooltip = 'Export the current template';
            app.templExp_bt.Text = 'Export';
            app.templRecalc_bt = uibutton(app.TrialanalysisPanel, 'push');
            app.templRecalc_bt.Tooltip = 'Recalculate template for the current trace with specified parameter';
            app.templRecalc_bt.Text = 'Recalc';
            app.text29 = uilabel(app.TrialanalysisPanel);
            app.text29.Text = 'Template window (s):';
            app.templateFrom = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.templateFrom.Value = 0.02;
            app.templateTo = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.templateTo.Value = 0.35;
            app.text32 = uilabel(app.TrialanalysisPanel);
            app.text32.Tooltip = 'Window for the computation of the power integral ';
            app.text32.Text = {'Window for power'; 'computation (s):'};
            app.templImp_bt = uibutton(app.TrialanalysisPanel, 'push');
            app.templImp_bt.ButtonPushedFcn = createCallbackFcn(app, @templImp_bt_Callback, true);
            app.templImp_bt.Tooltip = 'Import a template from a .mat file';
            app.templImp_bt.Text = 'Import';
            app.winPWleft = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.winPWleft.Value = 0.02;
            app.winPWright = uieditfield(app.TrialanalysisPanel, 'numeric');
            app.winPWright.Value = 0.8;
            app.BPtext = uilabel(app.TrialanalysisPanel);
            app.BPtext.Text = 'Select (BP)';
            app.sliderBPpower = uispinner(app.TrialanalysisPanel);
            app.sliderBPpower.Limits = [1 5];
            app.sliderBPpower.RoundFractionalValues = 'on';
            app.sliderBPpower.ValueChangedFcn = createCallbackFcn(app, @sliderBPpower_Callback, true);
            app.sliderBPpower.Tooltip = {'Select the Band for power computation'};
            app.sliderBPpower.Value = 1;
            app.TrialselectionPanel = uipanel(app.uipanel4);
            app.TrialselectionPanel.Title = 'Trial selection';
            app.SynchsignalmindurationmsLabel = uilabel(app.TrialselectionPanel);
            app.SynchsignalmindurationmsLabel.Text = 'Synch signal min duration  (ms)';
            app.triggerMinLength = uieditfield(app.TrialselectionPanel, 'numeric');
            app.triggerMinLength.Value = 1;
            app.SynchsignalmaxdurationmsLabel = uilabel(app.TrialselectionPanel);
            app.SynchsignalmaxdurationmsLabel.Text = 'Synch signal max duration (ms)';
            app.triggerMaxLength = uieditfield(app.TrialselectionPanel, 'numeric');
            app.triggerMaxLength.Value = 50;
            app.skip1trialCk = uicheckbox(app.TrialselectionPanel);
            app.skip1trialCk.Tooltip = 'Template-based response analysis';
            app.skip1trialCk.Text = 'Skip first trial';
            app.skip1trialCk.Value = true;
            app.doubleStimCk = uicheckbox(app.TrialselectionPanel);
            app.doubleStimCk.Tooltip = 'Click if the trial contain two stimuli, one at the beginning, the second at half period.';
            app.doubleStimCk.Text = 'Two stimuli per trial';
            app.MinimumTTLinterdistancesLabel = uilabel(app.TrialselectionPanel);
            app.MinimumTTLinterdistancesLabel.Text = 'Minimum TTL interdistance  (s)';
            app.trialMinLength = uieditfield(app.TrialselectionPanel, 'numeric');
            app.trialMinLength.Value = 1;
            app.DisplayPanel = uibuttongroup(app.dataProcessing);
            app.DisplayPanel.Title = 'Options for Spectral Analysis';
            app.text4 = uilabel(app.DisplayPanel);
            app.text4.Text = ' from (Hz)                    to  ';
            app.notchFilter = uicheckbox(app.DisplayPanel);
            app.notchFilter.ValueChangedFcn = createCallbackFcn(app, @notchFilter_Callback, true);
            app.notchFilter.Text = '   Notch filter';
            app.predefinedBW = uibuttongroup(app.DisplayPanel);
            app.predefinedBW.SelectionChangedFcn = createCallbackFcn(app, @predefinedBW_SelectionChangeFcn, true);
            app.predefinedBW.Title = 'Predefined bands';
            app.mouseCk = uiradiobutton(app.predefinedBW);
            app.mouseCk.Text = 'Mouse';
            app.mouseCk.Value = true;
            app.zebraCk = uiradiobutton(app.predefinedBW);
            app.zebraCk.Text = 'Zebrafish';
            app.EEGck = uiradiobutton(app.predefinedBW);
            app.EEGck.Text = 'EEG VEP';
            app.NewcastleBand = uiradiobutton(app.predefinedBW);
            app.NewcastleBand.Text = 'Newcastle';
            app.envelopeChoice = uibuttongroup(app.DisplayPanel);
            app.envelopeChoice.Title = 'Envelope Computation';
            app.text12 = uilabel(app.envelopeChoice);
            app.text12.Text = 'BP (Hz):';
            app.radiobutton6 = uiradiobutton(app.envelopeChoice);
            app.radiobutton6.Text = {'Thresholded'; 'variance'};
            app.radiobutton6.Value = true;
            app.plotEnvelopeCk = uicheckbox(app.envelopeChoice);
            app.plotEnvelopeCk.ValueChangedFcn = createCallbackFcn(app, @plotEnvelopeCk_Callback, true);
            app.plotEnvelopeCk.Text = {'Plot HP'; 'envelope'};
            app.filterBPpower = uicheckbox(app.envelopeChoice);
            app.filterBPpower.Text = {'Filter power '; 'plot'};
            app.envSPGfrom = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGfrom.Limits = [0 Inf];
            app.envSPGfrom.Value = 30;
            app.envSPGto = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGto.Limits = [0 Inf];
            app.envSPGto.Value = 80;
            app.FromLabel = uilabel(app.envelopeChoice);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.envelopeChoice);
            app.ToLabel.Text = 'To';
            app.uipanel17 = uipanel(app.DisplayPanel);
            app.uipanel17.Title = 'Spectrogram computation';
            app.uipanel18 = uipanel(app.uipanel17);
            app.uipanel18.Title = 'Time';
            app.txtspg = uilabel(app.uipanel18);
            app.txtspg.Text = 'SPG window (s):';
            app.text21 = uilabel(app.uipanel18);
            app.text21.Text = 'step (s):';
            app.SPGwindow = uieditfield(app.uipanel18, 'numeric');
            app.SPGwindow.Limits = [0 Inf];
            app.SPGwindow.Value = 0.25;
            app.SPGstep = uieditfield(app.uipanel18, 'numeric');
            app.SPGstep.Limits = [0 Inf];
            app.SPGstep.Value = 0.05;
            app.ChronuxAutoConfigCk = uicheckbox(app.uipanel18);
            app.ChronuxAutoConfigCk.Text = 'Automatic configuration';
            app.decimateFlag = uicheckbox(app.uipanel18);
            app.decimateFlag.Tooltip = 'To be used only for very long data set and slow PCs';
            app.decimateFlag.Text = 'Decimate data for spectogram';
            app.computeSpg_Ck = uicheckbox(app.uipanel17);
            app.computeSpg_Ck.Tooltip = 'To be used only for very long data set and slow PCs';
            app.computeSpg_Ck.Text = 'Compute SPG';
            app.computeSpg_Ck.Value = true;
            app.FrequencyPanel = uipanel(app.uipanel17);
            app.FrequencyPanel.Title = 'Frequency';
            app.text27 = uilabel(app.FrequencyPanel);
            app.text27.Text = 'Band of SPG from (Hz):';
            app.text28 = uilabel(app.FrequencyPanel);
            app.text28.Text = 'To:';
            app.text30 = uilabel(app.FrequencyPanel);
            app.text30.Tooltip = 'BP of the signal on which computing the SPG';
            app.text30.Text = 'LP for leakage correction (Hz):';
            app.SPGfromFreq = uieditfield(app.FrequencyPanel, 'numeric');
            app.SPGfromFreq.Limits = [0 Inf];
            app.SPGtoFreq = uieditfield(app.FrequencyPanel, 'numeric');
            app.SPGtoFreq.Limits = [0 Inf];
            app.SPGtoFreq.Value = 200;
            app.leakageLP = uieditfield(app.FrequencyPanel, 'numeric');
            app.leakageLP.Limits = [0 Inf];
            app.leakageLP.Value = 4;
            app.txt = uilabel(app.FrequencyPanel);
            app.txt.Text = 'SPG step (Hz):';
            app.SPGresolution = uieditfield(app.FrequencyPanel, 'numeric');
            app.SPGresolution.Limits = [0 Inf];
            app.SPGresolution.Value = 5;
            app.leakageCk = uicheckbox(app.FrequencyPanel);
            app.leakageCk.Text = 'Correct spectral leak';
            app.leakageCk.Value = true;
            app.ChronuxCk = uicheckbox(app.uipanel17);
            app.ChronuxCk.Text = 'Use Chronux';
            app.ChronuxCk.Value = true;
            app.text33 = uilabel(app.DisplayPanel);
            app.text33.Text = 'Butterworth filter order:';
            app.filtOrder = uieditfield(app.DisplayPanel, 'numeric');
            app.filtOrder.Value = 2;
            app.notchFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.notchFrom.ValueChangedFcn = createCallbackFcn(app, @notchFrom_Callback, true);
            app.notchFrom.Value = 49;
            app.notchTo = uieditfield(app.DisplayPanel, 'numeric');
            app.notchTo.ValueChangedFcn = createCallbackFcn(app, @notchTo_Callback, true);
            app.notchTo.Value = 51;
            app.BpTable = uitable(app.DisplayPanel);
            app.BpTable.ColumnName = {'BP'; 'Compute'; 'From (Hz)'; 'To (Hz)'};
            app.BpTable.RowName = {};
            app.BpTable.ColumnEditable = [false true true true];
            app.BpTable.CellEditCallback = createCallbackFcn(app, @BpTable_CellEdit, true);
            app.BpTable.CellSelectionCallback = createCallbackFcn(app, @BpTableCellSelection, true);
            app.NumberofBPsEditFieldLabel = uilabel(app.DisplayPanel);
            app.NumberofBPsEditFieldLabel.Text = 'Number of BPs';
            app.NumberofBPsEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.NumberofBPsEditField.Limits = [1 Inf];
            app.NumberofBPsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBPsEditField_ValueChanged, true);
            app.NumberofBPsEditField.Value = 5;
            app.DeleteBpBtn = uibutton(app.DisplayPanel, 'push');
            app.DeleteBpBtn.ButtonPushedFcn = createCallbackFcn(app, @DeleteBpBtnButtonPushed, true);
            app.DeleteBpBtn.Enable = 'off';
            app.DeleteBpBtn.Text = 'X';
            app.MoveDownBpBtn = uibutton(app.DisplayPanel, 'push');
            app.MoveDownBpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveDownBpBtn_ButtonPushed, true);
            app.MoveDownBpBtn.Enable = 'off';
            app.MoveDownBpBtn.Text = '↓';
            app.MoveUpBpBtn = uibutton(app.DisplayPanel, 'push');
            app.MoveUpBpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveUpBpBtn_ButtonPushed, true);
            app.MoveUpBpBtn.Enable = 'off';
            app.MoveUpBpBtn.Text = '↑';
            app.computeBP_2 = uibutton(app.DisplayPanel, 'push');
            app.computeBP_2.ButtonPushedFcn = createCallbackFcn(app, @computeBP_Callback, true);
            app.computeBP_2.Tooltip = 'Recompute and plot band pass data';
            app.computeBP_2.Text = 'Compute BP';
            app.BPsynch_led2 = uilamp(app.DisplayPanel);
            app.BPsynch_led2.Tooltip = {'GREEN = BP computed'; 'RED = ComputeBP is required'; ''};
            app.uipanel19 = uipanel(app.DisplayPanel);
            app.uipanel19.Title = 'Power';
            app.PowerwindowsEditFieldLabel = uilabel(app.uipanel19);
            app.PowerwindowsEditFieldLabel.Text = 'Power window (s):';
            app.powerWindow = uieditfield(app.uipanel19, 'numeric');
            app.powerWindow.Value = 0.25;
            app.stepsLabel = uilabel(app.uipanel19);
            app.stepsLabel.Text = 'step (s):';
            app.powerStep = uieditfield(app.uipanel19, 'numeric');
            app.powerStep.Value = 0.05;
            app.saveParams_btn = uibutton(app.SettingsTab, 'push');
            app.saveParams_btn.ButtonPushedFcn = createCallbackFcn(app, @saveParams_btn_Callback, true);
            app.saveParams_btn.Text = 'Save Parameters';
            app.loadParams_btn = uibutton(app.SettingsTab, 'push');
            app.loadParams_btn.ButtonPushedFcn = createCallbackFcn(app, @loadParams_btn_Callback, true);
            app.loadParams_btn.Text = 'Load Parameters';
            app.uipanel21 = uipanel(app.SettingsTab);
            app.uipanel21.Title = 'Concatenation Panel';
            app.analyzeWhileConc_ck = uicheckbox(app.uipanel21);
            app.analyzeWhileConc_ck.ValueChangedFcn = createCallbackFcn(app, @analyzeWhileConc_ck_Callback, true);
            app.analyzeWhileConc_ck.Text = 'Analyze while concatenating';
            app.concat_btn = uibutton(app.uipanel21, 'push');
            app.concat_btn.ButtonPushedFcn = createCallbackFcn(app, @concat_btn_Callback, true);
            app.concat_btn.Enable = 'off';
            app.concat_btn.Tooltip = 'Concatenate uploaded trials and run the analysis';
            app.concat_btn.Text = 'Concatenate now';
            app.clearConc_btn = uibutton(app.uipanel21, 'push');
            app.clearConc_btn.ButtonPushedFcn = createCallbackFcn(app, @clearConc_btn_Callback, true);
            app.clearConc_btn.Tooltip = 'Clear traces stored for concatenation';
            app.clearConc_btn.Text = 'Clear';
            app.concat_group = uibuttongroup(app.uipanel21);
            app.concat_group.Enable = 'off';
            app.concat_tr = uiradiobutton(app.concat_group);
            app.concat_tr.Text = 'Concatenate Trials';
            app.concat_tr.Value = true;
            app.concat_time = uiradiobutton(app.concat_group);
            app.concat_time.Text = 'Concatenate Time';
            app.concat_ck = uicheckbox(app.uipanel21);
            app.concat_ck.ValueChangedFcn = createCallbackFcn(app, @concat_ck_Callback, true);
            app.concat_ck.Tooltip = 'Concatenate trials opening different files';
            app.concat_ck.Text = 'Concatenation';
            app.uipanel20 = uipanel(app.SettingsTab);
            app.uipanel20.Title = 'Child Processes';
            app.powerSpectraCk = uicheckbox(app.uipanel20);
            app.powerSpectraCk.ValueChangedFcn = createCallbackFcn(app, @powerSpectraCk_Callback, true);
            app.powerSpectraCk.Tooltip = 'Compute power spectra of each loaded data';
            app.powerSpectraCk.Text = 'Power Spectra';
            app.autoSavePSck = uicheckbox(app.uipanel20);
            app.autoSavePSck.Tooltip = 'Auto save power spectra of each loaded data';
            app.autoSavePSck.Text = 'Auto Save';
            app.analyzePWdistributionCk = uicheckbox(app.uipanel20);
            app.analyzePWdistributionCk.ValueChangedFcn = createCallbackFcn(app, @analyzePWdistributionCk_Callback, true);
            app.analyzePWdistributionCk.Tooltip = 'Analyze the power distribution for each BP and the HP data';
            app.analyzePWdistributionCk.Text = 'Analyze Power Distribution';
            app.autoSavePWck = uicheckbox(app.uipanel20);
            app.autoSavePWck.Tooltip = 'Auto save distribution of spectral power';
            app.autoSavePWck.Text = 'Auto Save';
            app.TrRej_ck = uicheckbox(app.uipanel20);
            app.TrRej_ck.ValueChangedFcn = createCallbackFcn(app, @TrRej_ck_Callback, true);
            app.TrRej_ck.Text = 'Trial Rejection';
            app.multiChAnalyzer = uicheckbox(app.uipanel20);
            app.multiChAnalyzer.ValueChangedFcn = createCallbackFcn(app, @multiChAnalyzer_Callback, true);
            app.multiChAnalyzer.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.multiChAnalyzer.Text = 'Cross correlation and cross spectra';
            app.singleUnitExtraction_ck = uicheckbox(app.uipanel20);
            app.singleUnitExtraction_ck.ValueChangedFcn = createCallbackFcn(app, @singleUnitExtraction_ck_Callback, true);
            app.singleUnitExtraction_ck.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.singleUnitExtraction_ck.Text = 'Single unit extraction';
            app.hit_ck = uicheckbox(app.uipanel20);
            app.hit_ck.ValueChangedFcn = createCallbackFcn(app, @hit_ck_Callback, true);
            app.hit_ck.Text = 'Hit %';
            app.checkbox_sleepscoring = uicheckbox(app.uipanel20);
            app.checkbox_sleepscoring.ValueChangedFcn = createCallbackFcn(app, @checkbox_sleepscoring_Callback, true);
            app.checkbox_sleepscoring.Text = 'Sleep Scoring';
            app.peakAnalyzerCheckBox = uicheckbox(app.uipanel20);
            app.peakAnalyzerCheckBox.ValueChangedFcn = createCallbackFcn(app, @peakAnalyzerCheckBoxValueChanged, true);
            app.peakAnalyzerCheckBox.Text = 'peakAnalyzer';
            app.ZebraSaysTab = uitab(app.TabGroup);
            app.ZebraSaysTab.Title = 'ZebraSays';
            app.outable = uitable(app.ZebraSaysTab);
            app.outable.ColumnName = {'Dir'; 'File Name'; 'Time'; 'Ch'; 'Trial'; 'LP RMS'; 'BP1 RMS'; 'BP2 RMS'; 'BP3 RMS'; 'HP RMS'; 'Notes'};
            app.outable.RowName = {};
            app.outable.ColumnEditable = [false false false false false false false false false false true];
            app.outable.CellSelectionCallback = createCallbackFcn(app, @outableCellSelection, true);
            app.MetricsoftheentiretraceLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheentiretraceLabel.Text = 'Metrics of the entire trace';
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel.Text = 'Metrics of the evoked responses (single trials and averages)';
            app.MetricsofasynchronouseventsburstsandseizuresLabel = uilabel(app.ZebraSaysTab);
            app.MetricsofasynchronouseventsburstsandseizuresLabel.Text = 'Metrics of asynchronous events (bursts and seizures)';
            app.outEvochedResponses = uitable(app.ZebraSaysTab);
            app.outEvochedResponses.ColumnName = {'File Name'; 'Ch'; 'Trial'; 'Rep'; 'Amp'; 'T Peak'; 'Base PW BP2'; 'Resp PW BP2'; 'Resp/Base'; 'Temp PW BP2'; 'Comment'};
            app.outEvochedResponses.RowName = {};
            app.outEvochedResponses.CellSelectionCallback = createCallbackFcn(app, @outEvochedResponsesCellSelection, true);
            app.outEventsSummary = uitable(app.ZebraSaysTab);
            app.outEventsSummary.ColumnName = {'Dir'; 'File Name'; 'N'; 'Duration'; 'Time'; 'Base Beta'; 'Base RMS'; 'Ratio Beta'; 'Base Gamma'; 'Gamma RMS'; 'Ratio Gamma'; 'Ratio B/G'; 'Base HP'; 'HP RMS'; 'Ratio HP'; 'Notes'};
            app.outEventsSummary.RowName = {};
            app.outEventsSummary.ColumnEditable = [false false false false false false false false false false false false false false false true];
            app.CopytableButton = uibutton(app.ZebraSaysTab, 'push');
            app.CopytableButton.ButtonPushedFcn = createCallbackFcn(app, @CopytableButtonPushed, true);
            app.CopytableButton.Text = 'Copy table';
            app.CopyOutableSelection = uibutton(app.ZebraSaysTab, 'push');
            app.CopyOutableSelection.ButtonPushedFcn = createCallbackFcn(app, @CopyOutableSelectionButtonPushed, true);
            app.CopyOutableSelection.Enable = 'off';
            app.CopyOutableSelection.Text = 'Copy selection';
            app.DeleteSelRows = uibutton(app.ZebraSaysTab, 'push');
            app.DeleteSelRows.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelRowsButtonPushed, true);
            app.DeleteSelRows.Enable = 'off';
            app.DeleteSelRows.Text = 'Delete selected rows';
            app.CopyEvochedSelection = uibutton(app.ZebraSaysTab, 'push');
            app.CopyEvochedSelection.ButtonPushedFcn = createCallbackFcn(app, @CopyEvochedSelectionButtonPushed, true);
            app.CopyEvochedSelection.Enable = 'off';
            app.CopyEvochedSelection.Text = 'Copy selection';
            app.EventDetectionTab = uitab(app.TabGroup);
            app.EventDetectionTab.Title = 'EventDetection';
            app.uipanel2_3 = uipanel(app.EventDetectionTab);
            app.uipanel2_3.Title = 'Parameters';
            app.AnalysisresultsPanel = uipanel(app.uipanel2_3);
            app.AnalysisresultsPanel.Title = 'Analysis results';
            app.gauss1param = uilabel(app.AnalysisresultsPanel);
            app.gauss1param.Text = 'Gaussian 1: ';
            app.gauss2param = uilabel(app.AnalysisresultsPanel);
            app.gauss2param.Text = 'Gaussian 2:';
            app.text1 = uilabel(app.AnalysisresultsPanel);
            app.text1.Text = 'Baseline threshold:';
            app.text2_2 = uilabel(app.AnalysisresultsPanel);
            app.text2_2.Text = 'Event threshold:';
            app.baselineThr = uieditfield(app.AnalysisresultsPanel, 'numeric');
            app.baselineThr.ValueChangedFcn = createCallbackFcn(app, @eventThrValueChanged, true);
            app.baselineThr.Value = -3;
            app.eventThr = uieditfield(app.AnalysisresultsPanel, 'numeric');
            app.eventThr.ValueChangedFcn = createCallbackFcn(app, @eventThrValueChanged, true);
            app.eventThr.Value = -3;
            app.autoThr = uicheckbox(app.AnalysisresultsPanel);
            app.autoThr.ValueChangedFcn = createCallbackFcn(app, @eventThrValueChanged, true);
            app.autoThr.Text = 'Autosetting state threshold';
            app.autoThr.Value = true;
            app.AnalysissettingsPanel = uipanel(app.uipanel2_3);
            app.AnalysissettingsPanel.Title = 'Analysis settings';
            app.SelectedBPLabel = uilabel(app.AnalysissettingsPanel);
            app.SelectedBPLabel.Text = 'Selected BP:';
            app.detectionBP_dropDown = uidropdown(app.AnalysissettingsPanel);
            app.detectionBP_dropDown.ValueChangedFcn = createCallbackFcn(app, @detectionBP_dropDownValueChanged, true);
            app.text8_2 = uilabel(app.AnalysissettingsPanel);
            app.text8_2.Tooltip = 'Limits in log scale of the power histogram and bin number. Set to the lower limits to 0 for autoscaling.';
            app.text8_2.Text = {'Histogram'; '(From, To, Bins):'};
            app.fromH = uieditfield(app.AnalysissettingsPanel, 'numeric');
            app.fromH.Value = -5;
            app.toH = uieditfield(app.AnalysissettingsPanel, 'numeric');
            app.binNumber = uieditfield(app.AnalysissettingsPanel, 'numeric');
            app.binNumber.Value = 32;
            app.uipanel3_2 = uibuttongroup(app.AnalysissettingsPanel);
            app.powerLogCk = uiradiobutton(app.uipanel3_2);
            app.powerLogCk.Text = 'Log of power';
            app.powerLogCk.Value = true;
            app.radiobutton2 = uiradiobutton(app.uipanel3_2);
            app.radiobutton2.Text = 'Power';
            app.analyzeData = uibutton(app.AnalysissettingsPanel, 'push');
            app.analyzeData.ButtonPushedFcn = createCallbackFcn(app, @analyzeData_Callback, true);
            app.analyzeData.Text = 'Analyze';
            app.trialSlider_2 = uispinner(app.AnalysissettingsPanel);
            app.trialSlider_2.Limits = [1 Inf];
            app.trialSlider_2.RoundFractionalValues = 'on';
            app.trialSlider_2.ValueChangedFcn = createCallbackFcn(app, @trialSlider_2_Callback, true);
            app.trialSlider_2.Enable = 'off';
            app.trialSlider_2.Tooltip = {'Select the trial to visualize'};
            app.trialSlider_2.Value = 1;
            app.chSlider_2 = uispinner(app.AnalysissettingsPanel);
            app.chSlider_2.Limits = [1 Inf];
            app.chSlider_2.RoundFractionalValues = 'on';
            app.chSlider_2.ValueChangedFcn = createCallbackFcn(app, @chSlider_2_Callback, true);
            app.chSlider_2.Tooltip = {'Select the channel to visualize'};
            app.chSlider_2.Value = 1;
            app.chText_2 = uilabel(app.AnalysissettingsPanel);
            app.chText_2.Text = 'Ch 1';
            app.trialText_2 = uilabel(app.AnalysissettingsPanel);
            app.trialText_2.Enable = 'off';
            app.trialText_2.Text = 'Trial 1';
            app.ComputeUSandDSPanel_2 = uipanel(app.uipanel2_3);
            app.ComputeUSandDSPanel_2.Title = 'Compute US and DS';
            app.TabGroup3 = uitabgroup(app.ComputeUSandDSPanel_2);
            app.StatesmergingTab = uitab(app.TabGroup3);
            app.StatesmergingTab.Title = 'States merging';
            app.conMethod = uibuttongroup(app.StatesmergingTab);
            app.conMethod.Title = 'Method of concatenation';
            app.noConcCk = uiradiobutton(app.conMethod);
            app.noConcCk.Text = 'No concatenation';
            app.joinCk = uiradiobutton(app.conMethod);
            app.joinCk.Text = 'Join';
            app.joinOffsetChk = uiradiobutton(app.conMethod);
            app.joinOffsetChk.Text = 'Join with offset';
            app.joinOffsetChk.Value = true;
            app.text3_2 = uilabel(app.StatesmergingTab);
            app.text3_2.Tooltip = 'Shortest admissible duration of an upstate';
            app.text3_2.Text = 'Minimum state duration (s):';
            app.text4_3 = uilabel(app.StatesmergingTab);
            app.text4_3.Tooltip = 'Upstate definitions are bridged across interuptons shorter than this interval';
            app.text4_3.Text = 'Shortest state interruption:';
            app.minDuration = uieditfield(app.StatesmergingTab, 'numeric');
            app.minDuration.Value = 0.11;
            app.maxInterruption = uieditfield(app.StatesmergingTab, 'numeric');
            app.maxInterruption.Value = 0.11;
            app.erodeCk = uicheckbox(app.StatesmergingTab);
            app.erodeCk.Text = 'Check state edges';
            app.removeCk = uicheckbox(app.StatesmergingTab);
            app.removeCk.Text = 'Remove state interruptions';
            app.removeCk.Value = true;
            app.removePosUSCheckBox = uicheckbox(app.StatesmergingTab);
            app.removePosUSCheckBox.Text = 'Remove US with positive median';
            app.FurthercomputationTab = uitab(app.TabGroup3);
            app.FurthercomputationTab.Title = 'Further computation';
            app.doubleHemCheckBox = uicheckbox(app.FurthercomputationTab);
            app.doubleHemCheckBox.Text = ' Double hemisphere. Other channel = ';
            app.otherHem = uieditfield(app.FurthercomputationTab, 'numeric');
            app.otherHem.Limits = [1 Inf];
            app.otherHem.RoundFractionalValues = 'on';
            app.otherHem.Value = 1;
            app.computePwsCheckBox = uicheckbox(app.FurthercomputationTab);
            app.computePwsCheckBox.Text = 'Compute US and DS power spectra';
            app.PanelLowFreq = uipanel(app.FurthercomputationTab);
            app.USslopes_ck = uicheckbox(app.PanelLowFreq);
            app.USslopes_ck.Text = ' Calculate US slopes';
            app.multiPeakWavesCheckBox = uicheckbox(app.PanelLowFreq);
            app.multiPeakWavesCheckBox.Text = '';
            app.BPforMultipeakWavesSpinnerLabel = uilabel(app.PanelLowFreq);
            app.BPforMultipeakWavesSpinnerLabel.Text = 'BP for Multipeak Waves:';
            app.detectionBPmultiPeak_spinner = uispinner(app.PanelLowFreq);
            app.detectionBPmultiPeak_spinner.Limits = [1 Inf];
            app.detectionBPmultiPeak_spinner.Value = 1;
            app.SelectdeltabandLabel = uilabel(app.PanelLowFreq);
            app.SelectdeltabandLabel.Text = 'Select delta band:';
            app.PowerduringUpstateLabel = uilabel(app.FurthercomputationTab);
            app.PowerduringUpstateLabel.Text = 'Power during Upstate:';
            app.powerUS_dropDown = uidropdown(app.FurthercomputationTab);
            app.powerUS_dropDown.ValueChangedFcn = createCallbackFcn(app, @powerUS_dropDownValueChanged, true);
            app.pwinDSCk = uicheckbox(app.FurthercomputationTab);
            app.pwinDSCk.Text = 'Compute power also in DS';
            app.ExportTab = uitab(app.TabGroup3);
            app.ExportTab.Title = 'Export';
            app.expTracesCheckBox = uicheckbox(app.ExportTab);
            app.expTracesCheckBox.Text = {'Export trace segments around US start '; ''};
            app.TimewindowbeforeaftersEditFieldLabel = uilabel(app.ExportTab);
            app.TimewindowbeforeaftersEditFieldLabel.Text = 'Time window: before, after (s)';
            app.exp_beforeUS = uieditfield(app.ExportTab, 'numeric');
            app.exp_beforeUS.Value = 0.5;
            app.exp_afterUS = uieditfield(app.ExportTab, 'numeric');
            app.exp_afterUS.Value = 4.5;
            app.ExportdistributionandthrforcurrentchCheckBox = uicheckbox(app.ExportTab);
            app.ExportdistributionandthrforcurrentchCheckBox.Text = 'Export distribution and thr for current ch';
            app.extractEvents = uibutton(app.ComputeUSandDSPanel_2, 'push');
            app.extractEvents.ButtonPushedFcn = createCallbackFcn(app, @extractEvents_Callback, true);
            app.extractEvents.Text = 'Compute';
            app.uipanel1_2 = uipanel(app.EventDetectionTab);
            app.uipanel1_2.Title = 'Histogram';
            app.saveHCD = uibutton(app.uipanel1_2, 'push');
            app.saveHCD.ButtonPushedFcn = createCallbackFcn(app, @saveHCD_Callback, true);
            app.saveHCD.Tooltip = 'Save the histograms and the cumulative distributions';
            app.saveHCD.Text = 'Save';
            app.text9 = uilabel(app.uipanel1_2);
            app.text9.Text = 'Limits of the test plot:';
            app.traslateLeft = uibutton(app.uipanel1_2, 'push');
            app.traslateLeft.ButtonPushedFcn = createCallbackFcn(app, @traslateLeft_Callback, true);
            app.traslateLeft.Tooltip = 'Traslate the plot window left';
            app.traslateLeft.Text = '<';
            app.traslateRight = uibutton(app.uipanel1_2, 'push');
            app.traslateRight.ButtonPushedFcn = createCallbackFcn(app, @traslateRight_Callback, true);
            app.traslateRight.Tooltip = 'Traslate the plot window right';
            app.traslateRight.Text = '>';
            app.leftTime = uieditfield(app.uipanel1_2, 'numeric');
            app.leftTime.ValueChangedFcn = createCallbackFcn(app, @leftTimeValueChanged, true);
            app.rightTime = uieditfield(app.uipanel1_2, 'numeric');
            app.rightTime.ValueChangedFcn = createCallbackFcn(app, @rightTimeValueChanged, true);
            app.rightTime.Value = 10;
            app.replot = uibutton(app.uipanel1_2, 'push');
            app.replot.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.replot.Text = 'Replot';
            app.histoPlot = uiaxes(app.uipanel1_2);
            app.plotStates = uiaxes(app.uipanel1_2);
            app.statesTrack = uiaxes(app.uipanel1_2);
            app.statesTrack.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.TabGroup4 = uitabgroup(app.EventDetectionTab);
            app.ROCTab = uitab(app.TabGroup4);
            app.ROCTab.Title = 'ROC';
            app.BestthresholdEditFieldLabel = uilabel(app.ROCTab);
            app.BestthresholdEditFieldLabel.Text = 'Best threshold:';
            app.BestthresholdEditField = uieditfield(app.ROCTab, 'numeric');
            app.BestthresholdEditField.Editable = 'off';
            app.errorRocAxes = uiaxes(app.ROCTab);
            title(app.errorRocAxes, 'Error function')
            xlabel(app.errorRocAxes, 'Threshold')
            ylabel(app.errorRocAxes, 'Misses + False alarms')
            zlabel(app.errorRocAxes, 'Z')
            app.rocAxes = uiaxes(app.ROCTab);
            title(app.rocAxes, 'ROC curve')
            xlabel(app.rocAxes, 'False Alarms')
            ylabel(app.rocAxes, 'Hits')
            zlabel(app.rocAxes, 'Z')
            app.SpectraTab = uitab(app.TabGroup4);
            app.SpectraTab.Title = 'Spectra';
            app.uipanel4_3 = uipanel(app.SpectraTab);
            app.uipanel4_3.Title = 'Power Spectra';
            app.savePWS = uibutton(app.uipanel4_3, 'push');
            app.savePWS.ButtonPushedFcn = createCallbackFcn(app, @savePWS_Callback, true);
            app.savePWS.Text = 'Save PwS';
            app.SaveDownstSpec = uibutton(app.uipanel4_3, 'push');
            app.SaveDownstSpec.ButtonPushedFcn = createCallbackFcn(app, @SaveDownstSpecButtonPushed, true);
            app.SaveDownstSpec.Text = 'Save Dwnst PwS';
            app.text10 = uilabel(app.uipanel4_3);
            app.text10.Text = 'Set min & max power (dB):';
            app.text11 = uilabel(app.uipanel4_3);
            app.text11.Text = 'Set min & max freq (Hz):';
            app.minPw = uieditfield(app.uipanel4_3, 'numeric');
            app.minPw.ValueChangedFcn = createCallbackFcn(app, @minPwValueChanged, true);
            app.minPw.Value = -70;
            app.maxPw = uieditfield(app.uipanel4_3, 'numeric');
            app.maxPw.ValueChangedFcn = createCallbackFcn(app, @minPwValueChanged, true);
            app.maxPw.Value = -30;
            app.minFreq = uieditfield(app.uipanel4_3, 'numeric');
            app.minFreq.ValueChangedFcn = createCallbackFcn(app, @minPwValueChanged, true);
            app.minFreq.Value = 8;
            app.maxFreq = uieditfield(app.uipanel4_3, 'numeric');
            app.maxFreq.ValueChangedFcn = createCallbackFcn(app, @minPwValueChanged, true);
            app.maxFreq.Value = 200;
            app.powerSpectra = uiaxes(app.uipanel4_3);
            app.Tables = uitab(app.TabGroup4);
            app.Tables.Title = 'Tables';
            app.uipanel5_4 = uipanel(app.Tables);
            app.uipanel5_4.Title = 'US table';
            app.saveSegmentedStates = uibutton(app.uipanel5_4, 'push');
            app.saveSegmentedStates.ButtonPushedFcn = createCallbackFcn(app, @saveSegmentedStates_Callback, true);
            app.saveSegmentedStates.Text = 'Save US/DS';
            app.UStable = uitable(app.uipanel5_4);
            app.UStable.ColumnName = {'N'; 'T from'; 'T to'; 'Duration'; 'Median V'; 'SD'; 'Power'; 'Median V other hem'; 'SD other hem'; 'Power other hem'; 'Multipeak'; 'Multipeak other hem'; 'Avg rising slope'; 'Max rising slope'; 'Avg falling slope'; 'Max falling slope'; 'Avg rising slope other hem'; 'Max rising slope other hem'; 'Avg falling slope other hem'; 'Max falling slope other hem'};
            app.UStable.RowName = {};
            app.UStable.ColumnEditable = [false false false false false false false false false false];
            app.exportTab_btn = uibutton(app.uipanel5_4, 'push');
            app.exportTab_btn.ButtonPushedFcn = createCallbackFcn(app, @exportTab_btn_Callback, true);
            app.exportTab_btn.Text = 'Export table';
            app.SaveStartImagingButton = uibutton(app.uipanel5_4, 'push');
            app.SaveStartImagingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStartImagingButtonPushed, true);
            app.SaveStartImagingButton.Text = 'Save Start Imaging';
            app.CopyTableButton = uibutton(app.uipanel5_4, 'push');
            app.CopyTableButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTableButtonPushed, true);
            app.CopyTableButton.Text = {'Copy Table'; ''};
            app.DStablePanel = uipanel(app.Tables);
            app.DStablePanel.Title = 'DS table';
            app.DSTable = uitable(app.DStablePanel);
            app.DSTable.ColumnName = {'N'; 'T from'; 'T to'; 'duration'; 'median V'; 'SD'; 'Power'; 'median V other hem'; 'SD other hem'; 'Power other hem'};
            app.DSTable.RowName = {};
            app.ExportDStabBtn = uibutton(app.DStablePanel, 'state');
            app.ExportDStabBtn.ValueChangedFcn = createCallbackFcn(app, @ExportDStabBtnValueChanged, true);
            app.ExportDStabBtn.Text = 'Export table';
            app.CopyDStabBtn = uibutton(app.DStablePanel, 'state');
            app.CopyDStabBtn.ValueChangedFcn = createCallbackFcn(app, @CopyDStabBtnValueChanged, true);
            app.CopyDStabBtn.Text = 'Copy table';
            app.Tab = uitab(app.TabGroup4);
            app.Tab.Title = 'Tab';
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.ZebraAutoPanel = uipanel(app.AutoTab);
            app.ZebraAutoPanel.Title = 'ZebraAuto';
            app.AutoSetContrasts_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoSetContrasts_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoSetContrasts_btButtonPushed, true);
            app.AutoSetContrasts_bt.Text = 'Set Conditions';
            app.AutoGo_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoGo_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoGo_btButtonPushed, true);
            app.AutoGo_bt.Enable = 'off';
            app.AutoGo_bt.Text = 'Go';
            app.autoMode_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.autoMode_bt.ButtonPushedFcn = createCallbackFcn(app, @autoMode_btButtonPushed, true);
            app.autoMode_bt.Text = 'Auto Mode OFF';
            app.NumberofconditionsLabel = uilabel(app.ZebraAutoPanel);
            app.NumberofconditionsLabel.Text = 'Number of conditions';
            app.AutoNConds_txt = uieditfield(app.ZebraAutoPanel, 'numeric');
            app.AutoNConds_txt.Value = 6;
            app.uipanel2_4 = uipanel(app.ZebraAutoPanel);
            app.uipanel2_4.Title = 'Path selection';
            app.text2_3 = uilabel(app.uipanel2_4);
            app.text2_3.Text = 'Main folder';
            app.AutoFileNameIn_edit = uieditfield(app.uipanel2_4, 'text');
            app.AutoFileNameIn_edit.Value = 'ltp001.dat';
            app.AutoOutFileName_edit = uieditfield(app.uipanel2_4, 'text');
            app.AutoOutFileName_edit.Value = '.mat';
            app.text3_3 = uilabel(app.uipanel2_4);
            app.text3_3.Text = 'Data file name';
            app.AutoMainFolder_edit = uieditfield(app.uipanel2_4, 'text');
            app.text4_4 = uilabel(app.uipanel2_4);
            app.text4_4.Text = 'Output file name';
            app.uipanel1_3 = uipanel(app.ZebraAutoPanel);
            app.uipanel1_3.Title = 'Selection Panel';
            app.AutoSpg_ck = uicheckbox(app.uipanel1_3);
            app.AutoSpg_ck.Text = 'SPG';
            app.AutoSpg_ck.Value = true;
            app.AutoTable_ck = uicheckbox(app.uipanel1_3);
            app.AutoTable_ck.Text = 'Table';
            app.AutoTable_ck.Value = true;
            app.AutoTrace_ck = uicheckbox(app.uipanel1_3);
            app.AutoTrace_ck.Text = 'Trace';
            app.AutoTrace_ck.Value = true;
            app.uipanel4_4 = uipanel(app.uipanel1_3);
            app.uipanel4_4.Title = 'Spectra';
            app.AutoSpectra_ck = uicheckbox(app.uipanel4_4);
            app.AutoSpectra_ck.Text = 'Spectra';
            app.AutoSpectra_ck.Value = true;
            app.AutoWinSpectra_ck = uicheckbox(app.uipanel4_4);
            app.AutoWinSpectra_ck.Text = 'On window 1, (s)';
            app.AutoWinSpectra_ck.Value = true;
            app.AutoWinSpectra2_ck = uicheckbox(app.uipanel4_4);
            app.AutoWinSpectra2_ck.Text = 'On window 2, (s)';
            app.fromEditFieldLabel = uilabel(app.uipanel4_4);
            app.fromEditFieldLabel.Text = 'from';
            app.AutoSpectraFrom_edit = uieditfield(app.uipanel4_4, 'numeric');
            app.AutoSpectraFrom_edit.Value = 1;
            app.toEditFieldLabel = uilabel(app.uipanel4_4);
            app.toEditFieldLabel.Text = 'to';
            app.AutoSpectraTo_edit = uieditfield(app.uipanel4_4, 'numeric');
            app.AutoSpectraTo_edit.Value = 1;
            app.fromEditField_2Label = uilabel(app.uipanel4_4);
            app.fromEditField_2Label.Text = 'from';
            app.AutoSpectraFrom2_edit = uieditfield(app.uipanel4_4, 'numeric');
            app.AutoSpectraFrom2_edit.Value = 1;
            app.toEditField_2Label = uilabel(app.uipanel4_4);
            app.toEditField_2Label.Text = 'to';
            app.AutoSpectraTo2_edit = uieditfield(app.uipanel4_4, 'numeric');
            app.AutoSpectraTo2_edit.Value = 1;
            app.ChannelselectionButtonGroup = uibuttongroup(app.ZebraAutoPanel);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.allCh_rb = uiradiobutton(app.ChannelselectionButtonGroup);
            app.allCh_rb.Text = 'All channels';
            app.allCh_rb.Value = true;
            app.selCh_rb = uiradiobutton(app.ChannelselectionButtonGroup);
            app.selCh_rb.Text = 'Only channel';
            app.selCh_edit = uieditfield(app.ChannelselectionButtonGroup, 'numeric');
            app.sound_ck = uicheckbox(app.ZebraAutoPanel);
            app.sound_ck.Text = 'Sound';
            app.sound_ck.Value = true;
            app.auto_clearStoredLfp = uicheckbox(app.ZebraAutoPanel);
            app.auto_clearStoredLfp.Tooltip = {'Clear stored LFP before starting a new session (do not concatenate different sessions)'};
            app.auto_clearStoredLfp.Text = 'Clear';
            app.auto_clearStoredLfp.Value = true;
            % Show the figure after all components are created
            app.ZebraMainFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZebraExploreApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZebraMainFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZebraMainFig)
        end
    end
end