classdef analyzePWdistribution_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        analyzePWdist              matlab.ui.Figure
        uipanel1                   matlab.ui.container.Panel
        selectBPdisplay            matlab.ui.container.ButtonGroup
        BP1display                 matlab.ui.control.RadioButton
        BP2display                 matlab.ui.control.RadioButton
        BP3display                 matlab.ui.control.RadioButton
        HPdisplay                  matlab.ui.control.RadioButton
        text2                      matlab.ui.control.Label
        text3                      matlab.ui.control.Label
        BP1from                    matlab.ui.control.EditField
        BP1to                      matlab.ui.control.EditField
        BP1bins                    matlab.ui.control.EditField
        text4                      matlab.ui.control.Label
        BP2from                    matlab.ui.control.EditField
        BP2to                      matlab.ui.control.EditField
        BP2bins                    matlab.ui.control.EditField
        text5                      matlab.ui.control.Label
        BP3from                    matlab.ui.control.EditField
        BP3to                      matlab.ui.control.EditField
        BP3bins                    matlab.ui.control.EditField
        text6                      matlab.ui.control.Label
        HPfrom                     matlab.ui.control.EditField
        HPto                       matlab.ui.control.EditField
        HPbins                     matlab.ui.control.EditField
        Histo                      matlab.ui.control.Button
        selectCh                   matlab.ui.control.Slider
        selectedChTxt              matlab.ui.control.Label
        clearTable                 matlab.ui.control.Button
        distributionLogPowerTable  matlab.ui.control.Table
        PWdistribution             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.distributionLogPowerTable.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.distributionLogPowerTable.ColumnFormat = {[] [] [] [] [] 'short' [] [] [] [] [] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function analyzePWdistribution_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to analyzePWdistribution (see VARARGIN)

            
            % establish the link with the calling process
            app.parentApp =  varargin{1};
            
            
        end

        % Button pushed function: Histo
        function Histo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % refresh the local values of the parent handles
            analyzePowerDistribution(handles.parentObject, handles.parentHandles);
        end

        % Selection changed function: selectBPdisplay
        function selectBPdisplay_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in selectBPdisplay
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            plotPWhisto(handles.parentObject,handles.parentHandles)
        end

        % Value changed function: selectCh
        function selectCh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            plotPWhisto(handles.parentObject,handles.parentHandles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.analyzePWdist = uifigure('Visible', 'off');
            app.analyzePWdist.Name = 'analyzePWdistribution';
            app.analyzePWdist.Tag = 'analyzePWdist';
            app.uipanel1 = uipanel(app.analyzePWdist);
            app.uipanel1.Tag = 'uipanel1';
            app.selectBPdisplay = uibuttongroup(app.uipanel1);
            app.selectBPdisplay.SelectionChangedFcn = createCallbackFcn(app, @selectBPdisplay_SelectionChangeFcn, true);
            app.selectBPdisplay.Title = 'Select band to display';
            app.selectBPdisplay.Tag = 'selectBPdisplay';
            app.BP1display = uiradiobutton(app.selectBPdisplay);
            app.BP1display.Tag = 'BP1display';
            app.BP1display.Text = 'BP1';
            app.BP2display = uiradiobutton(app.selectBPdisplay);
            app.BP2display.Tag = 'BP2display';
            app.BP2display.Text = 'BP2';
            app.BP3display = uiradiobutton(app.selectBPdisplay);
            app.BP3display.Tag = 'BP3display';
            app.BP3display.Text = 'BP3';
            app.HPdisplay = uiradiobutton(app.selectBPdisplay);
            app.HPdisplay.Tag = 'HPdisplay';
            app.HPdisplay.Text = 'HP';
            app.HPdisplay.Value = true;
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Tooltip = 'Limits of the distribution and bin number';
            app.text2.Text = 'BP1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Log PW from     to        bins';
            app.BP1from = uieditfield(app.uipanel1, 'text');
            app.BP1from.Tag = 'BP1from';
            app.BP1from.Tooltip = 'Limits of the distribution and bin number';
            app.BP1from.Value = '-2.5';
            app.BP1to = uieditfield(app.uipanel1, 'text');
            app.BP1to.Tag = 'BP1to';
            app.BP1to.Tooltip = 'Limits of the distribution and bin number';
            app.BP1to.Value = '-0.5';
            app.BP1bins = uieditfield(app.uipanel1, 'text');
            app.BP1bins.Tag = 'BP1bins';
            app.BP1bins.Tooltip = 'Limits of the distribution and bin number';
            app.BP1bins.Value = '40';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Tooltip = 'Limits of the distribution and bin number';
            app.text4.Text = 'BP2';
            app.BP2from = uieditfield(app.uipanel1, 'text');
            app.BP2from.Tag = 'BP2from';
            app.BP2from.Tooltip = 'Limits of the distribution and bin number';
            app.BP2from.Value = '-3.5';
            app.BP2to = uieditfield(app.uipanel1, 'text');
            app.BP2to.Tag = 'BP2to';
            app.BP2to.Tooltip = 'Limits of the distribution and bin number';
            app.BP2to.Value = '0';
            app.BP2bins = uieditfield(app.uipanel1, 'text');
            app.BP2bins.Tag = 'BP2bins';
            app.BP2bins.Tooltip = 'Limits of the distribution and bin number';
            app.BP2bins.Value = '70';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Tooltip = 'Limits of the distribution and bin number';
            app.text5.Text = 'BP3';
            app.BP3from = uieditfield(app.uipanel1, 'text');
            app.BP3from.Tag = 'BP3from';
            app.BP3from.Tooltip = 'Limits of the distribution and bin number';
            app.BP3from.Value = '-3';
            app.BP3to = uieditfield(app.uipanel1, 'text');
            app.BP3to.Tag = 'BP3to';
            app.BP3to.Tooltip = 'Limits of the distribution and bin number';
            app.BP3to.Value = '-1';
            app.BP3bins = uieditfield(app.uipanel1, 'text');
            app.BP3bins.Tag = 'BP3bins';
            app.BP3bins.Tooltip = 'Limits of the distribution and bin number';
            app.BP3bins.Value = '40';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Tooltip = 'Limits of the distribution and bin number';
            app.text6.Text = 'HP';
            app.HPfrom = uieditfield(app.uipanel1, 'text');
            app.HPfrom.Tag = 'HPfrom';
            app.HPfrom.Tooltip = 'Limits of the distribution and bin number';
            app.HPfrom.Value = '-2';
            app.HPto = uieditfield(app.uipanel1, 'text');
            app.HPto.Tag = 'HPto';
            app.HPto.Tooltip = 'Limits of the distribution and bin number';
            app.HPto.Value = '-1.5';
            app.HPbins = uieditfield(app.uipanel1, 'text');
            app.HPbins.Tag = 'HPbins';
            app.HPbins.Tooltip = 'Limits of the distribution and bin number';
            app.HPbins.Value = '20';
            app.Histo = uibutton(app.uipanel1, 'push');
            app.Histo.ButtonPushedFcn = createCallbackFcn(app, @Histo_Callback, true);
            app.Histo.Tag = 'Histo';
            app.Histo.Tooltip = 'Compute and visualize distribution';
            app.Histo.Text = 'Recompute';
            app.selectCh = uislider(app.uipanel1);
            app.selectCh.Limits = [1 2];
            app.selectCh.MajorTicks = [];
            app.selectCh.ValueChangedFcn = createCallbackFcn(app, @selectCh_Callback, true);
            app.selectCh.MinorTicks = [];
            app.selectCh.Tag = 'selectCh';
            app.selectCh.Visible = 'off';
            app.selectCh.Tooltip = 'Select the channel to visualize';
            app.selectCh.Value = 1;
            app.selectedChTxt = uilabel(app.uipanel1);
            app.selectedChTxt.Tag = 'selectedChTxt';
            app.selectedChTxt.Tooltip = 'Visualized channel';
            app.selectedChTxt.Text = 'Ch 1';
            app.clearTable = uibutton(app.uipanel1, 'push');
            app.clearTable.Tag = 'clearTable';
            app.clearTable.Tooltip = 'Compute and visualize distribution';
            app.clearTable.Text = 'Clear Table';
            app.distributionLogPowerTable = uitable(app.analyzePWdist);
            app.distributionLogPowerTable.ColumnName = {'Directory'; 'File'; 'Time'; 'Ch'; 'Trial'; 'BP'; 'Sh Ent'; 'MM mean'; 'MM std'; 'MM %'; 'SM mean'; 'SM std'; 'SM %'};
            app.distributionLogPowerTable.ColumnEditable = [false false false false false false false false false false false false false];
            app.distributionLogPowerTable.Tag = 'distributionLogPowerTable';
            app.PWdistribution = uiaxes(app.analyzePWdist);
            app.PWdistribution.Tag = 'PWdistribution';
            % Show the figure after all components are created
            app.analyzePWdist.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyzePWdistribution_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.analyzePWdist)

                % Execute the startup function
                runStartupFcn(app, @(app)analyzePWdistribution_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.analyzePWdist)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.analyzePWdist)
        end
    end
end