classdef peakAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                      matlab.ui.Figure
        InputSelectionButtonGroup       matlab.ui.container.ButtonGroup
        LFPButton                       matlab.ui.control.RadioButton
        BandPassedButton                matlab.ui.control.RadioButton
        PowerButton                     matlab.ui.control.RadioButton
        ChSpinnerLabel                  matlab.ui.control.Label
        ChSpinner                       matlab.ui.control.Spinner
        TrSpinnerLabel                  matlab.ui.control.Label
        TrSpinner                       matlab.ui.control.Spinner
        BPSpinnerLabel                  matlab.ui.control.Label
        BPSpinner                       matlab.ui.control.Spinner
        lfpMaxY                         matlab.ui.control.NumericEditField
        lfpMinY                         matlab.ui.control.NumericEditField
        traceMaxY                       matlab.ui.control.NumericEditField
        traceMinY                       matlab.ui.control.NumericEditField
        minT                            matlab.ui.control.NumericEditField
        maxT                            matlab.ui.control.NumericEditField
        ResetButton_t                   matlab.ui.control.Button
        scrollForwardButton             matlab.ui.control.Button
        scrollBackButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BaselineTab                     matlab.ui.container.Tab
        PlotderivativesCheckBox         matlab.ui.control.CheckBox
        TracefilterwindowEditFieldLabel  matlab.ui.control.Label
        TracefilterwindowEditField      matlab.ui.control.NumericEditField
        TracefilterorderEditFieldLabel  matlab.ui.control.Label
        TracefilterorderEditField       matlab.ui.control.NumericEditField
        DerivfilterorderEditFieldLabel  matlab.ui.control.Label
        DerivfilterorderEditField       matlab.ui.control.NumericEditField
        DerivfilterwindowEditFieldLabel  matlab.ui.control.Label
        DerivfilterwindowEditField      matlab.ui.control.NumericEditField
        stderivthresholdEditFieldLabel  matlab.ui.control.Label
        stderivthresholdEditField       matlab.ui.control.NumericEditField
        ndderivthresholdEditFieldLabel  matlab.ui.control.Label
        ndderivthresholdEditField       matlab.ui.control.NumericEditField
        FindBaselineButton              matlab.ui.control.Button
        downsampleCheckBox              matlab.ui.control.CheckBox
        factorEditFieldLabel            matlab.ui.control.Label
        factorEditField                 matlab.ui.control.NumericEditField
        OrderforpolyfitEditFieldLabel   matlab.ui.control.Label
        OrderforpolyfitEditField        matlab.ui.control.NumericEditField
        PeaksTab                        matlab.ui.container.Tab
        ClearpeaksButton                matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        ResetButton_lfp                 matlab.ui.control.Button
        ResetButton_trace               matlab.ui.control.Button
        lfpAxes                         matlab.ui.control.UIAxes
        traceAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp
        LFP
        t
        currTrace
        currTime
        woBasel
        woBaselTime
        plotPeak
    end
    
    methods (Access = public)
        
        function plotLfp(app)
            inpos = app.lfpAxes.InnerPosition;
            cla(app.lfpAxes)
            plot(app.lfpAxes,app.t,app.LFP,'k')
            app.minT.Value = min(app.t);
            app.maxT.Value = max(app.t);
            axis(app.lfpAxes,[app.minT.Value, app.maxT.Value, app.lfpMinY.Value, app.lfpMaxY.Value])
            app.lfpAxes.InnerPosition = inpos;
        end
        
        function plotTrace(app)
            inpos = app.traceAxes.InnerPosition;
            cla(app.traceAxes)
            plot(app.traceAxes,[app.minT.Value, app.maxT.Value],[0 0],'Color',[0.5 0.5 0.5])
            plot(app.traceAxes,app.woBaselTime,app.woBasel,'k')
            axis(app.traceAxes,[app.minT.Value, app.maxT.Value, app.traceMinY.Value, app.traceMaxY.Value])
            app.traceAxes.InnerPosition([1,3]) = app.lfpAxes.InnerPosition([1,3]); % keep the 2 axes aligned
            app.traceAxes.InnerPosition([2,4]) = inpos([2,4]); % keep the vertical position and height
        end
        
        function addpeak(app)
            fprintf('current position is %.1f %.1f\n',app.traceAxes.CurrentPoint(1,1),app.traceAxes.CurrentPoint(1,2))
            currentX = app.traceAxes.CurrentPoint(1,1);
            [~,currentX_i] = min(abs(app.woBaselTime-currentX));
            allzero = find(findzerocrossings(app,app.woBasel));
            start_i = allzero(find(allzero<=currentX_i,1,"last"));
            stop_i = allzero(find(allzero>=currentX_i,1));
            fprintf('start = %.1f s ( %.1d min), stop = %.1f s\n', app.woBaselTime(start_i),app.woBaselTime(start_i)/60,app.woBaselTime(stop_i))
            
            fprintf('duration = %.1f s\n', app.woBaselTime(stop_i)-app.woBaselTime(start_i))
            
            peak = app.woBasel(start_i:stop_i);
            
            fprintf('mean = %.3f dB, integral = %.3f dB\n', mean(peak), sum(peak))
            
            patch(app.traceAxes,app.woBaselTime(start_i:stop_i),peak,'r','edgealpha',0)
        end
        function logical0 = findzerocrossings(app,x)
            x = reshape(x,1,[]);
            z = diff( [0, x >= 0]);
            pos = z==1;
            neg = circshift(z==-1,-1);
            logical0 = pos | neg;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            
            app.traceAxes.InnerPosition(1,3) = app.lfpAxes.InnerPosition(1,3); % keep the 2 axes aligned
        end

        % Button pushed function: FindBaselineButton
        function FindBaselineButtonPushed(app, event)
            
            selCh = app.ChSpinner.Value;
            selTr = app.TrSpinner.Value;
            selBp = app.BPSpinner.Value;
            
            app.LFP = reshape(squeeze(app.parentApp.workLFP(selCh,selTr,:)),1,[]);
            app.t = app.parentApp.x;


            switch app.InputSelectionButtonGroup.SelectedObject.Text  
                case 'LFP'
                    app.currTrace = app.LFP;
                    app.currTime = app.t;
                case 'BandPassed'
                    app.currTrace = reshape(squeeze(app.parentApp.bandPassed_LFP(selBp,selCh,selTr,:)),1,[]);
                    app.currTime = app.t;
                case 'Power'
                    app.currTrace = reshape(squeeze(app.parentApp.BPpower(selBp).power(selCh,selTr,:)),1,[]);
                    app.currTime = app.parentApp.BPpower(selBp).time;
            end
            
            if app.downsampleCheckBox.Value
                newi = 1:app.factorEditField.Value:length(app.currTime);
                app.currTrace = app.currTrace(newi);
                app.currTime = app.currTime(newi);
            end
            
            [bt, b] = baselinepoints(app.currTime,app.currTrace,...
                                                'YSgolayWindow',app.TracefilterwindowEditField.Value,...
                                                'YSgolayOrder',app.TracefilterorderEditField.Value,...
                                                'DerivativeSgolayWindow',app.DerivfilterwindowEditField.Value,...
                                                'DerivativeSgolayOrder',app.DerivfilterorderEditField.Value,...
                                                'FirstDerivativeThreshold',app.stderivthresholdEditField.Value,...
                                                'SecondDerivativeThreshold',app.ndderivthresholdEditField.Value,...
                                                'InterpolationMethod',app.InterpolmethodDropDown.Value,...
                                                'OutputPlot',app.PlotderivativesCheckBox.Value);
            
            % fit the funtion b = f(bt) with a line; resample it on
            % app.woBaselTime and divide app.currTrace for it. 
            switch app.InputSelectionButtonGroup.SelectedObject.Text  
                case 'Power'
                    b1 = 10*log10(b);
                    trace = 10*log10(app.currTrace);
                otherwise
                    b1 = b;
                    trace = app.currTrace;
            end
            coeff = polyfit(bt,b1,2);
            basel = coeff(1).*app.currTime.^2+coeff(2).*app.currTime+coeff(3);
            app.woBasel = trace-basel;
            app.woBaselTime = app.currTime;
            
            figure
            plot(app.currTime,trace)
            hold on
            plot(bt,b1,'o','markersize',3)
            plot(app.currTime,basel)
            
            app.plotLfp()
            app.plotTrace()
        end

        % Callback function
        function ActivateselectionButtonValueChanged(app, event)
            value = app.ActivateselectionButton.Value;
            if value
                app.traceAxes.ButtonDownFcn = @traceAxesButtonDown;
            else
                app.traceAxes.ButtonDownFcn = [];
            end
        end

        % Button down function: traceAxes
        function traceAxesButtonDown(app, event)
            addpeak(app)
        end

        % Value changed function: maxT, minT
        function minTValueChanged(app, event)
            app.lfpAxes.XLim = [app.minT.Value, app.maxT.Value];
            app.traceAxes.XLim = [app.minT.Value, app.maxT.Value];
        end

        % Value changed function: lfpMaxY, lfpMinY
        function lfpMaxYValueChanged(app, event)
            app.lfpAxes.YLim = [app.lfpMinY.Value, app.lfpMaxY.Value];
        end

        % Value changed function: traceMaxY
        function traceMaxYValueChanged(app, event)
            app.traceAxes = [app.traceMinY.Value, app.traceMaxY.Value]; 
        end

        % Button pushed function: ResetButton_lfp
        function ResetButton_lfpPushed(app, event)
            app.lfpMaxY.Value = Inf;
            app.lfpMinY.Value = -Inf;
            lfpMaxYValueChanged(app, [])
        end

        % Button pushed function: ResetButton_trace
        function ResetButton_tracePushed(app, event)
            app.traceMaxY.Value = Inf;
            app.traceMinY.Value = -Inf;
            traceMaxYValueChanged(app, [])
        end

        % Button pushed function: ResetButton_t
        function ResetButton_tPushed(app, event)
            app.maxT.Value = max(app.t);
            app.minT.Value = min(app.t);
            minTValueChanged(app, [])
        end

        % Button pushed function: scrollForwardButton
        function scrollForwardButtonPushed(app, event)
            t1 = app.minT.Value;
            t2 = app.maxT.Value;
            dt = t2-t1;
            app.minT.Value = t2;
            app.maxT.Value = t2+dt;
            minTValueChanged(app, [])
        end

        % Button pushed function: scrollBackButton
        function scrollBackButtonPushed(app, event)
            t1 = app.minT.Value;
            t2 = app.maxT.Value;
            dt = t2-t1;
            app.minT.Value = t1-dt;
            app.maxT.Value = t1;
            minTValueChanged(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'PeakAnalyzer';
            app.InputSelectionButtonGroup = uibuttongroup(app.mainFigure);
            app.InputSelectionButtonGroup.Title = 'Input Selection';
            app.LFPButton = uiradiobutton(app.InputSelectionButtonGroup);
            app.LFPButton.Text = 'LFP';
            app.BandPassedButton = uiradiobutton(app.InputSelectionButtonGroup);
            app.BandPassedButton.Text = 'BandPassed';
            app.PowerButton = uiradiobutton(app.InputSelectionButtonGroup);
            app.PowerButton.Text = 'Power';
            app.PowerButton.Value = true;
            app.ChSpinnerLabel = uilabel(app.InputSelectionButtonGroup);
            app.ChSpinnerLabel.Text = 'Ch';
            app.ChSpinner = uispinner(app.InputSelectionButtonGroup);
            app.ChSpinner.Value = 1;
            app.TrSpinnerLabel = uilabel(app.InputSelectionButtonGroup);
            app.TrSpinnerLabel.Text = 'Tr';
            app.TrSpinner = uispinner(app.InputSelectionButtonGroup);
            app.TrSpinner.Value = 1;
            app.BPSpinnerLabel = uilabel(app.InputSelectionButtonGroup);
            app.BPSpinnerLabel.Text = 'BP:';
            app.BPSpinner = uispinner(app.InputSelectionButtonGroup);
            app.BPSpinner.Value = 1;
            app.lfpMaxY = uieditfield(app.mainFigure, 'numeric');
            app.lfpMaxY.ValueChangedFcn = createCallbackFcn(app, @lfpMaxYValueChanged, true);
            app.lfpMaxY.Value = Inf;
            app.lfpMinY = uieditfield(app.mainFigure, 'numeric');
            app.lfpMinY.ValueChangedFcn = createCallbackFcn(app, @lfpMaxYValueChanged, true);
            app.lfpMinY.Value = -Inf;
            app.traceMaxY = uieditfield(app.mainFigure, 'numeric');
            app.traceMaxY.ValueChangedFcn = createCallbackFcn(app, @traceMaxYValueChanged, true);
            app.traceMaxY.Value = Inf;
            app.traceMinY = uieditfield(app.mainFigure, 'numeric');
            app.traceMinY.Value = -Inf;
            app.minT = uieditfield(app.mainFigure, 'numeric');
            app.minT.ValueChangedFcn = createCallbackFcn(app, @minTValueChanged, true);
            app.maxT = uieditfield(app.mainFigure, 'numeric');
            app.maxT.ValueChangedFcn = createCallbackFcn(app, @minTValueChanged, true);
            app.maxT.Value = 1;
            app.ResetButton_t = uibutton(app.mainFigure, 'push');
            app.ResetButton_t.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_tPushed, true);
            app.ResetButton_t.Text = 'Reset';
            app.scrollForwardButton = uibutton(app.mainFigure, 'push');
            app.scrollForwardButton.ButtonPushedFcn = createCallbackFcn(app, @scrollForwardButtonPushed, true);
            app.scrollForwardButton.Text = '>';
            app.scrollBackButton = uibutton(app.mainFigure, 'push');
            app.scrollBackButton.ButtonPushedFcn = createCallbackFcn(app, @scrollBackButtonPushed, true);
            app.scrollBackButton.Text = '<';
            app.TabGroup = uitabgroup(app.mainFigure);
            app.BaselineTab = uitab(app.TabGroup);
            app.BaselineTab.Title = 'Baseline';
            app.PlotderivativesCheckBox = uicheckbox(app.BaselineTab);
            app.PlotderivativesCheckBox.Text = 'Plot derivatives';
            app.PlotderivativesCheckBox.Value = true;
            app.TracefilterwindowEditFieldLabel = uilabel(app.BaselineTab);
            app.TracefilterwindowEditFieldLabel.Text = 'Trace filter window';
            app.TracefilterwindowEditField = uieditfield(app.BaselineTab, 'numeric');
            app.TracefilterwindowEditField.Value = 0.05;
            app.TracefilterorderEditFieldLabel = uilabel(app.BaselineTab);
            app.TracefilterorderEditFieldLabel.Text = 'Trace filter order';
            app.TracefilterorderEditField = uieditfield(app.BaselineTab, 'numeric');
            app.TracefilterorderEditField.Value = 15;
            app.DerivfilterorderEditFieldLabel = uilabel(app.BaselineTab);
            app.DerivfilterorderEditFieldLabel.Text = 'Deriv filter order';
            app.DerivfilterorderEditField = uieditfield(app.BaselineTab, 'numeric');
            app.DerivfilterorderEditField.Value = 3;
            app.DerivfilterwindowEditFieldLabel = uilabel(app.BaselineTab);
            app.DerivfilterwindowEditFieldLabel.Text = 'Deriv filter window';
            app.DerivfilterwindowEditField = uieditfield(app.BaselineTab, 'numeric');
            app.DerivfilterwindowEditField.Value = 0.02;
            app.stderivthresholdEditFieldLabel = uilabel(app.BaselineTab);
            app.stderivthresholdEditFieldLabel.Text = '1st deriv threshold';
            app.stderivthresholdEditField = uieditfield(app.BaselineTab, 'numeric');
            app.stderivthresholdEditField.Value = 5;
            app.ndderivthresholdEditFieldLabel = uilabel(app.BaselineTab);
            app.ndderivthresholdEditFieldLabel.Text = '2nd deriv threshold';
            app.ndderivthresholdEditField = uieditfield(app.BaselineTab, 'numeric');
            app.ndderivthresholdEditField.Value = 0.1;
            app.FindBaselineButton = uibutton(app.BaselineTab, 'push');
            app.FindBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @FindBaselineButtonPushed, true);
            app.FindBaselineButton.Text = 'Find Baseline';
            app.downsampleCheckBox = uicheckbox(app.BaselineTab);
            app.downsampleCheckBox.Text = 'downsample';
            app.factorEditFieldLabel = uilabel(app.BaselineTab);
            app.factorEditFieldLabel.Text = 'factor';
            app.factorEditField = uieditfield(app.BaselineTab, 'numeric');
            app.factorEditField.Value = 100;
            app.OrderforpolyfitEditFieldLabel = uilabel(app.BaselineTab);
            app.OrderforpolyfitEditFieldLabel.Text = 'Order for polyfit';
            app.OrderforpolyfitEditField = uieditfield(app.BaselineTab, 'numeric');
            app.OrderforpolyfitEditField.Value = 2;
            app.PeaksTab = uitab(app.TabGroup);
            app.PeaksTab.Title = 'Peaks';
            app.ClearpeaksButton = uibutton(app.PeaksTab, 'state');
            app.ClearpeaksButton.Text = 'Clear peaks';
            app.UITable = uitable(app.PeaksTab);
            app.UITable.ColumnName = {'From'; 'To'; 'Mean'; 'Peak x'; 'Peak y'; 'Integral'};
            app.UITable.RowName = {'''numbered'''};
            app.UITable.ColumnEditable = [false false false false false false];
            app.ResetButton_lfp = uibutton(app.mainFigure, 'push');
            app.ResetButton_lfp.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_lfpPushed, true);
            app.ResetButton_lfp.Text = 'Reset';
            app.ResetButton_trace = uibutton(app.mainFigure, 'push');
            app.ResetButton_trace.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_tracePushed, true);
            app.ResetButton_trace.Text = 'Reset';
            app.lfpAxes = uiaxes(app.mainFigure);
            title(app.lfpAxes, 'LFP')
            zlabel(app.lfpAxes, 'Z')
            app.lfpAxes.XTickLabel = '';
            app.traceAxes = uiaxes(app.mainFigure);
            title(app.traceAxes, 'Trace')
            xlabel(app.traceAxes, 'Time (s)')
            zlabel(app.traceAxes, 'Z')
            app.traceAxes.ButtonDownFcn = createCallbackFcn(app, @traceAxesButtonDown, true);
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = peakAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end